classdef AdvanceSearchWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SearchTab                       matlab.ui.container.Tab
        GraphTheseSearchResultsButton   matlab.ui.control.Button
        SecondFileEditField             matlab.ui.control.EditField
        AddMoreResultstoFileButton      matlab.ui.control.Button
        FileEditField                   matlab.ui.control.EditField
        ImportAndGraphResultsButton     matlab.ui.control.Button
        DateofSessionEditField          matlab.ui.control.EditField
        DateofSessionEditFieldLabel     matlab.ui.control.Label
        ExportSearchResultToFileButton  matlab.ui.control.Button
        DatabaseConnectionEditField     matlab.ui.control.EditField
        DatabaseConnectionEditFieldLabel  matlab.ui.control.Label
        BasicInstructions___________________________Label  matlab.ui.control.Label
        TimeStamp_____________________________________Label  matlab.ui.control.Label
        SearchResultsLabel              matlab.ui.control.Label
        BasicLabel_2                    matlab.ui.control.Label
        BasicLabel                      matlab.ui.control.Label
        Port4Label                      matlab.ui.control.Label
        Port3Label                      matlab.ui.control.Label
        Port2Label                      matlab.ui.control.Label
        Port1Label                      matlab.ui.control.Label
        Level4Label                     matlab.ui.control.Label
        Level3Label                     matlab.ui.control.Label
        Level2Label                     matlab.ui.control.Label
        Level1Label                     matlab.ui.control.Label
        BoxCostOffEditField             matlab.ui.control.EditField
        BoxCostOffEditFieldLabel        matlab.ui.control.Label
        TrialDurationEditField          matlab.ui.control.EditField
        TrialDurationEditFieldLabel     matlab.ui.control.Label
        RecordingDurationEditField      matlab.ui.control.EditField
        RecordingDurationEditFieldLabel  matlab.ui.control.Label
        RecordingAfterEditField         matlab.ui.control.EditField
        RecordingAfterEditFieldLabel    matlab.ui.control.Label
        StartTimeEditField              matlab.ui.control.EditField
        StartTimeEditFieldLabel         matlab.ui.control.Label
        BoxNoOfferDeliveryEditField     matlab.ui.control.EditField
        BoxNoOfferDeliveryEditFieldLabel  matlab.ui.control.Label
        BoxOfferDeliveryEditField       matlab.ui.control.EditField
        BoxOfferDeliveryEditFieldLabel  matlab.ui.control.Label
        ReferenceDurationEditField      matlab.ui.control.EditField
        ReferenceDurationEditFieldLabel  matlab.ui.control.Label
        TrialControlSettingsEditField   matlab.ui.control.EditField
        TrialControlSettingsEditFieldLabel  matlab.ui.control.Label
        DetectionSettingsEditField      matlab.ui.control.EditField
        DetectionSettingsEditFieldLabel  matlab.ui.control.Label
        TrialNameEditField              matlab.ui.control.EditField
        TrialNameEditFieldLabel         matlab.ui.control.Label
        StopTrackEditField              matlab.ui.control.EditField
        StopTrackEditFieldLabel         matlab.ui.control.Label
        FeederEditField                 matlab.ui.control.EditField
        FeederEditFieldLabel            matlab.ui.control.Label
        CageMateEditField               matlab.ui.control.EditField
        CageMateEditFieldLabel          matlab.ui.control.Label
        RewardVolume3EditField          matlab.ui.control.EditField
        RewardVolume3EditFieldLabel     matlab.ui.control.Label
        RewardVolume2EditField          matlab.ui.control.EditField
        RewardVolume2EditFieldLabel     matlab.ui.control.Label
        RewardVolume1EditField          matlab.ui.control.EditField
        RewardVolume1EditFieldLabel     matlab.ui.control.Label
        RewardConcentration4EditField   matlab.ui.control.EditField
        RewardConcentration4EditFieldLabel  matlab.ui.control.Label
        NotesEditField                  matlab.ui.control.EditField
        NotesEditFieldLabel             matlab.ui.control.Label
        LightLevelEditField             matlab.ui.control.EditField
        LightLevelEditFieldLabel        matlab.ui.control.Label
        PresentCostTimestampEditField   matlab.ui.control.EditField
        PresentCostTimestampEditFieldLabel  matlab.ui.control.Label
        RewardVolume4EditField          matlab.ui.control.EditField
        RewardVolume4EditFieldLabel     matlab.ui.control.Label
        CostProbability3EditField       matlab.ui.control.EditField
        CostProbability3EditFieldLabel  matlab.ui.control.Label
        CostProbability2EditField       matlab.ui.control.EditField
        CostProbability2EditFieldLabel  matlab.ui.control.Label
        CostProbability1EditField       matlab.ui.control.EditField
        CostProbability1EditFieldLabel  matlab.ui.control.Label
        ReferenceTimeEditField          matlab.ui.control.EditField
        ReferenceTimeEditFieldLabel     matlab.ui.control.Label
        VideoStartTimeEditField         matlab.ui.control.EditField
        VideoStartTimeEditFieldLabel    matlab.ui.control.Label
        RewardConcentration3EditField   matlab.ui.control.EditField
        RewardConcentration3EditFieldLabel  matlab.ui.control.Label
        RewardConcentration2EditField   matlab.ui.control.EditField
        RewardConcentration2EditFieldLabel  matlab.ui.control.Label
        RewardConcentration1EditField   matlab.ui.control.EditField
        RewardConcentration1EditFieldLabel  matlab.ui.control.Label
        CostProbability4EditField       matlab.ui.control.EditField
        CostProbability4EditFieldLabel  matlab.ui.control.Label
        IntensityOfCost4EditField       matlab.ui.control.EditField
        IntensityOfCost4EditFieldLabel  matlab.ui.control.Label
        IntensityOfCost3EditField       matlab.ui.control.EditField
        IntensityOfCost3EditFieldLabel  matlab.ui.control.Label
        IntensityOfCost2EditField       matlab.ui.control.EditField
        IntensityOfCost2EditFieldLabel  matlab.ui.control.Label
        IntensityOfCost1EditField       matlab.ui.control.EditField
        IntensityOfCost1EditFieldLabel  matlab.ui.control.Label
        AcceptedRejectedEditField       matlab.ui.control.EditField
        AcceptedRejectedEditFieldLabel  matlab.ui.control.Label
        AcceptedRejectedTimestampEditField  matlab.ui.control.EditField
        AcceptedRejectedTimestampEditFieldLabel  matlab.ui.control.Label
        RewardProbability3EditField     matlab.ui.control.EditField
        RewardProbability3EditFieldLabel  matlab.ui.control.Label
        RewardProbability2EditField     matlab.ui.control.EditField
        RewardProbability2EditFieldLabel  matlab.ui.control.Label
        ExperimenterEditField           matlab.ui.control.EditField
        ExperimenterEditFieldLabel      matlab.ui.control.Label
        PlayStartTrialToneTimestampEditField  matlab.ui.control.EditField
        PlayStartTrialToneTimestampEditFieldLabel  matlab.ui.control.Label
        RewardProbability4EditField     matlab.ui.control.EditField
        RewardProbability4EditFieldLabel  matlab.ui.control.Label
        RewardProbability1EditField     matlab.ui.control.EditField
        RewardProbability1EditFieldLabel  matlab.ui.control.Label
        TaskTypeDoneEditField           matlab.ui.control.EditField
        TaskTypeDoneEditFieldLabel      matlab.ui.control.Label
        HealthEditField                 matlab.ui.control.EditField
        HealthEditFieldLabel            matlab.ui.control.Label
        CageNumberEditField             matlab.ui.control.EditField
        CageNumberEditFieldLabel        matlab.ui.control.Label
        GenoTypeEditField               matlab.ui.control.EditField
        GenoTypeEditFieldLabel          matlab.ui.control.Label
        BirthDateEditField              matlab.ui.control.EditField
        BirthDateEditFieldLabel         matlab.ui.control.Label
        GenderEditField                 matlab.ui.control.EditField
        GenderEditFieldLabel            matlab.ui.control.Label
        SubjectNameIDEditField          matlab.ui.control.EditField
        SubjectNameIDEditFieldLabel     matlab.ui.control.Label
        DeleteSelectedRow               matlab.ui.control.Button
        SearchButton                    matlab.ui.control.Button
        Step4GobacktohomescreenandhitLoadSelectionLabel  matlab.ui.control.Label
        Step3SelectONEcellindesiredrowLabel  matlab.ui.control.Label
        Step2HitSearchLabel             matlab.ui.control.Label
        Step1EnterSearchParametersintotextboxesLabel  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        AnalyzeBehaviorTab              matlab.ui.container.Tab
        PlotButton                      matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        queryResult table = table("","","","","","","","","","","","","","","","","","","","","","","","","","","","","","","","","",'VariableNames',["subjectID", "gender", "birthdate", "genotype", "currentAge", "cageNumber", "currentHealth", "location", "sessionNumber", "experimentator", "mazeNumber", "taskTypeSuggested", "taskTypeActuallyDone","executionTime", "datePlanned", "costValue", "certaintyCost", "costProbability", "certaintyValue", "certaintyReward", "rewardValue", "rewardProbability", "firstDay", "firstDayAGE", "firstDayHealth", "lastDay", "lastDayAge", "lastDayHealth", "reason","subjects", "cageMade", "notes", "userID"]);

        index double
        exportRow table
        firstOrNot double = 0;
        databaseConnection string ='';
        username = 'postgres';
        password = '1234';
        actualTable string = 'live_table'
        queryParts string = ["subjectID","gender","birthdate","genotype","cagenumber","health","cagemates","experimenter","tasktypedone","notes","intensityofcost1","intensityofcost2","intensityofcost3","intensityofcost4","costprobability1","costprobability2","costprobability3","costprobability4","rewardconcentration1","rewardconcentration2","rewardconcentration3","rewardconcentration4","rewardvolume1","rewardvolume2","rewardvolume3","rewardvolume4","rewardprobability1","rewardprobability2","rewardprobability3","rewardprobability4","mazenumber","approachavoidtimestamp","approachavoid","playstarttrialtone","presentcost","lightlevel","referencetime","videostartime","feeder","stoptrack","trialname","detectionsettings","trialcontrolsettings","referenceduration","animalid","mazeofferdelivery","mazenoofferdelivery","starttime","recordingafter","recordingduration","trialduration","mazecostoff", "coordinatetimes", "xcoordinates", "ycoordinates", "presentcostend", "costpresenetationfinish", "stopincopixrecording", "decisionmakingtime","startincopixrecording", "date"];        
        
        exportFile string = ''
    
    end
    
    properties (Access = private)
        firstSearch double = -5; % Description
        secondSearch double = -5; % Description
        thirdSearch double = -5; % Description
        fourthSearch double = -5; % Description
        searchPosition double = 1; % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.databaseConnection = string(importdata('databaseConnection.txt'));
            app.DatabaseConnectionEditField.Value = app.databaseConnection;
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            % Make connection to database
            conn = database(app.databaseConnection, app.username, app.password);
            %all search values the user wants to use
            searchValues = [ string(app.SubjectNameIDEditField.Value),...
                string(app.GenderEditField.Value),...
                string(app.BirthDateEditField.Value),...
                string(app.GenoTypeEditField.Value),...
                string(app.CageNumberEditField.Value),...
                string(app.HealthEditField.Value),...
                string(app.CageMateEditField.Value),...
                string(app.ExperimenterEditField.Value),...
                string(app.TaskTypeDoneEditField.Value),...
                string(app.NotesEditField.Value),...
                string(app.IntensityOfCost1EditField.Value),...
                string(app.IntensityOfCost2EditField.Value), ...
                string(app.IntensityOfCost3EditField.Value),...
                string(app.IntensityOfCost4EditField.Value),...
                string(app.CostProbability1EditField.Value),...
                string(app.CostProbability2EditField.Value),...
                string(app.CostProbability3EditField.Value),...
                string(app.CostProbability4EditField.Value),...
                string(app.RewardConcentration1EditField.Value),...
                string(app.RewardConcentration2EditField.Value),...
                string(app.RewardConcentration3EditField.Value),...
                string(app.RewardConcentration4EditField.Value),...
                string(app.RewardVolume1EditField.Value),...
                string(app.RewardVolume2EditField.Value),...
                string(app.RewardVolume3EditField.Value),...
                string(app.RewardVolume4EditField.Value),...
                string(app.RewardProbability1EditField.Value),...
                string(app.RewardProbability2EditField.Value),...
                string(app.RewardProbability3EditField.Value),...
                string(app.RewardProbability4EditField.Value),...
                "",...
                string(app.AcceptedRejectedTimestampEditField.Value),...
                string(app.AcceptedRejectedEditField.Value),...
                string(app.PlayStartTrialToneTimestampEditField.Value),...
                string(app.PresentCostTimestampEditField.Value),...
                string(app.LightLevelEditField.Value),...
                string(app.ReferenceTimeEditField.Value),...
                string(app.VideoStartTimeEditField.Value),...
                string(app.FeederEditField.Value), ...
                string(app.StopTrackEditField.Value),...
                string(app.TrialNameEditField.Value),...
                string(app.DetectionSettingsEditField.Value),...
                string(app.TrialControlSettingsEditField.Value),...
                string(app.ReferenceDurationEditField.Value), ...
                "",...
                string(app.BoxOfferDeliveryEditField.Value),...
                string(app.BoxNoOfferDeliveryEditField.Value), ...
                string(app.StartTimeEditField.Value),...
                string(app.RecordingAfterEditField.Value),...
                string(app.RecordingDurationEditField.Value),...
                string(app.TrialDurationEditField.Value),...
                string(app.BoxCostOffEditField.Value),...
                "",...
                "",...
                "",...
                "",...
                "",...
                "",...
                "",...
                "",...
                string(app.DateofSessionEditField.Value)];
            
            
            %represents whether the user is using this search parameter or not
            searchParameters = [false false false false false false false false false false ...
                false false false false false false false false false false...
                false false false false false false false false false false...
                false false false false false false false false false false...
                false false false false false false false false false false...
                false false false false false false false false false false ...
                false];


            %all possible parts of the query

            i = 1;

            %checks and sets which search parameters are used
            while i <= length(searchValues)
                %If the length of the value is > 0 then we set the value to
                %true to show that this value is being used
                if strlength(searchValues(i)) > 0
                    searchParameters(i) = true;
                end
                i = i +1;
            end
%             display(searchParameters)
            numberOfSearchTerms = 1; 
            
            
            i =1;
            while i <= length(searchValues)
                if searchParameters(i) == true
                    numberOfSearchTerms = i; 
                end
                i = i +1; 
            end

            query = strcat('SELECT * FROM  ', {' '}, app.actualTable, ' WHERE ', {' '});
            i = 1;
            %builds the query
            while i <= length(searchParameters)
               
                if searchParameters(i)
                    if i==1
                        if i == numberOfSearchTerms
                            query  = strcat(query,app.queryParts(i),{' '}, "=", {' '}, "'",string(searchValues(i)),"'");
                        else
                            query  = strcat(query,app.queryParts(i),{' '},'=',{' '},"'",string(searchValues(i)),"'", " ", 'AND', {' '});
                        end
                    else
                        if i == numberOfSearchTerms
                            query  = strcat(query,app.queryParts(i),{' '},"=",{' '}, "'",string(searchValues(i)),"'", " ");
                        else
                            query  = strcat(query,app.queryParts(i),{' '},'=',{' '},"'",string(searchValues(i)),"'", " ", 'AND', {' '});
                        end
                        
                    end
                 end
                i = i +1;
                
            end
            query = strcat(query,';');
            display(query)

            %% Execute query and fetch results
            data = fetch(conn,query);
            display(data)
            app.queryResult = data;
            close(conn)

            %% Clear variables
            clear conn query
            app.UITable.Data = data;
            writetable(data, "Result.txt")
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            fclose ('all');
            delete ('port.txt');
            indices = event.Indices;
            app.index = indices; 
            A = readtable("Result.txt");
            
       
            B = A(indices(1,1),:);
            %display(B)
            
     
            writetable(B, "port.txt")
            
            uit = app.UITable;
            s1 = uistyle;
            s1.BackgroundColor = 'cyan';
            addStyle(uit, s1, 'cell', app.index);
            
            
        end

        % Button pushed function: DeleteSelectedRow
        function DeleteSelectedRowButtonPushed(app, event)
            answer = questdlg('Are you sure you have selected the correct box row, and that you want to delete this row?');
            conn = database(app.databaseConnection, app.username, app.password);
            switch answer
                case 'Yes'
                    tbd = readtable("port.txt");                
                    

                    query = strcat(' DELETE FROM', {' '}, app.actualTable, {' '});
                    i = 1;
                    while i <= width(app.actualTable)
                        if i == 1
                            query = strcat(query,"WHERE", {" "}, app.queryParts(i),{" "},"=", {" "}, "'",string(tbd{1,i}),"'", {" "});
                        else
                            query = strcat(query,"AND", {" "}, app.queryParts(i),{" "},"=", {" "}, "'",string(tbd{1,i}),"'", {" "});
                        end
                        i = i+1;
                    end
                    display(query)
                    execute(conn,query);
                    msgbox("You have successfully deleted a row from the database.")

                case 'No'
                case 'cancel'
            end
        end

        % Callback function
        function ExportRowToFileButtonPushed(app, event)
            disp(app.index)
            disp(app.queryResult)
            
            app.exportRow = app.queryResult(app.index(1,1),:); 
            %disp(class(exportRow))
            switch app.firstOrNot
                case 0
                    writetable(app.exportRow,"yourExportFile.txt");
                    msgbox("You have added the selected row to the import sheet.")
                    app.firstOrNot = app.firstOrNot +1; 
                case 1
                    writetable(app.exportRow,'yourExportFile.txt',"WriteMode","append", 'WriteVariableNames',false,"WriteRowNames",true)
                    msgbox("You have added the selected row to the import sheet.")
                    
            end
             
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
                     
            plot(app.UIAxes,[app.firstSearch app.secondSearch app.thirdSearch app.fourthSearch]);
            
        end

        % Button pushed function: ExportSearchResultToFileButton
        function ExportSearchResultToFileButtonPushed(app, event)
            %create local copy of search result
            local= app.queryResult;
            
            %get the user to specify file name and directory for saving
            [fileName, filePath] = uiputfile('*.xlsx', 'Create a file');
            
            %write the search result to the file the user selected
            writetable(local, strcat(filePath,fileName))
            
            %display the user's chosen file path in the GUI
            app.FileEditField.Value = strcat(filePath,fileName); 
            
            %confirm the user's path writing
            msgbox(strcat('Your search Result has been written to ',filePath,fileName))
            app.exportFile = strcat(filePath,fileName);
            
            
        end

        % Button pushed function: ImportAndGraphResultsButton
        function ImportAndGraphResultsButtonPushed(app, event)
            [file,path] = uigetfile('*.xlsx');
            local = readtable(strcat(path,file));
            numberOfTotalRows = height(local);
            i = 1;
            %keeps track of how many times the rat approaches the
            %designated feeders
            feeder1Approaches = 0.00;
            feeder2Approaches = 0.00;
            feeder3Approaches = 0.00;
            feeder4Approaches = 0.00;

            %keeps track of how many times each feeder appears in tests
            feeder1Appearences = 0;
            feeder2Appearences = 0;
            feeder3Appearences = 0;
            feeder4Appearences = 0;
            
            while i <= numberOfTotalRows
                feederValue = string(local{i,39});
                %if the feeder value appears and it approaches then the
                %feeder1/2/3/4 approaches variable is incremented
                if str2double(string(local{i,33})) == 1
                    switch feederValue
                        case '1'
                            feeder1Approaches = feeder1Approaches+1;
                        case '2'
                            feeder2Approaches = feeder2Approaches+1;
                        case '3'
                            feeder3Approaches = feeder3Approaches+1;
                        case '4'
                            feeder4Approaches = feeder4Approaches+1;
                    end

                end

                %if the feederValue appears the feeder1/2/3/4 Appearences
                %variables are incremented
                switch feederValue
                    case '1'   
                        feeder1Appearences = feeder1Appearences + 1;
                    case '2'
                        feeder2Appearences = feeder2Appearences + 1;
                    case '3'                
                        feeder3Appearences = feeder3Appearences + 1;
                    case '4'
                        feeder4Appearences = feeder4Appearences + 1;
                end
                i = i+1;
            end

            approaches = [feeder1Approaches,feeder2Approaches,feeder3Approaches,feeder4Approaches];
            display(approaches)
            appearences = [feeder1Appearences, feeder2Appearences,feeder3Appearences,feeder4Appearences];
            display(appearences)
            
            %feeder[1,2,3,4]ApproachPercentage = number of times rat approached / number of times the feeder appeared
            feeder1ApproachPercentage = feeder1Approaches / feeder1Appearences;
            feeder2ApproachPercentage = feeder2Approaches / feeder2Appearences;
            feeder3ApproachPercentage =feeder3Approaches / feeder3Appearences;
            feeder4ApproachPercentage =feeder4Approaches / feeder4Appearences; 

            %feeder[1,2,3,4]Percentage is the percent concentration stored in the table included in any
            feeder1Percentage = str2double(regexprep(string(local{1,19}),'%','')) / 100;
            feeder2Percentage = str2double(regexprep(string(local{1,20}),'%',''))/100;
            feeder3Percentage = str2double(regexprep(string(local{1,21}),'%',''))/100;
            feeder4Percentage = str2double(regexprep(string(local{1,22}),'%',''))/100;

            ycoordinates = [feeder1ApproachPercentage, feeder2ApproachPercentage, feeder3ApproachPercentage, feeder4ApproachPercentage ];
            xcoordinates = [feeder1Percentage, feeder2Percentage, feeder3Percentage, feeder4Percentage];

            plot(xcoordinates, ycoordinates, '-o', 'LineWidth',3)

             display(xcoordinates)
             display(ycoordinates)


        end

        % Button pushed function: AddMoreResultstoFileButton
        function AddMoreResultstoFileButtonPushed(app, event)
            %create a local copy of the table
            local = app.queryResult;
            
            %get the name of the file the user wants to append to
            [file,path] = uigetfile('*.xlsx');
            
            %display the file the user wants append to
            app.SecondFileEditField.Value = strcat(path,file);
            
            %check to make sure the user selected the file
            if isequal(file,0)
                %if the user didn't select a file nothing happens
                disp('User selected Cancel');
                
                
            else
                %if the user did select a file print out the file they
                %selected
                disp(['User selected ', fullfile(path,file)]);
                
                %append the current search result to the file the user
                %selected
                writetable(local, strcat(path,file),'WriteMode','Append', ...
                    'WriteVariableNames', false, 'WriteRowNames',true)
                
                
                %confirm successful writing to user
                msgbox(strcat("These search results have been appended to ", app.exportFile))
            end
        end

        % Button pushed function: GraphTheseSearchResultsButton
        function GraphTheseSearchResultsButtonPushed(app, event)
            local = app.queryResult;
            numberOfTotalRows = height(local);
            i = 1;
            %keeps track of how many times the rat approaches the
            %designated feeders
            feeder1Approaches = 0.00;
            feeder2Approaches = 0.00;
            feeder3Approaches = 0.00;
            feeder4Approaches = 0.00;

            %keeps track of how many times each feeder appears in tests
            feeder1Appearences = 0;
            feeder2Appearences = 0;
            feeder3Appearences = 0;
            feeder4Appearences = 0;
            
            while i <= numberOfTotalRows
                feederValue = string(local{i,39});
                %if the feeder value appears and it approaches then the
                %feeder1/2/3/4 approaches variable is incremented
                if str2double(string(local{i,33})) == 1
                    switch feederValue
                        case '1'
                            feeder1Approaches = feeder1Approaches+1;
                        case '2'
                            feeder2Approaches = feeder2Approaches+1;
                        case '3'
                            feeder3Approaches = feeder3Approaches+1;
                        case '4'
                            feeder4Approaches = feeder4Approaches+1;
                    end

                end

                %if the feederValue appears the feeder1/2/3/4 Appearences
                %variables are incremented
                switch feederValue
                    case '1'   
                        feeder1Appearences = feeder1Appearences + 1;
                    case '2'
                        feeder2Appearences = feeder2Appearences + 1;
                    case '3'                
                        feeder3Appearences = feeder3Appearences + 1;
                    case '4'
                        feeder4Appearences = feeder4Appearences + 1;
                end
                i = i+1;
            end

            approaches = [feeder1Approaches,feeder2Approaches,feeder3Approaches,feeder4Approaches];
            display(approaches)
            appearences = [feeder1Appearences, feeder2Appearences,feeder3Appearences,feeder4Appearences];
            display(appearences)
            
            %feeder[1,2,3,4]ApproachPercentage = number of times rat approached / number of times the feeder appeared
            feeder1ApproachPercentage = feeder1Approaches / feeder1Appearences;
            feeder2ApproachPercentage = feeder2Approaches / feeder2Appearences;
            feeder3ApproachPercentage =feeder3Approaches / feeder3Appearences;
            feeder4ApproachPercentage =feeder4Approaches / feeder4Appearences; 

            %feeder[1,2,3,4]Percentage is the percent concentration stored in the table included in any
            feeder1Percentage = str2double(regexprep(string(local{1,19}),'%','')) / 100;
            feeder2Percentage = str2double(regexprep(string(local{1,20}),'%',''))/100;
            feeder3Percentage = str2double(regexprep(string(local{1,21}),'%',''))/100;
            feeder4Percentage = str2double(regexprep(string(local{1,22}),'%',''))/100;

            ycoordinates = [feeder1ApproachPercentage, feeder2ApproachPercentage, feeder3ApproachPercentage, feeder4ApproachPercentage ];
            xcoordinates = [feeder1Percentage, feeder2Percentage, feeder3Percentage, feeder4Percentage];

            plot(xcoordinates, ycoordinates, '-o', 'LineWidth',3)

             display(xcoordinates)
             display(ycoordinates)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SearchTab = uitab(app.TabGroup);
            app.SearchTab.Title = 'Search';
            app.UITable = uitable(app.SearchTab);
            app.UITable.ColumnName = {'subjectID'; 'gender'; 'birthdate'; 'genotype'; 'cagenumber'; 'health'; 'cagemates'; 'experimenter'; 'tasktypedone'; 'notes'; 'intensityofcost1'; 'intensityofcost2'; 'intensityofcost3'; 'intensityofcost4'; 'costprobability1'; 'costprobability2'; 'costprobability3'; 'costprobability4'; 'rewardconcentration1'; 'rewardconcentration2'; 'rewardconcentration3'; 'rewardconcentration4'; 'rewardvolume1'; 'rewardvolume2'; 'rewardvolume3'; 'rewardvolume4'; 'rewardprobability1'; 'rewardprobability2'; 'rewardprobability3'; 'rewardprobability4'; 'mazenumber'; 'approachavoidtimestamp'; 'approachavoid'; 'playstarttrialtone'; 'presentcost'; 'lightlevel'; 'referencetime'; 'videostartime'; 'feeder'; 'stoptrack'; 'trialname'; 'detectionsettings'; 'trialcontrolsettings'; 'referenceduration'; 'animalid'; 'mazeofferdelivery'; 'mazenoofferdelivery'; 'starttime'; 'recordingafter'; 'recordingduration'; 'trialduration'; 'mazecostoff'; 'coordinatetimes'; 'xcoordinates'; 'ycoordinates'; 'presentcostend'; 'costpresenetationfinish'; 'stopincopixrecording'; 'decisionmakingtime'; 'startincopixrecording'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.Step1EnterSearchParametersintotextboxesLabel = uilabel(app.SearchTab);
            app.Step1EnterSearchParametersintotextboxesLabel.Text = 'Step 1)  Enter Search Parameters into text boxes';
            app.Step2HitSearchLabel = uilabel(app.SearchTab);
            app.Step2HitSearchLabel.Text = 'Step 2)  Hit "Search"';
            app.Step3SelectONEcellindesiredrowLabel = uilabel(app.SearchTab);
            app.Step3SelectONEcellindesiredrowLabel.Text = 'Step 3) Select ONE cell in desired row';
            app.Step4GobacktohomescreenandhitLoadSelectionLabel = uilabel(app.SearchTab);
            app.Step4GobacktohomescreenandhitLoadSelectionLabel.Text = 'Step 4) Go back to home screen and hit "Load Selection"';
            app.SearchButton = uibutton(app.SearchTab, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.DeleteSelectedRow = uibutton(app.SearchTab, 'push');
            app.DeleteSelectedRow.ButtonPushedFcn = createCallbackFcn(app, @DeleteSelectedRowButtonPushed, true);
            app.DeleteSelectedRow.Text = 'Delete Selected Row';
            app.SubjectNameIDEditFieldLabel = uilabel(app.SearchTab);
            app.SubjectNameIDEditFieldLabel.Text = 'Subject Name/ID';
            app.SubjectNameIDEditField = uieditfield(app.SearchTab, 'text');
            app.GenderEditFieldLabel = uilabel(app.SearchTab);
            app.GenderEditFieldLabel.Text = 'Gender';
            app.GenderEditField = uieditfield(app.SearchTab, 'text');
            app.BirthDateEditFieldLabel = uilabel(app.SearchTab);
            app.BirthDateEditFieldLabel.Text = 'Birth Date';
            app.BirthDateEditField = uieditfield(app.SearchTab, 'text');
            app.GenoTypeEditFieldLabel = uilabel(app.SearchTab);
            app.GenoTypeEditFieldLabel.Text = 'GenoType';
            app.GenoTypeEditField = uieditfield(app.SearchTab, 'text');
            app.CageNumberEditFieldLabel = uilabel(app.SearchTab);
            app.CageNumberEditFieldLabel.Text = 'Cage Number';
            app.CageNumberEditField = uieditfield(app.SearchTab, 'text');
            app.HealthEditFieldLabel = uilabel(app.SearchTab);
            app.HealthEditFieldLabel.Text = 'Health';
            app.HealthEditField = uieditfield(app.SearchTab, 'text');
            app.TaskTypeDoneEditFieldLabel = uilabel(app.SearchTab);
            app.TaskTypeDoneEditFieldLabel.Text = 'Task Type Done';
            app.TaskTypeDoneEditField = uieditfield(app.SearchTab, 'text');
            app.RewardProbability1EditFieldLabel = uilabel(app.SearchTab);
            app.RewardProbability1EditFieldLabel.Text = 'Reward Probability 1';
            app.RewardProbability1EditField = uieditfield(app.SearchTab, 'text');
            app.RewardProbability4EditFieldLabel = uilabel(app.SearchTab);
            app.RewardProbability4EditFieldLabel.Text = 'Reward Probability 4';
            app.RewardProbability4EditField = uieditfield(app.SearchTab, 'text');
            app.PlayStartTrialToneTimestampEditFieldLabel = uilabel(app.SearchTab);
            app.PlayStartTrialToneTimestampEditFieldLabel.Text = 'Play Start Trial Tone Timestamp';
            app.PlayStartTrialToneTimestampEditField = uieditfield(app.SearchTab, 'text');
            app.ExperimenterEditFieldLabel = uilabel(app.SearchTab);
            app.ExperimenterEditFieldLabel.Text = 'Experimenter';
            app.ExperimenterEditField = uieditfield(app.SearchTab, 'text');
            app.RewardProbability2EditFieldLabel = uilabel(app.SearchTab);
            app.RewardProbability2EditFieldLabel.Text = 'Reward Probability 2';
            app.RewardProbability2EditField = uieditfield(app.SearchTab, 'text');
            app.RewardProbability3EditFieldLabel = uilabel(app.SearchTab);
            app.RewardProbability3EditFieldLabel.Text = 'Reward Probability 3';
            app.RewardProbability3EditField = uieditfield(app.SearchTab, 'text');
            app.AcceptedRejectedTimestampEditFieldLabel = uilabel(app.SearchTab);
            app.AcceptedRejectedTimestampEditFieldLabel.Text = 'Accepted/Rejected Timestamp';
            app.AcceptedRejectedTimestampEditField = uieditfield(app.SearchTab, 'text');
            app.AcceptedRejectedEditFieldLabel = uilabel(app.SearchTab);
            app.AcceptedRejectedEditFieldLabel.Text = 'Accepted/Rejected';
            app.AcceptedRejectedEditField = uieditfield(app.SearchTab, 'text');
            app.IntensityOfCost1EditFieldLabel = uilabel(app.SearchTab);
            app.IntensityOfCost1EditFieldLabel.Text = 'Intensity Of Cost 1';
            app.IntensityOfCost1EditField = uieditfield(app.SearchTab, 'text');
            app.IntensityOfCost2EditFieldLabel = uilabel(app.SearchTab);
            app.IntensityOfCost2EditFieldLabel.Text = 'Intensity Of Cost 2';
            app.IntensityOfCost2EditField = uieditfield(app.SearchTab, 'text');
            app.IntensityOfCost3EditFieldLabel = uilabel(app.SearchTab);
            app.IntensityOfCost3EditFieldLabel.Text = 'Intensity Of Cost 3';
            app.IntensityOfCost3EditField = uieditfield(app.SearchTab, 'text');
            app.IntensityOfCost4EditFieldLabel = uilabel(app.SearchTab);
            app.IntensityOfCost4EditFieldLabel.Text = 'Intensity Of Cost 4';
            app.IntensityOfCost4EditField = uieditfield(app.SearchTab, 'text');
            app.CostProbability4EditFieldLabel = uilabel(app.SearchTab);
            app.CostProbability4EditFieldLabel.Text = 'Cost Probability 4';
            app.CostProbability4EditField = uieditfield(app.SearchTab, 'text');
            app.RewardConcentration1EditFieldLabel = uilabel(app.SearchTab);
            app.RewardConcentration1EditFieldLabel.Text = 'Reward Concentration 1';
            app.RewardConcentration1EditField = uieditfield(app.SearchTab, 'text');
            app.RewardConcentration2EditFieldLabel = uilabel(app.SearchTab);
            app.RewardConcentration2EditFieldLabel.Text = 'Reward Concentration 2';
            app.RewardConcentration2EditField = uieditfield(app.SearchTab, 'text');
            app.RewardConcentration3EditFieldLabel = uilabel(app.SearchTab);
            app.RewardConcentration3EditFieldLabel.Text = 'Reward Concentration 3';
            app.RewardConcentration3EditField = uieditfield(app.SearchTab, 'text');
            app.VideoStartTimeEditFieldLabel = uilabel(app.SearchTab);
            app.VideoStartTimeEditFieldLabel.Text = 'Video Start Time';
            app.VideoStartTimeEditField = uieditfield(app.SearchTab, 'text');
            app.ReferenceTimeEditFieldLabel = uilabel(app.SearchTab);
            app.ReferenceTimeEditFieldLabel.Text = 'Reference Time';
            app.ReferenceTimeEditField = uieditfield(app.SearchTab, 'text');
            app.CostProbability1EditFieldLabel = uilabel(app.SearchTab);
            app.CostProbability1EditFieldLabel.Text = 'Cost Probability 1';
            app.CostProbability1EditField = uieditfield(app.SearchTab, 'text');
            app.CostProbability2EditFieldLabel = uilabel(app.SearchTab);
            app.CostProbability2EditFieldLabel.Text = 'Cost Probability 2';
            app.CostProbability2EditField = uieditfield(app.SearchTab, 'text');
            app.CostProbability3EditFieldLabel = uilabel(app.SearchTab);
            app.CostProbability3EditFieldLabel.Text = 'Cost Probability 3';
            app.CostProbability3EditField = uieditfield(app.SearchTab, 'text');
            app.RewardVolume4EditFieldLabel = uilabel(app.SearchTab);
            app.RewardVolume4EditFieldLabel.Text = 'Reward Volume 4';
            app.RewardVolume4EditField = uieditfield(app.SearchTab, 'text');
            app.PresentCostTimestampEditFieldLabel = uilabel(app.SearchTab);
            app.PresentCostTimestampEditFieldLabel.Text = 'Present Cost Timestamp';
            app.PresentCostTimestampEditField = uieditfield(app.SearchTab, 'text');
            app.LightLevelEditFieldLabel = uilabel(app.SearchTab);
            app.LightLevelEditFieldLabel.Text = 'Light Level';
            app.LightLevelEditField = uieditfield(app.SearchTab, 'text');
            app.NotesEditFieldLabel = uilabel(app.SearchTab);
            app.NotesEditFieldLabel.Text = 'Notes';
            app.NotesEditField = uieditfield(app.SearchTab, 'text');
            app.RewardConcentration4EditFieldLabel = uilabel(app.SearchTab);
            app.RewardConcentration4EditFieldLabel.Text = 'Reward Concentration 4';
            app.RewardConcentration4EditField = uieditfield(app.SearchTab, 'text');
            app.RewardVolume1EditFieldLabel = uilabel(app.SearchTab);
            app.RewardVolume1EditFieldLabel.Text = 'Reward Volume 1';
            app.RewardVolume1EditField = uieditfield(app.SearchTab, 'text');
            app.RewardVolume2EditFieldLabel = uilabel(app.SearchTab);
            app.RewardVolume2EditFieldLabel.Text = 'Reward Volume 2';
            app.RewardVolume2EditField = uieditfield(app.SearchTab, 'text');
            app.RewardVolume3EditFieldLabel = uilabel(app.SearchTab);
            app.RewardVolume3EditFieldLabel.Text = 'Reward Volume 3';
            app.RewardVolume3EditField = uieditfield(app.SearchTab, 'text');
            app.CageMateEditFieldLabel = uilabel(app.SearchTab);
            app.CageMateEditFieldLabel.Text = 'Cage Mate';
            app.CageMateEditField = uieditfield(app.SearchTab, 'text');
            app.FeederEditFieldLabel = uilabel(app.SearchTab);
            app.FeederEditFieldLabel.Text = 'Feeder';
            app.FeederEditField = uieditfield(app.SearchTab, 'text');
            app.StopTrackEditFieldLabel = uilabel(app.SearchTab);
            app.StopTrackEditFieldLabel.Text = 'Stop Track';
            app.StopTrackEditField = uieditfield(app.SearchTab, 'text');
            app.TrialNameEditFieldLabel = uilabel(app.SearchTab);
            app.TrialNameEditFieldLabel.Text = 'Trial Name';
            app.TrialNameEditField = uieditfield(app.SearchTab, 'text');
            app.DetectionSettingsEditFieldLabel = uilabel(app.SearchTab);
            app.DetectionSettingsEditFieldLabel.Text = 'Detection Settings';
            app.DetectionSettingsEditField = uieditfield(app.SearchTab, 'text');
            app.TrialControlSettingsEditFieldLabel = uilabel(app.SearchTab);
            app.TrialControlSettingsEditFieldLabel.Text = 'Trial Control Settings';
            app.TrialControlSettingsEditField = uieditfield(app.SearchTab, 'text');
            app.ReferenceDurationEditFieldLabel = uilabel(app.SearchTab);
            app.ReferenceDurationEditFieldLabel.Text = 'Reference Duration';
            app.ReferenceDurationEditField = uieditfield(app.SearchTab, 'text');
            app.BoxOfferDeliveryEditFieldLabel = uilabel(app.SearchTab);
            app.BoxOfferDeliveryEditFieldLabel.Text = 'Box # Offer Delivery';
            app.BoxOfferDeliveryEditField = uieditfield(app.SearchTab, 'text');
            app.BoxNoOfferDeliveryEditFieldLabel = uilabel(app.SearchTab);
            app.BoxNoOfferDeliveryEditFieldLabel.Text = 'Box # No Offer Delivery';
            app.BoxNoOfferDeliveryEditField = uieditfield(app.SearchTab, 'text');
            app.StartTimeEditFieldLabel = uilabel(app.SearchTab);
            app.StartTimeEditFieldLabel.Text = 'Start Time';
            app.StartTimeEditField = uieditfield(app.SearchTab, 'text');
            app.RecordingAfterEditFieldLabel = uilabel(app.SearchTab);
            app.RecordingAfterEditFieldLabel.Text = 'Recording After';
            app.RecordingAfterEditField = uieditfield(app.SearchTab, 'text');
            app.RecordingDurationEditFieldLabel = uilabel(app.SearchTab);
            app.RecordingDurationEditFieldLabel.Text = 'Recording Duration';
            app.RecordingDurationEditField = uieditfield(app.SearchTab, 'text');
            app.TrialDurationEditFieldLabel = uilabel(app.SearchTab);
            app.TrialDurationEditFieldLabel.Text = 'Trial Duration';
            app.TrialDurationEditField = uieditfield(app.SearchTab, 'text');
            app.BoxCostOffEditFieldLabel = uilabel(app.SearchTab);
            app.BoxCostOffEditFieldLabel.Text = 'Box # Cost Off';
            app.BoxCostOffEditField = uieditfield(app.SearchTab, 'text');
            app.Level1Label = uilabel(app.SearchTab);
            app.Level1Label.Text = 'Level 1';
            app.Level2Label = uilabel(app.SearchTab);
            app.Level2Label.Text = 'Level 2';
            app.Level3Label = uilabel(app.SearchTab);
            app.Level3Label.Text = 'Level 3';
            app.Level4Label = uilabel(app.SearchTab);
            app.Level4Label.Text = 'Level 4';
            app.Port1Label = uilabel(app.SearchTab);
            app.Port1Label.Text = 'Port 1';
            app.Port2Label = uilabel(app.SearchTab);
            app.Port2Label.Text = 'Port 2';
            app.Port3Label = uilabel(app.SearchTab);
            app.Port3Label.Text = 'Port 3';
            app.Port4Label = uilabel(app.SearchTab);
            app.Port4Label.Text = 'Port 4';
            app.BasicLabel = uilabel(app.SearchTab);
            app.BasicLabel.Text = 'Basic____________________________________________________________________________________';
            app.BasicLabel_2 = uilabel(app.SearchTab);
            app.BasicLabel_2.Text = 'Advanced_______________________________________________________________________________________';
            app.SearchResultsLabel = uilabel(app.SearchTab);
            app.SearchResultsLabel.Text = 'Search Results';
            app.TimeStamp_____________________________________Label = uilabel(app.SearchTab);
            app.TimeStamp_____________________________________Label.Text = 'Time Stamps______________________________________________________________________________________________';
            app.BasicInstructions___________________________Label = uilabel(app.SearchTab);
            app.BasicInstructions___________________________Label.Text = 'Basic Instructions___________________________';
            app.DatabaseConnectionEditFieldLabel = uilabel(app.SearchTab);
            app.DatabaseConnectionEditFieldLabel.Text = 'Database Connection';
            app.DatabaseConnectionEditField = uieditfield(app.SearchTab, 'text');
            app.ExportSearchResultToFileButton = uibutton(app.SearchTab, 'push');
            app.ExportSearchResultToFileButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSearchResultToFileButtonPushed, true);
            app.ExportSearchResultToFileButton.Text = 'Export Search Result To File';
            app.DateofSessionEditFieldLabel = uilabel(app.SearchTab);
            app.DateofSessionEditFieldLabel.Text = 'Date of Session';
            app.DateofSessionEditField = uieditfield(app.SearchTab, 'text');
            app.ImportAndGraphResultsButton = uibutton(app.SearchTab, 'push');
            app.ImportAndGraphResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportAndGraphResultsButtonPushed, true);
            app.ImportAndGraphResultsButton.Text = 'Import And Graph Results';
            app.FileEditField = uieditfield(app.SearchTab, 'text');
            app.AddMoreResultstoFileButton = uibutton(app.SearchTab, 'push');
            app.AddMoreResultstoFileButton.ButtonPushedFcn = createCallbackFcn(app, @AddMoreResultstoFileButtonPushed, true);
            app.AddMoreResultstoFileButton.Text = 'Add More Results to File';
            app.SecondFileEditField = uieditfield(app.SearchTab, 'text');
            app.GraphTheseSearchResultsButton = uibutton(app.SearchTab, 'push');
            app.GraphTheseSearchResultsButton.ButtonPushedFcn = createCallbackFcn(app, @GraphTheseSearchResultsButtonPushed, true);
            app.GraphTheseSearchResultsButton.Text = 'Graph These Search Results';
            app.AnalyzeBehaviorTab = uitab(app.TabGroup);
            app.AnalyzeBehaviorTab.Title = 'Analyze Behavior';
            app.UIAxes = uiaxes(app.AnalyzeBehaviorTab);
            xlabel(app.UIAxes, 'Concentration of Sucrose Reward (%)')
            ylabel(app.UIAxes, 'Choice of Sucrose Reward (%)')
            zlabel(app.UIAxes, 'Z')
            app.PlotButton = uibutton(app.AnalyzeBehaviorTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdvanceSearchWindow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end