classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel_3                        matlab.ui.container.Panel
        SeeUploadHistoryButton         matlab.ui.control.Button
        EditSwitch                     matlab.ui.control.Switch
        EditSwitchLabel                matlab.ui.control.Label
        SearchButton                   matlab.ui.control.Button
        DataSelectionLabel             matlab.ui.control.Label
        Panel_2                        matlab.ui.container.Panel
        Maze4Panel                     matlab.ui.container.Panel
        SelectRatDropDown_4            matlab.ui.control.DropDown
        SelectRatDropDown_4Label       matlab.ui.control.Label
        CageMatesTextArea_4            matlab.ui.control.TextArea
        CageMatesTextArea_4Label       matlab.ui.control.Label
        GenoTypeEditField_4            matlab.ui.control.EditField
        GenoTypeEditField_4Label       matlab.ui.control.Label
        BirthDateEditField_4           matlab.ui.control.EditField
        BirthDateEditField_7Label      matlab.ui.control.Label
        CageNumberEditField_4          matlab.ui.control.EditField
        CageNumberEditField_7Label     matlab.ui.control.Label
        GenderEditField_4              matlab.ui.control.EditField
        GenderEditField_7Label         matlab.ui.control.Label
        SubjectNameIDEditField_4       matlab.ui.control.EditField
        SubjectNameIDEditField_7Label  matlab.ui.control.Label
        HealthEditField_4              matlab.ui.control.EditField
        HealthEditField_7Label         matlab.ui.control.Label
        Maze3Panel                     matlab.ui.container.Panel
        SelectRatDropDown_3            matlab.ui.control.DropDown
        SelectRatDropDown_3Label       matlab.ui.control.Label
        CageMatesTextArea_3            matlab.ui.control.TextArea
        CageMatesTextArea_3Label       matlab.ui.control.Label
        GenoTypeEditField_3            matlab.ui.control.EditField
        GenoTypeEditField_3Label       matlab.ui.control.Label
        BirthDateEditField_3           matlab.ui.control.EditField
        BirthDateEditField_6Label      matlab.ui.control.Label
        CageNumberEditField_3          matlab.ui.control.EditField
        CageNumberEditField_6Label     matlab.ui.control.Label
        GenderEditField_3              matlab.ui.control.EditField
        GenderEditField_6Label         matlab.ui.control.Label
        SubjectNameIDEditField_3       matlab.ui.control.EditField
        SubjectNameIDEditField_6Label  matlab.ui.control.Label
        HealthEditField_3              matlab.ui.control.EditField
        HealthEditField_6Label         matlab.ui.control.Label
        Maze2Panel                     matlab.ui.container.Panel
        SelectRatDropDown_2            matlab.ui.control.DropDown
        SelectRatDropDown_2Label       matlab.ui.control.Label
        GenoTypeEditField_2            matlab.ui.control.EditField
        GenoTypeEditField_2Label       matlab.ui.control.Label
        CageMatesTextArea_2            matlab.ui.control.TextArea
        CageMatesTextArea_2Label       matlab.ui.control.Label
        BirthDateEditField_2           matlab.ui.control.EditField
        BirthDateEditField_5Label      matlab.ui.control.Label
        CageNumberEditField_2          matlab.ui.control.EditField
        CageNumberEditField_5Label     matlab.ui.control.Label
        GenderEditField_2              matlab.ui.control.EditField
        GenderEditField_5Label         matlab.ui.control.Label
        SubjectNameIDEditField_2       matlab.ui.control.EditField
        SubjectNameIDEditField_5Label  matlab.ui.control.Label
        HealthEditField_2              matlab.ui.control.EditField
        HealthEditField_5Label         matlab.ui.control.Label
        Maze1Panel                     matlab.ui.container.Panel
        SelectRatDropDown              matlab.ui.control.DropDown
        SelectRatDropDownLabel         matlab.ui.control.Label
        GenoTypeEditField              matlab.ui.control.EditField
        GenoTypeEditFieldLabel         matlab.ui.control.Label
        CageMatesTextArea              matlab.ui.control.TextArea
        CageMatesTextAreaLabel         matlab.ui.control.Label
        BirthDateEditField             matlab.ui.control.EditField
        BirthDateEditFieldLabel        matlab.ui.control.Label
        CageNumberEditField            matlab.ui.control.EditField
        CageNumberEditFieldLabel       matlab.ui.control.Label
        GenderEditField                matlab.ui.control.EditField
        GenderEditFieldLabel           matlab.ui.control.Label
        SubjectNameIDEditField         matlab.ui.control.EditField
        SubjectNameIDEditFieldLabel    matlab.ui.control.Label
        HealthEditField                matlab.ui.control.EditField
        HealthEditFieldLabel           matlab.ui.control.Label
        Panel_4                        matlab.ui.container.Panel
        DONTFORGETTOSETDATELabel       matlab.ui.control.Label
        DateMMDDYYYYEditField          matlab.ui.control.EditField
        DateMMDDYYYYLabel              matlab.ui.control.Label
        SetTodaysDateButton            matlab.ui.control.Button
        FileSelectedTextArea           matlab.ui.control.TextArea
        FileSelectedTextAreaLabel      matlab.ui.control.Label
        Maze4Panel_2                   matlab.ui.container.Panel
        RadialLabel_3                  matlab.ui.control.Label
        HorizontalLabel_3              matlab.ui.control.Label
        GridLabel_3                    matlab.ui.control.Label
        DiagonalLabel_3                matlab.ui.control.Label
        PortDropDown_4                 matlab.ui.control.DropDown
        PortDropDown_4Label            matlab.ui.control.Label
        Level4Label_4                  matlab.ui.control.Label
        Level3Label_4                  matlab.ui.control.Label
        Level2Label_4                  matlab.ui.control.Label
        Level1Label_4                  matlab.ui.control.Label
        NotesEditField_4               matlab.ui.control.EditField
        NotesEditField_4Label          matlab.ui.control.Label
        RewardProbabilityLabel_5       matlab.ui.control.Label
        RewardVolumeLabel_4            matlab.ui.control.Label
        RewardConcentrationLabel_4     matlab.ui.control.Label
        CostProbabilityLabel_4         matlab.ui.control.Label
        IntensityofCostLabel_4         matlab.ui.control.Label
        Port4EditField_RP_5            matlab.ui.control.EditField
        Port3EditField_RP_5            matlab.ui.control.EditField
        Port2EditField_RP_5            matlab.ui.control.EditField
        Port1EditField_RP_5            matlab.ui.control.EditField
        Port4EditField_RV_5            matlab.ui.control.EditField
        Port3EditField_RV_5            matlab.ui.control.EditField
        Port2EditField_RV_5            matlab.ui.control.EditField
        Port1EditField_RV_5            matlab.ui.control.EditField
        Port4EditField_CR_5            matlab.ui.control.EditField
        Port3EditField_CR_5            matlab.ui.control.EditField
        Port2EditField_CR_5            matlab.ui.control.EditField
        Port1EditField_CR_5            matlab.ui.control.EditField
        Port4EditField_CP_4            matlab.ui.control.EditField
        Port3EditField_CP_4            matlab.ui.control.EditField
        Port2EditField_CP_4            matlab.ui.control.EditField
        Port1EditField_CP_4            matlab.ui.control.EditField
        Port4EditField_CC_4            matlab.ui.control.EditField
        Port3EditField_CC_4            matlab.ui.control.EditField
        Port2EditField_CC_4            matlab.ui.control.EditField
        Port1EditField_CC_4            matlab.ui.control.EditField
        TaskTypeDoneEditField_4        matlab.ui.control.EditField
        TaskTypeDoneEditField_4Label   matlab.ui.control.Label
        ExperimenterEditField_4        matlab.ui.control.EditField
        ExperimenterEditField_4Label   matlab.ui.control.Label
        Maze3Panel_2                   matlab.ui.container.Panel
        RadialLabel_4                  matlab.ui.control.Label
        HorizontalLabel_4              matlab.ui.control.Label
        GridLabel_4                    matlab.ui.control.Label
        DiagonalLabel_4                matlab.ui.control.Label
        PortDropDown_3                 matlab.ui.control.DropDown
        PortDropDown_3Label            matlab.ui.control.Label
        Level4Label_3                  matlab.ui.control.Label
        Level3Label_3                  matlab.ui.control.Label
        Level2Label_3                  matlab.ui.control.Label
        Level1Label_3                  matlab.ui.control.Label
        NotesEditField_3               matlab.ui.control.EditField
        NotesEditField_3Label          matlab.ui.control.Label
        RewardProbabilityLabel_4       matlab.ui.control.Label
        RewardVolumeLabel_3            matlab.ui.control.Label
        RewardConcentrationLabel_3     matlab.ui.control.Label
        CostProbabilityLabel_3         matlab.ui.control.Label
        IntensityofCostLabel_3         matlab.ui.control.Label
        Port4EditField_RP_4            matlab.ui.control.EditField
        Port3EditField_RP_4            matlab.ui.control.EditField
        Port2EditField_RP_4            matlab.ui.control.EditField
        Port1EditField_RP_4            matlab.ui.control.EditField
        Port4EditField_RV_4            matlab.ui.control.EditField
        Port3EditField_RV_4            matlab.ui.control.EditField
        Port2EditField_RV_4            matlab.ui.control.EditField
        Port1EditField_RV_4            matlab.ui.control.EditField
        Port4EditField_CR_4            matlab.ui.control.EditField
        Port3EditField_CR_4            matlab.ui.control.EditField
        Port2EditField_CR_4            matlab.ui.control.EditField
        Port1EditField_CR_4            matlab.ui.control.EditField
        Port4EditField_CP_3            matlab.ui.control.EditField
        Port3EditField_CP_3            matlab.ui.control.EditField
        Port2EditField_CP_3            matlab.ui.control.EditField
        Port1EditField_CP_3            matlab.ui.control.EditField
        Port4EditField_CC_3            matlab.ui.control.EditField
        Port3EditField_CC_3            matlab.ui.control.EditField
        Port2EditField_CC_3            matlab.ui.control.EditField
        Port1EditField_CC_3            matlab.ui.control.EditField
        TaskTypeDoneEditField_3        matlab.ui.control.EditField
        TaskTypeDoneEditField_3Label   matlab.ui.control.Label
        ExperimenterEditField_3        matlab.ui.control.EditField
        ExperimenterEditField_3Label   matlab.ui.control.Label
        Maze2Panel_2                   matlab.ui.container.Panel
        RadialLabel_2                  matlab.ui.control.Label
        HorizontalLabel_2              matlab.ui.control.Label
        GridLabel_2                    matlab.ui.control.Label
        DiagonalLabel_2                matlab.ui.control.Label
        PortDropDown_2                 matlab.ui.control.DropDown
        PortDropDown_2Label            matlab.ui.control.Label
        Level4Label_2                  matlab.ui.control.Label
        Level3Label_2                  matlab.ui.control.Label
        Level2Label_2                  matlab.ui.control.Label
        Level1Label_2                  matlab.ui.control.Label
        NotesEditField_2               matlab.ui.control.EditField
        NotesEditField_2Label          matlab.ui.control.Label
        RewardProbabilityLabel_3       matlab.ui.control.Label
        RewardVolumeLabel_2            matlab.ui.control.Label
        RewardConcentrationLabel_2     matlab.ui.control.Label
        CostProbabilityLabel_2         matlab.ui.control.Label
        IntensityofCostLabel_2         matlab.ui.control.Label
        Port4EditField_RP_3            matlab.ui.control.EditField
        Port3EditField_RP_3            matlab.ui.control.EditField
        Port2EditField_RP_3            matlab.ui.control.EditField
        Port1EditField_RP_3            matlab.ui.control.EditField
        Port4EditField_RV_3            matlab.ui.control.EditField
        Port3EditField_RV_3            matlab.ui.control.EditField
        Port2EditField_RV_3            matlab.ui.control.EditField
        Port1EditField_RV_3            matlab.ui.control.EditField
        Port4EditField_CR_3            matlab.ui.control.EditField
        Port3EditField_CR_3            matlab.ui.control.EditField
        Port2EditField_CR_3            matlab.ui.control.EditField
        Port1EditField_CR_3            matlab.ui.control.EditField
        Port4EditField_CP_2            matlab.ui.control.EditField
        Port3EditField_CP_2            matlab.ui.control.EditField
        Port2EditField_CP_2            matlab.ui.control.EditField
        Port1EditField_CP_2            matlab.ui.control.EditField
        Port4EditField_CC_2            matlab.ui.control.EditField
        Port3EditField_CC_2            matlab.ui.control.EditField
        Port2EditField_CC_2            matlab.ui.control.EditField
        Port1EditField_CC_2            matlab.ui.control.EditField
        TaskTypeDoneEditField_2        matlab.ui.control.EditField
        TaskTypeDoneEditField_2Label   matlab.ui.control.Label
        ExperimenterEditField_2        matlab.ui.control.EditField
        ExperimenterEditField_2Label   matlab.ui.control.Label
        AddDataToDatabaseButton        matlab.ui.control.Button
        SelectNodulusFileButton        matlab.ui.control.Button
        Maze1Panel_2                   matlab.ui.container.Panel
        PortDropDown                   matlab.ui.control.DropDown
        PortDropDownLabel              matlab.ui.control.Label
        Level4Label                    matlab.ui.control.Label
        Level3Label                    matlab.ui.control.Label
        Level2Label                    matlab.ui.control.Label
        Level1Label                    matlab.ui.control.Label
        NotesEditField                 matlab.ui.control.EditField
        NotesEditFieldLabel            matlab.ui.control.Label
        RadialLabel                    matlab.ui.control.Label
        HorizontalLabel                matlab.ui.control.Label
        GridLabel                      matlab.ui.control.Label
        DiagonalLabel                  matlab.ui.control.Label
        RewardProbabilityLabel_2       matlab.ui.control.Label
        RewardVolumeLabel              matlab.ui.control.Label
        RewardConcentrationLabel       matlab.ui.control.Label
        CostProbabilityLabel           matlab.ui.control.Label
        IntensityofCostLabel           matlab.ui.control.Label
        Port4EditField_RP_2            matlab.ui.control.EditField
        Port3EditField_RP_2            matlab.ui.control.EditField
        Port2EditField_RP_2            matlab.ui.control.EditField
        Port1EditField_RP_2            matlab.ui.control.EditField
        Port4EditField_RV_2            matlab.ui.control.EditField
        Port3EditField_RV_2            matlab.ui.control.EditField
        Port2EditField_RV_2            matlab.ui.control.EditField
        Port1EditField_RV_2            matlab.ui.control.EditField
        Port4EditField_CR_2            matlab.ui.control.EditField
        Port3EditField_CR_2            matlab.ui.control.EditField
        Port2EditField_CR_2            matlab.ui.control.EditField
        Port1EditField_CR_2            matlab.ui.control.EditField
        Port4EditField_CP              matlab.ui.control.EditField
        Port3EditField_CP              matlab.ui.control.EditField
        Port2EditField_CP              matlab.ui.control.EditField
        Port1EditField_CP              matlab.ui.control.EditField
        Port4EditField_CC              matlab.ui.control.EditField
        Port3EditField_CC              matlab.ui.control.EditField
        Port2EditField_CC              matlab.ui.control.EditField
        Port1EditField_CC              matlab.ui.control.EditField
        TaskTypeDoneEditField          matlab.ui.control.EditField
        TaskTypeDoneEditFieldLabel     matlab.ui.control.Label
        ExperimenterEditField          matlab.ui.control.EditField
        ExperimenterEditFieldLabel     matlab.ui.control.Label
        ContextMenu                    matlab.ui.container.ContextMenu
        Menu                           matlab.ui.container.Menu
        Menu2                          matlab.ui.container.Menu
    end


    properties (Access = private)
        rat1 table % the data of the rat currently stored in maze 1
        rat2 table %data of the rat in maze 2
        rat3 table %data of rat in maze 3
        rat4 table %data of rat in maze 4
        numberOfRats double

        actual_table string = "livetable2"  %the table in the database being written to
        rattable string = "rattable"; %name of the active rat table
        password string = "1234"; %pw for the database
        datasource string = "live_database"; %datasource
        username string = "postgres"; %username
        IPaddress string;
        jarFilePath string;

        files struct; %the names of all files in the user defined folder
        workingDirectory string; %the file path for the user defined folder

        info string =[[] [] [] [] [] [] [] [] [] [] [] [] [] [] [] [] [] [] [] [] [] [] [] [] [] [] [] [] [] [] [] [] [] ]; %all info parsed from nodulus is stored here
        universalColumnRow cell = []; %the (x,y) coordinates of the desired variables in the nodulus file
        universalCounter double = 0;

        variableList string = ["subjectID","gender","birthdate","genotype","cagenumber","health","cagemates","experimenter","tasktypedone","notes","intensityofcost1","intensityofcost2","intensityofcost3","intensityofcost4","costprobability1","costprobability2","costprobability3","costprobability4","rewardconcentration1","rewardconcentration2","rewardconcentration3","rewardconcentration4","rewardvolume1","rewardvolume2","rewardvolume3","rewardvolume4","rewardprobability1","rewardprobability2","rewardprobability3","rewardprobability4","mazenumber","approachavoidtimestamp","approachavoid","playstarttrialtone","presentcost","lightlevel","referencetime","videostartime","feeder","stoptrack","trialname","detectionsettings","trialcontrolsettings","referenceduration","animalid","mazeofferdelivery","mazenoofferdelivery","starttime","recordingafter","recordingduration","trialduration","mazecostoff", "coordinatetimes", "xcoordinates", "ycoordinates", "presentcostend", "costpresenetationfinish", "stopincopixrecording", "decisionmakingtime","startincopixrecording", "date", "activezonetimestamp","activezonevalue","presentcostontimestamp","costacknowledgementtimestamp","deliveryacknowledgementtimestamp","coordinatetimes2","xcoordinates2","ycoordinates2"];

        universalCurrentNodulusFile table; %the nodulus file currently being parsed
        universalVariablesWeWant table; %table of the variables we want


        rat_1_timestamps string; %shuffled rat 1 timestamps for position
        rat_1_x_coordinates string; % shuffled rat 1 x coordinates for position
        rat_1_y_coordinates string; % shuffled rat 1 y coordinates for position

        rat_2_timestamps string; % shuffled rat 2 timestamps for position
        rat_2_x_coordinates string;%shuffled rat 2 x coordinates for position
        rat_2_y_coordinates string;%shuffled rat 2 y coordinates for position

        rat_3_timestamps string;% shuffled rat 3 timestamps for position
        rat_3_x_coordinates string;%shuffled rat 3 x coordinates for position
        rat_3_y_coordinates string;%shuffled rat 3 y coordinates for position

        rat_4_timestamps string;%shuffled rat 4 timestamps for position
        rat_4_x_coordinates string;%shuffled rat 4 x coordinates for position
        rat_4_y_coordinates string;%shuffled rat 4 y coordinates for position
        
        actualRat1Timestamps string =  ["No" "Value" "found"]; %unshuffled rat 1 timestamps
        actualRat1XCoordinates string =  ["No" "Value" "found"]; %unshuffled rat 1 x coordinates
        actualRat1YCoordinates string =  ["No" "Value" "found"]; %unshuffled rat 1 y coordinates
        
        actualRat2Timestamps string =  ["No" "Value" "found"]; %unshuffled rat 2 timestamps
        actualRat2XCoordinates string =  ["No" "Value" "found"]; %unshuffled rat 2 x coordinates
        actualRat2YCoordinates string =  ["No" "Value" "found"]; %unshuffled rat 2 y coordinates
        
        actualRat3Timestamps string =  ["No" "Value" "found"]; %unshuffled rat 3 timestamps
        actualRat3XCoordinates string=  ["No" "Value" "found"]; %unshuffled rat 3 x coordinates
        actualRat3YCoordinates string=  ["No" "Value" "found"]; %unshuffled rat 3 y coordinates
        
        actualRat4Timestamps string=  ["No" "Value" "found"]; %unshuffled rat 4 timestamps
        actualRat4XCoordinates string=  ["No" "Value" "found"]; %unshuffled rat 4 x coordinates
        actualRat4YCoordinates string=  ["No" "Value" "found"]; %unshuffled rat 4 y coordinates
        

        driverPath string = '';
        IPAddress string = '';

        replaceAllDuplicates double = 0;

        RatsInAppMatchFilePath double = 1;
        RatsInAppMatchRatsInFile double = 1;

        RatsInPathMatchRatsInFile double = 1;
        
        namesFoundUniversal string
        
        universalContainer;


    end

    methods (Access = private)

        function results(app)
            j = 1;

            while j<= height(app.universalVariablesWeWant)
                try
                    current = app.universalContainer(lower(string(app.universalVariablesWeWant{j,1})));
                    row = current(1);
                    column = current(2);
                    lookingAt = string(app.universalVariablesWeWant{j,1});
%                     display(lookingAt)
                    
                    switch lower(lookingAt)
                        case "accepted"
                            %Timestamp, variable name, value
                            app.info(1) = string(app.universalCurrentNodulusFile{row,2});
                            app.info(2) = string(app.universalCurrentNodulusFile{row,column});
                            app.info(3) = string(app.universalCurrentNodulusFile{row,column+1});
                        case "accepted2"
                            %Timestamp, variable name, value
                            app.info(4) = string(app.universalCurrentNodulusFile{row,2});
                            app.info(5) = string(app.universalCurrentNodulusFile{row,column});
                            app.info(6) = string(app.universalCurrentNodulusFile{row,column+1});
                        case "accepted3"
                            %Timestamp, variable name, value
                            app.info(7) = string(app.universalCurrentNodulusFile{row,2});
                            app.info(8)= string(app.universalCurrentNodulusFile{row,column});
                            app.info(9) =  string(app.universalCurrentNodulusFile{row,column+1});
                        case "accepted4"

                            %Timestamp, variable name, value
                            app.info(10) = string(app.universalCurrentNodulusFile{row,2});
                            app.info(11) = string(app.universalCurrentNodulusFile{row,column});
                            app.info(12) = string(app.universalCurrentNodulusFile{row,column+1});
                        case "play start trial tone"
                            %Timestamp, variable name
                            app.info(13) =string(app.universalCurrentNodulusFile{row,2});
                            app.info(14) = string(app.universalCurrentNodulusFile{row,column});
                        case "present cost"
                            %Timestamp, variable name
                            app.info(15) = string(app.universalCurrentNodulusFile{row,2});
                            app.info(16) = string(app.universalCurrentNodulusFile{row,column});
                        case "light level"
                            %variable name, value
                            app.info(17) = string(app.universalCurrentNodulusFile{row,column});
                            app.info(18)= string(app.universalCurrentNodulusFile{row,column+1});
                        case "reference time"
                            %variable name, value
                            app.info(19)= string(app.universalCurrentNodulusFile{row,column});
                            app.info(20) = string(app.universalCurrentNodulusFile{row,column+1});
                        case "video start time"
                            app.info(21) = string(app.universalCurrentNodulusFile{row,column});
                            app.info(22) = string(app.universalCurrentNodulusFile{row,column+1});
                        case "feeder"
                            %variable name, value
                            app.info(23)= string(app.universalCurrentNodulusFile{row,column});
                            app.info(24) = string(app.universalCurrentNodulusFile{row,column+1});
                        case "stop track"
                            %Timestamp, variable name
                            app.info(25) = string(app.universalCurrentNodulusFile{row,2});
                            app.info(26) = string(app.universalCurrentNodulusFile{row,column});
                        case "trial name"
                            %variable name, value
                            app.info(27) = string(app.universalCurrentNodulusFile{row,column});
                            app.info(28)= string(app.universalCurrentNodulusFile{row,column+1});
                        case "detection settings"
                            %variable name, value
                            app.info(29) = string(app.universalCurrentNodulusFile{row,column});
                            app.info(30) = string(app.universalCurrentNodulusFile{row,column+1});
                        case "trial control settings"
                            %variable name, value
                            app.info(31) = string(app.universalCurrentNodulusFile{row,column});
                            app.info(32) =string(app.universalCurrentNodulusFile{row,column+1});
                        case "reference duration"
                            %variable name, value
                            app.info(33) = string(app.universalCurrentNodulusFile{row,column});
                            app.info(34) = string(app.universalCurrentNodulusFile{row,column+1});
                        case "animal id"
                            %variable name, value1, value 2, value 3, value 4
                            app.info(35) = string(app.universalCurrentNodulusFile{row,column});
                            app.info(36) = string(app.universalCurrentNodulusFile{row,column+1});
                            app.info(37) = string(app.universalCurrentNodulusFile{row,column+2});
                            app.info(38) = string(app.universalCurrentNodulusFile{row,column+3});
                            app.info(39) = string(app.universalCurrentNodulusFile{row,column+4});
                        case "box 1 offer delivery"
                            %Timestamp, variable name
                            app.info(40) = string(app.universalCurrentNodulusFile{row,2});
                            app.info(41) = string(app.universalCurrentNodulusFile{row,column});
                        case "box 2 offer delivery"
                            %Timestamp, variable name
                            app.info(42) = string(app.universalCurrentNodulusFile{row,2});
                            app.info(43) = string(app.universalCurrentNodulusFile{row,column});
                        case "box 3 offer delivery"
                            %Timestamp, variable name
                            app.info(44) = string(app.universalCurrentNodulusFile{row,2});
                            app.info(45) = string(app.universalCurrentNodulusFile{row,column});
                        case "box 4 offer delivery"
                            %Timestamp, variable name
                            app.info(46) = string(app.universalCurrentNodulusFile{row,2});
                            app.info(47) =  string(app.universalCurrentNodulusFile{row,column});
                        case "box 1 no offer delivery"
                            %Timestamp, variable name
                            app.info(48) = string(app.universalCurrentNodulusFile{row,2});
                            app.info(49) = string(app.universalCurrentNodulusFile{row,column});
                        case "box 2 no offer delivery"
                            %Timestamp, variable name
                            app.info(50) = string(app.universalCurrentNodulusFile{row,2});
                            app.info(51) = string(app.universalCurrentNodulusFile{row,column});
                        case "box 3 no offer delivery"
                            %Timestamp, variable name
                            app.info(52) = string(app.universalCurrentNodulusFile{row,2});
                            app.info(53) = string(app.universalCurrentNodulusFile{row,column});
                        case "box 4 no offer delivery"
                            %Timestamp, variable name
                            app.info(54) = string(app.universalCurrentNodulusFile{row,2});
                            app.info(55) = string(app.universalCurrentNodulusFile{row,column});
                        case "start time"
                            %variable name, value
                            app.info(56) = string(app.universalCurrentNodulusFile{row,column});
                            app.info(57) = string(app.universalCurrentNodulusFile{row,column+1});
                        case "recording after"
                            %variable name, value
                            app.info(58) = string(app.universalCurrentNodulusFile{row,column});
                            app.info(59) = string(app.universalCurrentNodulusFile{row,column+1});
                        case "recording duration"
                            %variable name, value
                            app.info(60) = string(app.universalCurrentNodulusFile{row,column});
                            app.info(61) = string(app.universalCurrentNodulusFile{row,column+1});
                        case "trial duration"
                            %variable name, value
                            app.info(62) = string(app.universalCurrentNodulusFile{row,column});
                            app.info(63) = string(app.universalCurrentNodulusFile{row,column+1});
                        case "box 1 cost off"
                            %Timestamp, variable name, value
                            app.info(65) = string(app.universalCurrentNodulusFile{row,2});
                            app.info(66) = string(app.universalCurrentNodulusFile{row,column});
                        case "box 2 cost off"
                            %Timestamp, variable name, value
                            app.info(67) = string(app.universalCurrentNodulusFile{row,2});
                            app.info(68) =string(app.universalCurrentNodulusFile{row,column});
                        case "box 3 cost off"
                            %Timestamp, variable name, value
                            app.info(69) = string(app.universalCurrentNodulusFile{row,2});
                            app.info(70) = string(app.universalCurrentNodulusFile{row,column});
                        case "box 4 cost off"
                            %Timestamp, variable name, value
                            app.info(71) = string(app.universalCurrentNodulusFile{row,2});
                            app.info(72) = string(app.universalCurrentNodulusFile{row,column});
                        case "present cost end"
                            app.info(73) = string(app.universalCurrentNodulusFile{row,2});
                            app.info(74) = string(app.universalCurrentNodulusFile{row,column});
                        case "cost presentation finish"
                            app.info(75) = string(app.universalCurrentNodulusFile{row,2});
                            app.info(76) = string(app.universalCurrentNodulusFile{row,column});
                        case "stop incopix recording"
                            app.info(77) = string(app.universalCurrentNodulusFile{row,2});
                            app.info(78) = string(app.universalCurrentNodulusFile{row,column});
                        case "decision making time"
                            app.info(79) = string(app.universalCurrentNodulusFile{row,2});
                            app.info(80) = string(app.universalCurrentNodulusFile{row,column});
                        case "start incopix recording"
                            app.info(81) = string(app.universalCurrentNodulusFile{row,2});
                            app.info(82) = string(app.universalCurrentNodulusFile{row,column});
                        case "activezone_b1"
                            %timestamp and value to the right
                            app.info(83) = string(app.universalCurrentNodulusFile{row,2});
                            app.info(84) = string(app.universalCurrentNodulusFile{row,column+1});
                        case "activezone_b2"
                            %timestamp and value to the right
                            app.info(85) = string(app.universalCurrentNodulusFile{row,2});
                            app.info(86) = string(app.universalCurrentNodulusFile{row,column+1});
                        case "activezone_b3"
                            %timestamp and value to the right
                            app.info(87) = string(app.universalCurrentNodulusFile{row,2});
                            app.info(88) = string(app.universalCurrentNodulusFile{row,column+1});
                        case "activezone_b4"
                            %timestamp and value to the right
                            app.info(89) = string(app.universalCurrentNodulusFile{row,2});
                            app.info(90) = string(app.universalCurrentNodulusFile{row,column+1});
                        case "present cost on b1 hori"
                            %timestamp
                            app.info(91) = string(app.universalCurrentNodulusFile{row,2});
                        case "present cost on b2 hori"
                            %timestamp
                            app.info(92) = string(app.universalCurrentNodulusFile{row,2});
                        case "present cost on b3 hori"
                            %timestamp
                            app.info(93) = string(app.universalCurrentNodulusFile{row,2});
                        case "present cost on b4 hori"
                            %timestamp
                            app.info(94) = string(app.universalCurrentNodulusFile{row,2});
                        case "present cost on b1 radi"
                            %timestamp
                            app.info(102) = string(app.universalCurrentNodulusFile{row,2});
                        case "present cost on b2 radi"
                            %timestamp
                            app.info(103) = string(app.universalCurrentNodulusFile{row,2});
                        case "present cost on b3 radi"
                            %timestamp
                            app.info(104) = string(app.universalCurrentNodulusFile{row,2});
                        case "present cost on b4 radi"
                            %timestamp
                            app.info(105) = string(app.universalCurrentNodulusFile{row,2});
                        case "present cost on b1 diag"
                            %timestamp
                            app.info(106) = string(app.universalCurrentNodulusFile{row,2});
                        case "present cost on b2 diag"
                            %timestamp
                            app.info(107) = string(app.universalCurrentNodulusFile{row,2});
                        case "present cost on b3 diag"
                            %timestamp
                            app.info(108) = string(app.universalCurrentNodulusFile{row,2});
                        case "present cost on b4 diag"
                            %timestamp
                            app.info(109) = string(app.universalCurrentNodulusFile{row,2});
                        case "present cost on b1 grid"
                            %timestamp
                            app.info(110) = string(app.universalCurrentNodulusFile{row,2});
                        case "present cost on b2 grid"
                            %timestamp
                            app.info(111) = string(app.universalCurrentNodulusFile{row,2});
                        case "present cost on b3 grid"
                            %timestamp
                            app.info(112) = string(app.universalCurrentNodulusFile{row,2});
                        case "present cost on b4 grid"
                            %timestamp
                            app.info(113) = string(app.universalCurrentNodulusFile{row,2});
                        case "cost ack 1"
                            %timestamp
                            app.info(95) = string(app.universalCurrentNodulusFile{row,2});
                        case "cost ack 2"
                            %timestamp
                            app.info(96) = string(app.universalCurrentNodulusFile{row,2});
                        case "cost ack 3"
                            %timestamp
                            app.info(97) = string(app.universalCurrentNodulusFile{row,2});
                        case "cost ack 4"
                            %timestamp
                            app.info(98) = string(app.universalCurrentNodulusFile{row,2});
                        case "delivery ack 1"
                            %timestamp
                            app.info(99) = string(app.universalCurrentNodulusFile{row,2});
                        case "delivery ack 2"
                            %timestamp
                            app.info(100) = string(app.universalCurrentNodulusFile{row,2});
                        case "delivery ack 3"
                            %timestamp
                            app.info(101) = string(app.universalCurrentNodulusFile{row,2});
                        case "delivery ack 4"
                            %timestamp
                            app.info(114) = string(app.universalCurrentNodulusFile{row,2});
                    end
                catch

                    lookingAt = string(app.universalVariablesWeWant{j,1});

                    %                     display(lookingAt)
                    switch lower(lookingAt)
                        case "accepted"
                            %Timestamp, variable name, value
                            app.info(1) = "NOT FOUND IN NODULUS";
                            app.info(2) = "NOT FOUND IN NODULUS";
                            app.info(3) = "NOT FOUND IN NODULUS";
                        case "accepted2"
                            %Timestamp, variable name, value
                            app.info(4) = "NOT FOUND IN NODULUS";
                            app.info(5) = "NOT FOUND IN NODULUS";
                            app.info(6) = "NOT FOUND IN NODULUS";
                        case "accepted3"
                            %Timestamp, variable name, value
                            app.info(7) = "NOT FOUND IN NODULUS";
                            app.info(8)= "NOT FOUND IN NODULUS";
                            app.info(9) =  "NOT FOUND IN NODULUS";
                        case "accepted4"

                            %Timestamp, variable name, value
                            app.info(10) = "NOT FOUND IN NODULUS";
                            app.info(11) = "NOT FOUND IN NODULUS";
                            app.info(12) = "NOT FOUND IN NODULUS";
                        case "play start trial tone"
                            %Timestamp, variable name
                            app.info(13) ="NOT FOUND IN NODULUS";
                            app.info(14) = "NOT FOUND IN NODULUS";
                        case "present cost"
                            %Timestamp, variable name
                            app.info(15) = "NOT FOUND IN NODULUS";
                            app.info(16) = "NOT FOUND IN NODULUS";
                        case "light level"
                            %variable name, value
                            app.info(17) = "NOT FOUND IN NODULUS";
                            app.info(18)= "NOT FOUND IN NODULUS";
                        case "reference time"
                            %variable name, value
                            app.info(19)= "NOT FOUND IN NODULUS";
                            app.info(20) = "NOT FOUND IN NODULUS";
                        case "video start time"
                            app.info(21) = "NOT FOUND IN NODULUS";
                            app.info(22) = "NOT FOUND IN NODULUS";
                        case "feeder"
                            %variable name, value
                            app.info(23)= "NOT FOUND IN NODULUS";
                            app.info(24) = "NOT FOUND IN NODULUS";
                        case "stop track"
                            %Timestamp, variable name
                            app.info(25) = "NOT FOUND IN NODULUS";
                            app.info(26) = "NOT FOUND IN NODULUS";
                        case "trial name"
                            %variable name, value
                            app.info(27) = "NOT FOUND IN NODULUS";
                            app.info(28)= "NOT FOUND IN NODULUS";
                        case "detection settings"
                            %variable name, value
                            app.info(29) = "NOT FOUND IN NODULUS";
                            app.info(30) = "NOT FOUND IN NODULUS";
                        case "trial control settings"
                            %variable name, value
                            app.info(31) = "NOT FOUND IN NODULUS";
                            app.info(32) ="NOT FOUND IN NODULUS";
                        case "reference duration"
                            %variable name, value
                            app.info(33) = "NOT FOUND IN NODULUS";
                            app.info(34) = "NOT FOUND IN NODULUS";
                        case "animal id"
                            %variable name, value1, value 2, value 3, value 4
                            app.info(35) = "NOT FOUND IN NODULUS";
                            app.info(36) = "NOT FOUND IN NODULUS";
                            app.info(37) = "NOT FOUND IN NODULUS";
                            app.info(38) = "NOT FOUND IN NODULUS";
                            app.info(39) = "NOT FOUND IN NODULUS";
                        case "box 1 offer delivery"
                            %Timestamp, variable name
                            app.info(40) = "NOT FOUND IN NODULUS";
                            app.info(41) = "NOT FOUND IN NODULUS";
                        case "box 2 offer delivery"
                            %Timestamp, variable name
                            app.info(42) = "NOT FOUND IN NODULUS";
                            app.info(43) = "NOT FOUND IN NODULUS";
                        case "box 3 offer delivery"
                            %Timestamp, variable name
                            app.info(44) = "NOT FOUND IN NODULUS";
                            app.info(45) = "NOT FOUND IN NODULUS";
                        case "box 4 offer delivery"
                            %Timestamp, variable name
                            app.info(46) = "NOT FOUND IN NODULUS";
                            app.info(47) =  "NOT FOUND IN NODULUS";
                        case "box 1 no offer delivery"
                            %Timestamp, variable name
                            app.info(48) = "NOT FOUND IN NODULUS";
                            app.info(49) = "NOT FOUND IN NODULUS";
                        case "box 2 no offer delivery"
                            %Timestamp, variable name
                            app.info(50) = "NOT FOUND IN NODULUS";
                            app.info(51) = "NOT FOUND IN NODULUS";
                        case "box 3 no offer delivery"
                            %Timestamp, variable name
                            app.info(52) = "NOT FOUND IN NODULUS";
                            app.info(53) = "NOT FOUND IN NODULUS";
                        case "box 4 no offer delivery"
                            %Timestamp, variable name
                            app.info(54) = "NOT FOUND IN NODULUS";
                            app.info(55) = "NOT FOUND IN NODULUS";
                        case "start time"
                            %variable name, value
                            app.info(56) = "NOT FOUND IN NODULUS";
                            app.info(57) = "NOT FOUND IN NODULUS";
                        case "recording after"
                            %variable name, value
                            app.info(58) = "NOT FOUND IN NODULUS";
                            app.info(59) = "NOT FOUND IN NODULUS";
                        case "recording duration"
                            %variable name, value
                            app.info(60) = "NOT FOUND IN NODULUS";
                            app.info(61) = "NOT FOUND IN NODULUS";
                        case "trial duration"
                            %variable name, value
                            app.info(62) = "NOT FOUND IN NODULUS";
                            app.info(63) = "NOT FOUND IN NODULUS";
                        case "box 1 cost off"
                            %Timestamp, variable name, value
                            app.info(65) = "NOT FOUND IN NODULUS";
                            app.info(66) = "NOT FOUND IN NODULUS";
                        case "box 2 cost off"
                            %Timestamp, variable name, value
                            app.info(67) ="NOT FOUND IN NODULUS";
                            app.info(68) ="NOT FOUND IN NODULUS";
                        case "box 3 cost off"
                            %Timestamp, variable name, value
                            app.info(69) = "NOT FOUND IN NODULUS";
                            app.info(70) = "NOT FOUND IN NODULUS";
                        case "box 4 cost off"
                            %Timestamp, variable name, value
                            app.info(71) = "NOT FOUND IN NODULUS";
                            app.info(72) = "NOT FOUND IN NODULUS";
                        case "present cost end"
                            app.info(73) = "NOT FOUND IN NODULUS";
                            app.info(74) = "NOT FOUND IN NODULUS";
                        case "cost presentation finish"
                            app.info(75) = "NOT FOUND IN NODULUS";
                            app.info(76) = "NOT FOUND IN NODULUS";
                        case "stop incopix recording"
                            app.info(77) = "NOT FOUND IN NODULUS";
                            app.info(78) = "NOT FOUND IN NODULUS";
                        case "decision making time"
                            app.info(79) = "NOT FOUND IN NODULUS";
                            app.info(80) = "NOT FOUND IN NODULUS";
                        case "start incopix recording"
                            app.info(81) = "NOT FOUND IN NODULUS";
                            app.info(82) = "NOT FOUND IN NODULUS";
                        case "activezone_b1"
                            %timestamp and value to the right
                            app.info(83) = "NOT FOUND IN NODULUS";
                            app.info(84) = "NOT FOUND IN NODULUS";
                        case "activezone_b2"
                            %timestamp and value to the right
                            app.info(85) = "NOT FOUND IN NODULUS";
                            app.info(86) = "NOT FOUND IN NODULUS";
                        case "activezone_b3"
                            %timestamp and value to the right
                            app.info(87) = "NOT FOUND IN NODULUS";
                            app.info(88) = "NOT FOUND IN NODULUS";
                        case "activezone_b4"
                            %timestamp and value to the right
                            app.info(89) = "NOT FOUND IN NODULUS";
                            app.info(90) = "NOT FOUND IN NODULUS";
                        case "present cost on b1 hori"
                            %timestamp
                            app.info(91) = "NOT FOUND IN NODULUS";
                        case "present cost on b2 hori"
                            %timestamp
                            app.info(92) = "NOT FOUND IN NODULUS";
                        case "present cost on b3 hori"
                            %timestamp
                            app.info(93) = "NOT FOUND IN NODULUS";
                        case "present cost on b4 hori"
                            %timestamp
                            app.info(94) = "NOT FOUND IN NODULUS";
                        case "present cost on b1 radi"
                            %timestamp
                            app.info(102) = "NOT FOUND IN NODULUS";
                        case "present cost on b2 radi"
                            %timestamp
                            app.info(103) = "NOT FOUND IN NODULUS";
                        case "present cost on b3 radi"
                            %timestamp
                            app.info(104) = "NOT FOUND IN NODULUS";
                        case "present cost on b4 radi"
                            %timestamp
                            app.info(105) = "NOT FOUND IN NODULUS";
                        case "present cost on b1 diag"
                            %timestamp
                            app.info(106) = "NOT FOUND IN NODULUS";
                        case "present cost on b2 diag"
                            %timestamp
                            app.info(107) = "NOT FOUND IN NODULUS";
                        case "present cost on b3 diag"
                            %timestamp
                            app.info(108) = "NOT FOUND IN NODULUS";
                        case "present cost on b4 diag"
                            %timestamp
                            app.info(109) = "NOT FOUND IN NODULUS";
                        case "present cost on b1 grid"
                            %timestamp
                            app.info(110) ="NOT FOUND IN NODULUS";
                        case "present cost on b2 grid"
                            %timestamp
                            app.info(111) = "NOT FOUND IN NODULUS";
                        case "present cost on b3 grid"
                            %timestamp
                            app.info(112) = "NOT FOUND IN NODULUS";
                        case "present cost on b4 grid"
                            %timestamp
                            app.info(113) = "NOT FOUND IN NODULUS";
                        case "cost ack 1"
                            %timestamp
                            app.info(95) = "NOT FOUND IN NODULUS";
                        case "cost ack 2"
                            %timestamp
                            app.info(96) = "NOT FOUND IN NODULUS";
                        case "cost ack 3"
                            %timestamp
                            app.info(97) ="NOT FOUND IN NODULUS";
                        case "cost ack 4"
                            %timestamp
                            app.info(98) = "NOT FOUND IN NODULUS";
                        case "delivery ack 1"
                            %timestamp
                            app.info(99) = "NOT FOUND IN NODULUS";
                        case "delivery ack 2"
                            %timestamp
                            app.info(100) = "NOT FOUND IN NODULUS";
                        case "delivery ack 3"
                            %timestamp
                            app.info(101) = "NOT FOUND IN NODULUS";
                        case "delivery ack 4"
                            %timestamp
                            app.info(114) = "NOT FOUND IN NODULUS";
                    end
                    %                     warndlg(strcat(lookingAt, {' '}, 'was not found in the nodulus. "NOT FOUND IN NODULUS" was filled in its place.'))
                end
                j = j+1;
            end
            %creates 4 tables from each trial file, that contain the (X,Y) coordinates of the 4 rats
            rat_1_plot = readtable(strcat(app.workingDirectory,"/",app.files(app.universalCounter).name), 'Sheet',strcat('Track-Rat Arena 1a-Subject 1'));
            %display(rat_1_plot)
            rat_2_plot = readtable(strcat(app.workingDirectory,"/",app.files(app.universalCounter).name), 'Sheet',strcat('Track-Rat Arena 1a-Subject 2'));
            rat_3_plot = readtable(strcat(app.workingDirectory,"/",app.files(app.universalCounter).name), 'Sheet',strcat('Track-Rat Arena 1a-Subject 3'));
            rat_4_plot = readtable(strcat(app.workingDirectory,"/",app.files(app.universalCounter).name), 'Sheet',strcat('Track-Rat Arena 1a-Subject 4'));

            try
                %extracts the desired data from the rats
                time1 = rat_1_plot.RecordingTime;
                x1_coordinates = rat_1_plot.XCenter;
                y1_coordinates = rat_1_plot.YCenter;

                time2 = rat_2_plot.RecordingTime;
                x2_coordinates = rat_2_plot.XCenter;
                y2_coordinates = rat_2_plot.YCenter;

                time3 = rat_3_plot.RecordingTime;
                x3_coordinates = rat_3_plot.XCenter;
                y3_coordinates = rat_3_plot.YCenter;

                time4 = rat_4_plot.RecordingTime;
                x4_coordinates = rat_4_plot.XCenter;
                y4_coordinates = rat_4_plot.YCenter;
            catch 
                time1 = ["Not";"Found";"In";"Nodulus"];
                x1_coordinates =["Not";"Found";"In";"Nodulus"];
                y1_coordinates = ["Not";"Found";"In";"Nodulus"];

                time2 = ["Not";"Found";"In";"Nodulus"];
                x2_coordinates = ["Not";"Found";"In";"Nodulus"];
                y2_coordinates = ["Not";"Found";"In";"Nodulus"];

                time3 = ["Not";"Found";"In";"Nodulus"];
                x3_coordinates = ["Not";"Found";"In";"Nodulus"];
                y3_coordinates = ["Not";"Found";"In";"Nodulus"];

                time4 = ["Not";"Found";"In";"Nodulus"];
                x4_coordinates = ["Not";"Found";"In";"Nodulus"];
                y4_coordinates = ["Not";"Found";"In";"Nodulus"];
            end


            %converts from a multiple row, 1 column array to a muliple
            %column, 1 row array
            time1_asRow = string(time1.');
            x1_coordinates_asRow =string(x1_coordinates.');
            y1_coordinates_asRow =string(y1_coordinates.');

            time2_asRow = string(time2.');
            x2_coordinates_asRow =string(x2_coordinates.');
            y2_coordinates_asRow =string(y2_coordinates.');

            time3_asRow = string(time3.');
            x3_coordinates_asRow =string(x3_coordinates.');
            y3_coordinates_asRow =string(y3_coordinates.');

            time4_asRow = string(time4.');
            x4_coordinates_asRow =string(x4_coordinates.');
            y4_coordinates_asRow =string(y4_coordinates.');

            %stores the rats time and (x,y) coordinates the app
            %properties
            app.rat_1_timestamps = time1_asRow;
            app.rat_1_x_coordinates = x1_coordinates_asRow;
            app.rat_1_y_coordinates = y1_coordinates_asRow;

            app.rat_2_timestamps = time2_asRow;
            app.rat_2_x_coordinates = x2_coordinates_asRow;
            app.rat_2_y_coordinates = y2_coordinates_asRow;

            app.rat_3_timestamps = time3_asRow;
            app.rat_3_x_coordinates = x3_coordinates_asRow;
            app.rat_3_y_coordinates = y3_coordinates_asRow;

            app.rat_4_timestamps = time4_asRow;
            app.rat_4_x_coordinates = x4_coordinates_asRow;
            app.rat_4_y_coordinates = y4_coordinates_asRow;
            
            unshuffle(app)

        end

        function unshuffle(app)
            %display(app.rat_1_timestamps)
            

            rat1FirstXCoordinate =app.rat_1_x_coordinates(1);
            rat1FirstYCoordinate =app.rat_1_y_coordinates(1);
            i = 2;
            while ismissing(rat1FirstXCoordinate)
                rat1FirstXCoordinate = app.rat_1_x_coordinates(i);
                i = i+1;
            end
            i = 2;
            while ismissing(rat1FirstYCoordinate)
                rat1FirstYCoordinate = app.rat_1_y_coordinates(i);
                i = i+1;
            end
            
            rat2FirstXCoordinate = app.rat_2_x_coordinates(1);           
            rat2FirstYCoordinate = app.rat_2_y_coordinates(1);    
            i = 2;
            while ismissing(rat2FirstXCoordinate)
                rat2FirstXCoordinate = app.rat_2_x_coordinates(i);
                i = i+1;
            end
            i = 2;
            while ismissing(rat2FirstYCoordinate)
                rat2FirstYCoordinate = app.rat_2_y_coordinates(i);
                i = i+1;
            end
           
            rat3FirstXCoordinate = app.rat_3_x_coordinates(1);
            rat3FirstYCoordinate = app.rat_3_y_coordinates(1);
            i = 2;
            while ismissing(rat3FirstXCoordinate)
                rat3FirstXCoordinate = app.rat_3_x_coordinates(i);
                i = i+1;
            end
            i = 2;
            while ismissing(rat3FirstYCoordinate)
                rat3FirstYCoordinate = app.rat_3_y_coordinates(i);
                i = i+1;
            end
            
            rat4FirstXCoordinate = app.rat_4_x_coordinates(1);
            rat4FirstYCoordinate = app.rat_4_y_coordinates(1);
            i = 2;
            while ismissing(rat4FirstXCoordinate)
                rat4FirstXCoordinate = app.rat_4_x_coordinates(i);
                i = i+1;
            end
            i = 2;
            while ismissing(rat4FirstYCoordinate)
                disp("workd")
                rat4FirstYCoordinate = app.rat_4_y_coordinates(i);
                i = i+1;
            end
            
                      
%             display(strcat("This is the result from indexing rat 1 unshuffled the timestamps ",rat1FirstTimeStamp))
            display(strcat("This is the result from indexing rat 1 unshuffledthe x coords ",rat1FirstXCoordinate))
            display(strcat("This is the result from indexing rat 1 unshuffledthe y coords ",rat1FirstYCoordinate))

%             display(strcat("This is the result from indexing rat 2 unshuffled the timestamps ",rat2FirstTimeStamp))
            display(strcat("This is the result from indexing rat 2 unshuffledthe x coords ",rat2FirstXCoordinate))
            display(strcat("This is the result from indexing rat 2 unshuffledthe y coords ",rat2FirstYCoordinate))

%             display(strcat("This is the result from indexing rat 3 unshuffled the timestamps ",rat3FirstTimeStamp))
            display(strcat("This is the result from indexing rat 3 unshuffledthe x coords ",rat3FirstXCoordinate))
            display(strcat("This is the result from indexing rat 3 unshuffledthe y coords ",rat3FirstYCoordinate))

%             display(strcat("This is the result from indexing rat 4 unshuffled the timestamps ",rat4FirstTimeStamp))
            display(strcat("This is the result from indexing rat 4 unshuffledthe x coords ",string(rat4FirstXCoordinate)))
            display(class(rat4FirstYCoordinate))
            

            try
                disp(strcat("Rat 1 first (x,y): (",rat1FirstXCoordinate,",",rat1FirstYCoordinate,")"))
                if str2double(rat1FirstXCoordinate) >0
                    if str2double(rat1FirstYCoordinate) > 0
                        app.actualRat2Timestamps = app.rat_1_timestamps;
                        app.actualRat2XCoordinates =app.rat_1_x_coordinates;
                        app.actualRat2YCoordinates = app.rat_1_y_coordinates;
                        disp("The Rat 2 info was stored in the place of Rat 1")
                    elseif str2double(rat1FirstYCoordinate) < 0
                        app.actualRat4Timestamps = app.rat_1_timestamps;
                        app.actualRat4XCoordinates =app.rat_1_x_coordinates;
                        app.actualRat4YCoordinates = app.rat_1_y_coordinates;
                        disp("The Rat 4 info was stored in the place of Rat 1")
                    end
                elseif str2double(rat1FirstXCoordinate) < 0
                    if str2double(rat1FirstYCoordinate) > 0
                        app.actualRat1Timestamps = app.rat_1_timestamps;
                        app.actualRat1XCoordinates =app.rat_1_x_coordinates;
                        app.actualRat1YCoordinates = app.rat_1_y_coordinates;
                        disp("The Rat 1 info was stored in the place of Rat 1")
                    elseif str2double(rat1FirstYCoordinate) < 0
                        app.actualRat3Timestamps = app.rat_1_timestamps;
                        app.actualRat3XCoordinates =app.rat_1_x_coordinates;
                        app.actualRat3YCoordinates = app.rat_1_y_coordinates;
                        disp("The Rat 3 info was stored in the place of Rat 1")
                    end
                end
            catch
                disp("Rat 1 had no (x,y) coordinates to compare")
            end

            try
                disp(strcat("Rat 2 first (x,y): (",rat2FirstXCoordinate,",",rat2FirstYCoordinate,")"))
                if str2double(rat2FirstXCoordinate) >0
                    if str2double(rat2FirstYCoordinate) > 0
                        app.actualRat2Timestamps = app.rat_2_timestamps;
                        app.actualRat2XCoordinates =app.rat_2_x_coordinates;
                        app.actualRat2YCoordinates = app.rat_2_y_coordinates;
                        disp("The Rat 2 info was stored in the place of Rat 2")
                    elseif str2double(rat2FirstYCoordinate) < 0
                        app.actualRat4Timestamps = app.rat_2_timestamps;
                        app.actualRat4XCoordinates =app.rat_2_x_coordinates;
                        app.actualRat4YCoordinates = app.rat_2_y_coordinates;
                        disp("The Rat 4 info was stored in the place of Rat 2")
                    end
                elseif str2double(rat2FirstXCoordinate) < 0
                    if str2double(rat2FirstYCoordinate) > 0
                        app.actualRat1Timestamps = app.rat_2_timestamps;
                        app.actualRat1XCoordinates =app.rat_2_x_coordinates;
                        app.actualRat1YCoordinates = app.rat_2_y_coordinates;
                        disp("The Rat 1 info was stored in the place of Rat 2")
                    elseif str2double(rat2FirstYCoordinate) < 0
                        app.actualRat3Timestamps = app.rat_2_timestamps;
                        app.actualRat3XCoordinates =app.rat_2_x_coordinates;
                        app.actualRat3YCoordinates = app.rat_2_y_coordinates;
                        disp("The Rat 3 info was stored in the place of Rat 2")
                    end
                end
            catch
                disp("Rat 2 had no (x,y) coordinates to compare")
            end
            try
                disp(strcat("Rat 3 first (x,y): (",rat3FirstXCoordinate,",",rat3FirstYCoordinate,")"))
                if str2double(rat3FirstXCoordinate) >0
                    if str2double(rat3FirstYCoordinate) > 0
                        app.actualRat2Timestamps = app.rat_3_timestamps;
                        app.actualRat2XCoordinates =app.rat_3_x_coordinates;
                        app.actualRat2YCoordinates = app.rat_3_y_coordinates;
                        disp("The Rat 2 info was stored in the place of Rat 3")
                    elseif str2double(rat3FirstYCoordinate) < 0
                        app.actualRat4Timestamps = app.rat_3_timestamps;
                        app.actualRat4XCoordinates =app.rat_3_x_coordinates;
                        app.actualRat4YCoordinates = app.rat_3_y_coordinates;
                        disp("The Rat 4 info was stored in the place of Rat 3")
                    end
                elseif str2double(rat3FirstXCoordinate) < 0
                    if str2double(rat3FirstYCoordinate) > 0
                        app.actualRat1Timestamps = app.rat_3_timestamps;
                        app.actualRat1XCoordinates =app.rat_3_x_coordinates;
                        app.actualRat1YCoordinates = app.rat_3_y_coordinates;
                        disp("The Rat 1 info was stored in the place of Rat 3")
                    elseif str2double(rat3FirstYCoordinate) < 0
                        app.actualRat3Timestamps = app.rat_3_timestamps;
                        app.actualRat3XCoordinates =app.rat_3_x_coordinates;
                        app.actualRat3YCoordinates = app.rat_3_y_coordinates;
                        disp("The Rat 3 info was stored in the place of Rat 3")
                    end
                end
            catch
               disp("Rat 3 had no (x,y) coordinates to compare")
            end

            try
                disp(strcat("Rat 4 first (x,y): (",rat4FirstXCoordinate,",",rat4FirstYCoordinate,")"))
                if str2double(rat4FirstXCoordinate) >0
                    if str2double(rat4FirstYCoordinate) > 0
                        app.actualRat2Timestamps = app.rat_4_timestamps;
                        app.actualRat2XCoordinates =app.rat_4_x_coordinates;
                        app.actualRat2YCoordinates = app.rat_4_y_coordinates;
                        disp("The Rat 2 info was stored in the place of Rat 4")
                    elseif str2double(rat4FirstYCoordinate) < 0
                        app.actualRat4Timestamps = app.rat_4_timestamps;
                        app.actualRat4XCoordinates =app.rat_4_x_coordinates;
                        app.actualRat4YCoordinates = app.rat_4_y_coordinates;
                        disp("The Rat 4 info was stored in the place of Rat 4")
                    end
                elseif str2double(rat4FirstXCoordinate) < 0
                    if str2double(rat4FirstYCoordinate) > 0
                        app.actualRat1Timestamps = app.rat_4_timestamps;
                        app.actualRat1XCoordinates =app.rat_4_x_coordinates;
                        app.actualRat1YCoordinates = app.rat_4_y_coordinates;
                        disp("The Rat 1 info was stored in the place of Rat 4")
                    elseif str2double(rat4FirstYCoordinate) < 0
                        app.actualRat3Timestamps = app.rat_4_timestamps;
                        app.actualRat3XCoordinates =app.rat_4_x_coordinates;
                        app.actualRat3YCoordinates = app.rat_4_y_coordinates;
                        disp("The Rat 3 info was stored in the place of Rat 4")
                    end
                end
            catch
               disp("Rat 4 had no (x,y) coordinates to compare")
            end
        end
        function organize(app)
            %establishes connection to database
            conn = database(app.datasource,app.username,app.password);
            %active table in the database
            tablename = app.actual_table;


            %the following lines of code create 1 row in the database from
            %all the info gathered in previous parts, represeting the rat
            %in maze 1
            a = string(app.SubjectNameIDEditField.Value);   %rat 1 subjectID
            b = string(app.GenderEditField.Value);          %rat 1 gender
            c = string(app.BirthDateEditField.Value);       %rat 1 birthdate
            d = string(app.GenoTypeEditField.Value);        %rat 1 genotype
            e = string(app.CageNumberEditField.Value);      %rat 1 cageNumber
            f = string(app.HealthEditField.Value);          %rat 1 Health
            g = string(app.CageMatesTextArea.Value);       %rat 1 cageMate
            h = string(app.ExperimenterEditField.Value);    %Cage 1 Experimenter
            i = string(app.TaskTypeDoneEditField.Value);    %task type done
            j = string(app.NotesEditField.Value);           %notes

            %Intensity Of Cost 1-4
            k = string(app.Port1EditField_CC.Value);
            l = string(app.Port2EditField_CC.Value);
            m = string(app.Port3EditField_CC.Value);
            n = string(app.Port4EditField_CC.Value);

            %CostProbability 1-4
            o = string(app.Port1EditField_CP.Value);
            p = string(app.Port2EditField_CP.Value);
            q = string(app.Port3EditField_CP.Value);
            r = string(app.Port4EditField_CP.Value);

            %rewardConcentration 1-4
            s = string(app.Port1EditField_CR_2.Value);
            t = string(app.Port2EditField_CR_2.Value);
            u = string(app.Port3EditField_CR_2.Value);
            v = string(app.Port4EditField_CR_2.Value);

            %reward Volume 1-4
            w = string(app.Port1EditField_RV_2.Value);
            x = string(app.Port2EditField_RV_2.Value);
            y = string(app.Port3EditField_RV_2.Value);
            z = string(app.Port4EditField_RV_2.Value);

            %reward Probability 1-3
            aa = string(app.Port1EditField_RP_2.Value);
            ab = string(app.Port2EditField_RP_2.Value);
            ac = string(app.Port3EditField_RP_2.Value);
            ad = string(app.Port4EditField_RP_2.Value);

            %troubleshooting
            %             display(app.info)

            ae = "maze 1";                                        %maze number
            af = app.info(1);                                     %accepted 1 timestamp
            ag = app.info(3);                                     %accepted 1 value
            ah = app.info(13);                                    %play start trial tone
            ai = app.info(15);                                    %Present Cost
            aj = app.info(18);                                    %Light Level
            ak = app.info(20);                                    %REference Time
            al = app.info(22);                                    %Video Start Time
            am= app.info(24);                                     %Feeder
            an = app.info(25);                                    %Stop Track
            ao = app.info(28);                                    %Trial Name
            ap = app.info(30);                                    %Detection Settings
            aq = app.info(32);                                    %Trial Control Settings
            ar = app.info(34);                                    %Reference Duration
            as = app.info(36);                                    %animal id
            at = app.info(40);                                    %Box 1 Offer Delivery
            au = app.info(48);                                    %Box 1 No Offer Delivery
            av = app.info(57);                                    %start time
            aw = app.info(59);                                    %recording after
            ax = app.info(61);                                    %recording duration
            ay = app.info(63);                                    %Trial Duration
            az = app.info(65);                                    %Box 1 Cost Off
            aaa = app.rat_1_timestamps;                           %timestamps for coordinates
            aab = app.rat_1_x_coordinates;                          %x coordinates
            aac = app.rat_1_y_coordinates;                          %y coordinates
            aad = app.info(73);                                    %present cost end
            aae = app.info(75);                                     %cost presentation finish
            aaf = app.info(77);                                    %stop incopix recording
            aag = app.info(79);                                     %decision making time
            aah = app.info(81);                                    %start incopix recording
            aai = string(app.DateMMDDYYYYEditField.Value); %DATE
            aaj = app.info(83); % Box 1 Active zone timestamp
            aak = app.info(84); %box 1 active zone value
            if strcmpi(app.info(91),"NOT FOUND IN NODULUS") ~= 1
                aal = app.info(91);
            elseif strcmpi(app.info(102),"NOT FOUND IN NODULUS") ~= 1
                aal = app.info(102);
            elseif strcmpi(app.info(106),"NOT FOUND IN NODULUS") ~= 1
                aal = app.info(106);
            elseif strcmpi(app.info(110),"NOT FOUND IN NODULUS") ~= 1
                aal = app.info(110);
            else
                aal = "NOT FOUND IN NODULUS";
            end

            aam = app.info(95); %box 1 cost acknowledgement timestamp
            aan = app.info(99); % box 1 delivery acknoledgement timestamp


            if strlength(am) == 0
                temp= convertStringsToChars(aak);
                am = string(temp(2));
            end
            aao = app.actualRat1Timestamps;
            aap = app.actualRat1XCoordinates;
            aaq = app.actualRat1YCoordinates;
            data1 = table(a,b,c,d,e,f,g,h,i,j,k,l,m,n,o,p,q,r,s,t,u,v,w,x,y,z,aa,ab,ac,ad,ae,af,ag,ah,ai,aj,ak,al,am,an,ao,ap,aq,ar,as,at,au,av,aw,ax,ay,az,aaa,aab,aac,aad,aae,aaf,aag,aah,aai,aaj,aak,aal,aam,aan,aao, aap, aaq,'VariableNames',app.variableList);
            %display(data1);

            %this query checks if there are any rows with the exact
            %same reference time and name
            duplicateCheck = fetch(conn,strcat("SELECT * FROM", {' '}, app.actual_table, {' '},  "WHERE referencetime = ", "'",ak,"'", " AND subjectid =", {' '}, "'", a ,"'",";"));
            %disp(duplicateCheck)
            %if there are rows in duplicate check then that means the
            %user is trying to upload duplicate data
            if app.replaceAllDuplicates == 0
                %the following if statement checks if the user is aware
                %whether they're uploadinf dup-data yet
                if height(duplicateCheck)>0
                    %if they are not aware then the replaceAllDuplcates
                    %variable will be set to 0 and they'll get alerted
                    %and prompted for action
                    disp("The following duplicate was detected in organize1");
                    display(duplicateCheck)
                    answer = questdlg('A duplicate data set has been Detected. What would you like to do?',...
                        'Duplicate Detection',...
                        'Delete old data, keep new',"Don't upload this data", "cancel");
                    switch answer
                        %this case handles
                        case 'Delete old data, keep new'
                            execute(conn, strcat('DELETE FROM', {' '}, app.actual_table, {' '}, "WHERE referencetime = ", "'", ak, "'", " AND subjectid =", {' '}, "'", a ,"'", ";"));

                            sqlwrite(conn,tablename,data1)
                            app.replaceAllDuplicates = 1;
                        case "Don't upload this data"
                            msgbox("This new row will not be uploaded.")
                            app.replaceAllDuplicates = -1;
                    end
                else
                    sqlwrite(conn,tablename,data1)
                end
            else
                %if they are aware then the old data is deleted
                execute(conn, strcat('DELETE FROM', {' '}, app.actual_table, {' '}, "WHERE referencetime = ", "'", ak, "'", " AND subjectid =", {' '}, "'", a ,"'", ";"));

                %the new data is written
                sqlwrite(conn,tablename,data1)

            end


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


        end

        function organize2(app)
            %establishes connection to database
            conn = database(app.datasource,app.username,app.password);
            %active table in the database
            tablename = app.actual_table;

            %the following lines of code create 1 row in the database from
            %all the info gathered in previous parts, represeting the rat
            %in maze 2
            a = string(app.SubjectNameIDEditField_2.Value);   %rat 2 subjectID
            b = string(app.GenderEditField_2.Value);          %rat 2 gender
            c = string(app.BirthDateEditField_2.Value);       %rat 2 birthdate
            d = string(app.GenoTypeEditField_2.Value);        %rat 2 genotype
            e = string(app.CageNumberEditField_2.Value);      %rat 2 cageNumber
            f = string(app.HealthEditField_2.Value);          %rat 2 Health
            g = string(app.CageMatesTextArea_2.Value);       %rat 2 cageMate
            h = string(app.ExperimenterEditField_2.Value);    %Cage 2 Experimenter
            i = string(app.TaskTypeDoneEditField_2.Value);    %task type done
            j = string(app.NotesEditField_2.Value);           %rat 2 notes

            %Maze 2 Intensity Of Cost 1-4
            k = string(app.Port1EditField_CC_2.Value);
            l = string(app.Port2EditField_CC_2.Value);
            m = string(app.Port3EditField_CC_2.Value);
            n = string(app.Port4EditField_CC_2.Value);

            %Maze 2 CostProbability 1-4
            o = string(app.Port1EditField_CP_2.Value);
            p = string(app.Port2EditField_CP_2.Value);
            q = string(app.Port3EditField_CP_2.Value);
            r = string(app.Port4EditField_CP_2.Value);

            %Maze 2 rewardConcentration 1-4
            s = string(app.Port1EditField_CR_3.Value);
            t = string(app.Port2EditField_CR_3.Value);
            u = string(app.Port3EditField_CR_3.Value);
            v = string(app.Port4EditField_CR_3.Value);

            %Maze 2 reward Volume 1-4
            w = string(app.Port1EditField_RV_3.Value);
            x = string(app.Port2EditField_RV_3.Value);
            y = string(app.Port3EditField_RV_3.Value);
            z = string(app.Port4EditField_RV_3.Value);

            %Maze 2 reward Probability 1-3
            aa = string(app.Port1EditField_RP_3.Value);
            ab = string(app.Port2EditField_RP_3.Value);
            ac = string(app.Port3EditField_RP_3.Value);
            ad = string(app.Port4EditField_RP_3.Value);


            ae = "maze 2";                                        %maze number
            af = app.info(4);                                     %accepted 2 timestamp %%%%%%%%%%%%%%%%%%%%%%%%%
            ag = app.info(6);                                     %accepted 2 value     %%%%%%%%%%%%%%%%%%%%%%%%%
            ah = app.info(13);                                    %play start trial tone
            ai = app.info(15);                                    %Present Cost
            aj = app.info(18);                                    %Light Level
            ak = app.info(20);                                    %REference Time
            al = app.info(22);                                    %Video Start Time
            am= app.info(24);                                     %Feeder
            an = app.info(25);                                    %Stop Track
            ao = app.info(28);                                    %Trial Name
            ap = app.info(30);                                    %Detection Settings
            aq = app.info(32);                                    %Trial Control Settings
            ar = app.info(34);                                    %Reference Duration
            as = app.info(37);                                    %rat 2 animal id %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            at = app.info(42);                                    %Box 2 Offer Delivery %%%%%%%%%%%%%%%%%%%%%%%%%
            au = app.info(50);                                    %Box 2 No Offer Delivery %%%%%%%%%%%%%%%%%%%%%%%%%
            av = app.info(57);                                    %start time
            aw = app.info(59);                                    %recording after
            ax = app.info(61);                                    %recording duration
            ay = app.info(63);                                    %Trial Duration
            az = app.info(67);                                    %Box 2 Cost Off %%%%%%%%%%%%%%%%%%%%%%%
            aaa = app.rat_2_timestamps;                           %timestamps for coordinates
            aab = app.rat_2_x_coordinates;                          %x coordinates
            aac = app.rat_2_y_coordinates;                          %y coordinates
            aad = app.info(73);                                    %present cost end
            aae = app.info(75);                                     %cost presentation finish
            aaf = app.info(77);                                    %stop incopix recording
            aag = app.info(79);                                     %decision making time
            aah = app.info(81);                                    %start incopix recording
            aai = string(app.DateMMDDYYYYEditField.Value); %DATE
            aaj = app.info(85); % Box 2 Active zone timestamp
            aak = app.info(86); %box 2 active zone value
            if strcmpi(app.info(92),"NOT FOUND IN NODULUS") ~= 1
                aal = app.info(92);
            elseif strcmpi(app.info(103),"NOT FOUND IN NODULUS") ~= 1
                aal = app.info(103);
            elseif strcmpi(app.info(107),"NOT FOUND IN NODULUS") ~= 1
                aal = app.info(107);
            elseif strcmpi(app.info(111),"NOT FOUND IN NODULUS") ~= 1
                aal = app.info(111);
            else
                aal = "NOT FOUND IN NODULUS";
            end
            aam = app.info(96); %box 2 cost acknowledgement timestamp
            aan = app.info(100); % box 2 delivery acknoledgement timestamp
            if strlength(am) == 0
                temp= convertStringsToChars(aak);
                am = string(temp(2));
            end
            aao = app.actualRat2Timestamps;
            aap = app.actualRat2XCoordinates;
            aaq = app.actualRat2YCoordinates;
            data2 = table(a,b,c,d,e,f,g,h,i,j,k,l,m,n,o,p,q,r,s,t,u,v,w,x,y,z,aa,ab,ac,ad,ae,af,ag,ah,ai,aj,ak,al,am,an,ao,ap,aq,ar,as,at,au,av,aw,ax,ay,az,aaa,aab,aac,aad,aae,aaf,aag,aah,aai,aaj,aak,aal,aam,aan,aao, aap, aaq,'VariableNames',app.variableList);
            % display(data2);
            %this query checks if there are any rows with the exact
            %same reference time
            duplicateCheck = fetch(conn,strcat("SELECT * FROM", {' '}, app.actual_table, {' '},  "WHERE referencetime = ", "'",ak,"'", " AND subjectid =", {' '}, "'", a ,"'",";"));
            disp(duplicateCheck)
            %if there are rows in duplicate check then that means the
            %user is trying to upload duplicate data
            if app.replaceAllDuplicates == 0
                %the following if statement checks if the user is aware
                %whether they're uploadinf dup-data yet
                if height(duplicateCheck)>0
                    %if they are not aware then the replaceAllDuplcates
                    %variable will be set to 0 and they'll get alerted
                    %and prompted for action
                    disp("The following duplicate was detected in organize 2");
                    display(duplicateCheck)
                    answer = questdlg('A duplicate data set has been Detected. What would you like to do?',...
                        'Duplicate Detection',...
                        'Delete old data, keep new',"Don't upload this data", "cancel");
                    switch answer
                        %this case handles
                        case 'Delete old data, keep new'
                            execute(conn, strcat('DELETE FROM', {' '}, app.actual_table, {' '}, "WHERE referencetime = ", "'", ak, "'", " AND subjectid =", {' '}, "'", a ,"'", ";"));
                            app.replaceAllDuplicates = 1;
                            sqlwrite(conn,tablename,data2)
                        case "Don't upload this data"
                            msgbox("This new row will not be uploaded.")
                            app.replaceAllDuplicates = -1;
                    end
                else

                    sqlwrite(conn,tablename,data2)
                end
            else
                %if they are aware then the old data is deleted
                execute(conn, strcat('DELETE FROM', {' '}, app.actual_table, {' '}, "WHERE referencetime = ", "'", ak, "'", " AND subjectid =", {' '}, "'", a ,"'", ";"));
                %the new data is written
                sqlwrite(conn,tablename,data2)

            end
            
        end
        function organize3(app)
            %establishes connection to database
            conn = database(app.datasource,app.username,app.password);
            %active table in the database
            tablename = app.actual_table;

            %the following lines of code create 1 row in the database from
            %all the info gathered in previous parts, represeting the rat
            %in maze 3
            a = string(app.SubjectNameIDEditField_3.Value);   %rat 3 subjectID
            b = string(app.GenderEditField_3.Value);          %rat 3 gender
            c = string(app.BirthDateEditField_3.Value);       %rat 3 birthdate
            d = string(app.GenoTypeEditField_3.Value);        %rat 3 genotype
            e = string(app.CageNumberEditField_3.Value);      %rat 3 cageNumber
            f = string(app.HealthEditField_3.Value);          %rat 3 Health
            g = string(app.CageMatesTextArea_3.Value);       %rat 3 cageMate
            h = string(app.ExperimenterEditField_3.Value);    %Cage 3 Experimenter
            i = string(app.TaskTypeDoneEditField_3.Value);    %task type done
            j = string(app.NotesEditField_3.Value);           %rat 3 notes

            %Maze 3 Intensity Of Cost 1-4
            k = string(app.Port1EditField_CC_3.Value);
            l = string(app.Port2EditField_CC_3.Value);
            m = string(app.Port3EditField_CC_3.Value);
            n = string(app.Port4EditField_CC_3.Value);

            %Maze 3 CostProbability 1-4
            o = string(app.Port1EditField_CP_3.Value);
            p = string(app.Port2EditField_CP_3.Value);
            q = string(app.Port3EditField_CP_3.Value);
            r = string(app.Port4EditField_CP_3.Value);

            %Maze 3 rewardConcentration 1-4
            s = string(app.Port1EditField_CR_4.Value);
            t = string(app.Port2EditField_CR_4.Value);
            u = string(app.Port3EditField_CR_4.Value);
            v = string(app.Port4EditField_CR_4.Value);

            %Maze 3 reward Volume 1-4
            w = string(app.Port1EditField_RV_4.Value);
            x = string(app.Port2EditField_RV_4.Value);
            y = string(app.Port3EditField_RV_4.Value);
            z = string(app.Port4EditField_RV_4.Value);

            %Maze 3 reward Probability 1-3
            aa = string(app.Port1EditField_RP_4.Value);
            ab = string(app.Port2EditField_RP_4.Value);
            ac = string(app.Port3EditField_RP_4.Value);
            ad = string(app.Port4EditField_RP_4.Value);


            ae = "maze 3";                                        %maze number
            af = app.info(7);                                     %accepted 3 timestamp %%%%%%%%%%%%%%%%%%%%%%%%%
            ag = app.info(9);                                     %accepted 3 value     %%%%%%%%%%%%%%%%%%%%%%%%%
            ah = app.info(13);                                    %play start trial tone
            ai = app.info(15);                                    %Present Cost
            aj = app.info(18);                                    %Light Level
            ak = app.info(20);                                    %REference Time
            al = app.info(22);                                    %Video Start Time
            am= app.info(24);                                     %Feeder
            an = app.info(25);                                    %Stop Track
            ao = app.info(28);                                    %Trial Name
            ap = app.info(30);                                    %Detection Settings
            aq = app.info(32);                                    %Trial Control Settings
            ar = app.info(34);                                    %Reference Duration
            as = app.info(38);                                    %rat 3 animal id %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            at = app.info(44);                                    %Box 3 Offer Delivery %%%%%%%%%%%%%%%%%%%%%%%%%
            au = app.info(52);                                    %Box 3 No Offer Delivery %%%%%%%%%%%%%%%%%%%%%%%%%
            av = app.info(57);                                    %start time
            aw = app.info(59);                                    %recording after
            ax = app.info(61);                                    %recording duration
            ay = app.info(63);                                    %Trial Duration
            az = app.info(69);                                    %Box 3 Cost Off %%%%%%%%%%%%%%%%%%%%%%%
            aaa = app.rat_3_timestamps;                           %timestamps for coordinates
            aab = app.rat_3_x_coordinates;                          %x coordinates
            aac = app.rat_3_y_coordinates;                          %y coordinates
            aad = app.info(73);                                    %present cost end
            aae = app.info(75);                                     %cost presentation finish
            aaf = app.info(77);                                    %stop incopix recording
            aag = app.info(79);                                     %decision making time
            aah = app.info(81);                                    %start incopix recording
            aai = string(app.DateMMDDYYYYEditField.Value);                  %DATE
            aaj = app.info(87); % Box 3 Active zone timestamp
            aak = app.info(88); %box 3 active zone value
            if strcmpi(app.info(93),"NOT FOUND IN NODULUS") ~= 1
                aal = app.info(93);
            elseif strcmpi(app.info(104),"NOT FOUND IN NODULUS") ~= 1
                aal = app.info(104);
            elseif strcmpi(app.info(108),"NOT FOUND IN NODULUS") ~= 1
                aal = app.info(108);
            elseif strcmpi(app.info(112),"NOT FOUND IN NODULUS") ~= 1
                aal = app.info(112);
            else
                aal = "NOT FOUND IN NODULUS";
            end
            aam = app.info(97); %box 3 cost acknowledgement timestamp
            aan = app.info(101); % box 3 delivery acknoledgement timestamp
            if strlength(am) == 0
                temp= convertStringsToChars(aak);
                am = string(temp(2));
            end
            aao = app.actualRat3Timestamps;
            aap = app.actualRat3XCoordinates;
            aaq = app.actualRat3YCoordinates;
            data3 = table(a,b,c,d,e,f,g,h,i,j,k,l,m,n,o,p,q,r,s,t,u,v,w,x,y,z,aa,ab,ac,ad,ae,af,ag,ah,ai,aj,ak,al,am,an,ao,ap,aq,ar,as,at,au,av,aw,ax,ay,az,aaa,aab,aac,aad,aae,aaf,aag,aah,aai,aaj,aak,aal,aam,aan,aao, aap, aaq,'VariableNames',app.variableList);
            %display(data3);
            %this query checks if there are any rows with the exact
            %same reference time
            duplicateCheck = fetch(conn,strcat("SELECT * FROM", {' '}, app.actual_table, {' '},  "WHERE referencetime = ", "'",ak,"'", " AND subjectid =", {' '}, "'", a ,"'",";"));
            disp(duplicateCheck)
            %if there are rows in duplicate check then that means the
            %user is trying to upload duplicate data
            if app.replaceAllDuplicates == 0
                %the following if statement checks if the user is aware
                %whether they're uploadinf dup-data yet
                if height(duplicateCheck)>0
                    %if they are not aware then the replaceAllDuplcates
                    %variable will be set to 0 and they'll get alerted
                    %and prompted for action
                    disp("The following duplicate was detected in organize 3");
                    display(duplicateCheck)
                    answer = questdlg('A duplicate data set has been Detected. What would you like to do?',...
                        'Duplicate Detection',...
                        'Delete old data, keep new',"Don't upload this data", "cancel");
                    switch answer
                        %this case handles
                        case 'Delete old data, keep new'
                            execute(conn, strcat('DELETE FROM', {' '}, app.actual_table, {' '}, "WHERE referencetime = ", "'", ak, "'", " AND subjectid =", {' '}, "'", a ,"'", ";"));
                            sqlwrite(conn,tablename,data3)
                            app.replaceAllDuplicates = 1;
                        case "Don't upload this data"
                            app.replaceAllDuplicates = -1;
                            msgbox("This new row will not be uploaded.")
                    end
                else
                    sqlwrite(conn,tablename,data3)
                end
            else

                %if they are aware then the old data is deleted
                execute(conn, strcat('DELETE FROM', {' '}, app.actual_table, {' '}, "WHERE referencetime = ", "'", ak, "'", " AND subjectid =", {' '}, "'", a ,"'", ";"));
                %the new data is written
                sqlwrite(conn,tablename,data3)

            end
        end

        function organize4(app)
            %establishes connection to database
            conn = database(app.datasource,app.username,app.password);
            %active table in the database
            tablename = app.actual_table;

            %the following lines of code create 1 row in the database from
            %all the info gathered in previous parts, represeting the rat
            %in maze 4
            a = string(app.SubjectNameIDEditField_4.Value);   %rat 4 subjectID
            b = string(app.GenderEditField_4.Value);          %rat 4 gender
            c = string(app.BirthDateEditField_4.Value);       %rat 4 birthdate
            d = string(app.GenoTypeEditField_4.Value);        %rat 4 genotype
            e = string(app.CageNumberEditField_4.Value);      %rat 4 cageNumber
            f = string(app.HealthEditField_4.Value);          %rat 4 Health
            g = string(app.CageMatesTextArea_4.Value);       %rat 4 cageMate
            h = string(app.ExperimenterEditField_4.Value);    %Cage 4 Experimenter
            i = string(app.TaskTypeDoneEditField_4.Value);    %task type done
            j = string(app.NotesEditField_4.Value);           %rat 4 notes

            %Maze 4 Intensity Of Cost 1-4
            k = string(app.Port1EditField_CC_4.Value);
            l = string(app.Port2EditField_CC_4.Value);
            m = string(app.Port3EditField_CC_4.Value);
            n = string(app.Port4EditField_CC_4.Value);

            %Maze 4 CostProbability 1-4
            o = string(app.Port1EditField_CP_4.Value);
            p = string(app.Port2EditField_CP_4.Value);
            q = string(app.Port3EditField_CP_4.Value);
            r = string(app.Port4EditField_CP_4.Value);

            %Maze 4 rewardConcentration 1-4
            s = string(app.Port1EditField_CR_5.Value);
            t = string(app.Port2EditField_CR_5.Value);
            u = string(app.Port3EditField_CR_5.Value);
            v = string(app.Port4EditField_CR_5.Value);

            %Maze 4 reward Volume 1-4
            w = string(app.Port1EditField_RV_5.Value);
            x = string(app.Port2EditField_RV_5.Value);
            y = string(app.Port3EditField_RV_5.Value);
            z = string(app.Port4EditField_RV_5.Value);

            %Maze 4 reward Probability 1-3
            aa = string(app.Port1EditField_RP_5.Value);
            ab = string(app.Port2EditField_RP_5.Value);
            ac = string(app.Port3EditField_RP_5.Value);
            ad = string(app.Port4EditField_RP_5.Value);


            ae = "maze 4";                                        %maze number
            af = app.info(10);                                    %accepted 4 timestamp %%%%%%%%%%%%%%%%%%%%%%%%%
            ag = app.info(12);                                    %accepted 4 value     %%%%%%%%%%%%%%%%%%%%%%%%%
            ah = app.info(13);                                    %play start trial tone
            ai = app.info(15);                                    %Present Cost
            aj = app.info(18);                                    %Light Level
            ak = app.info(20);                                    %REference Time
            al = app.info(22);                                    %Video Start Time
            am= app.info(24);                                     %Feeder
            an = app.info(25);                                    %Stop Track
            ao = app.info(28);                                    %Trial Name
            ap = app.info(30);                                    %Detection Settings
            aq = app.info(32);                                    %Trial Control Settings
            ar = app.info(34);                                    %Reference Duration
            as = app.info(39);                                    %rat 4 animal id %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            at = app.info(46);                                    %Box 4 Offer Delivery %%%%%%%%%%%%%%%%%%%%%%%%%
            au = app.info(54);                                    %Box 4 No Offer Delivery %%%%%%%%%%%%%%%%%%%%%%%%%
            av = app.info(57);                                    %start time
            aw = app.info(59);                                    %recording after
            ax = app.info(61);                                    %recording duration
            ay = app.info(63);                                    %Trial Duration
            az = app.info(71);                                    %Box 4 Cost Off %%%%%%%%%%%%%%%%%%%%%%%
            aaa = app.rat_4_timestamps;                           %timestamps for coordinates
            aab = app.rat_4_x_coordinates;                          %x coordinates
            aac = app.rat_4_y_coordinates;                          %y coordinates
            aad = app.info(73);                                    %present cost end
            aae = app.info(75);                                     %cost presentation finish
            aaf = app.info(77);                                    %stop incopix recording
            aag = app.info(79);                                     %decision making time
            aah = app.info(81);                                    %start incopix recording
            aai = string(app.DateMMDDYYYYEditField.Value);                 %date

            aaj = app.info(89); % Box 4 Active zone timestamp
            aak = app.info(90); %box 4 active zone value
            if strcmpi(app.info(94),"NOT FOUND IN NODULUS") ~= 1
                aal = app.info(94);
            elseif strcmpi(app.info(105),"NOT FOUND IN NODULUS") ~= 1
                aal = app.info(105);
            elseif strcmpi(app.info(109),"NOT FOUND IN NODULUS") ~= 1
                aal = app.info(109);
            elseif strcmpi(app.info(113),"NOT FOUND IN NODULUS") ~= 1
                aal = app.info(113);
            else
                aal = "NOT FOUND IN NODULUS";
            end
            aam = app.info(98); %box 4 cost acknowledgement timestamp
            aan = app.info(114); % box 4 delivery acknoledgement timestamp
            if strlength(am) == 0
                temp= convertStringsToChars(aak);
                am = string(temp(2));
            end
            aao = app.actualRat4Timestamps;
            aap = app.actualRat4XCoordinates;
            aaq = app.actualRat4YCoordinates;
            data4 = table(a,b,c,d,e,f,g,h,i,j,k,l,m,n,o,p,q,r,s,t,u,v,w,x,y,z,aa,ab,ac,ad,ae,af,ag,ah,ai,aj,ak,al,am,an,ao,ap,aq,ar,as,at,au,av,aw,ax,ay,az,aaa,aab,aac,aad,aae,aaf,aag,aah,aai,aaj,aak,aal,aam,aan,aao, aap, aaq,'VariableNames',app.variableList);
            %display(data4);

            %this query checks if there are any rows with the exact
            %same reference time
            duplicateCheck = fetch(conn,strcat("SELECT * FROM", {' '}, app.actual_table, {' '},  "WHERE referencetime = ", "'",ak,"'", " AND subjectid =", {' '}, "'", a ,"'",";"));
            disp(duplicateCheck)
            %if there are rows in duplicate check then that means the
            %user is trying to upload duplicate data
            if app.replaceAllDuplicates == 0
                %the following if statement checks if the user is aware
                %whether they're uploadinf dup-data yet
                if height(duplicateCheck)>0
                    %if they are not aware then the replaceAllDuplcates
                    %variable will be set to 0 and they'll get alerted
                    %and prompted for action
                    disp("The following duplicate was detected in organize 4");
                    display(duplicateCheck)
                    answer = questdlg('A duplicate data set has been Detected. What would you like to do?',...
                        'Duplicate Detection',...
                        'Delete old data, keep new',"Don't upload this data", "cancel");
                    switch answer
                        %this case handles
                        case 'Delete old data, keep new'
                            execute(conn, strcat('DELETE FROM', {' '}, app.actual_table, {' '}, "WHERE referencetime = ", "'", ak, "'", " AND subjectid =", {' '}, "'", a ,"'", ";"));

                            sqlwrite(conn,tablename,data4)
                            app.replaceAllDuplicates = 1;
                        case "Don't upload this data"
                            app.replaceAllDuplicates = -1;
                            msgbox("This new row will not be uploaded.")
                    end
                else
                    sqlwrite(conn,tablename,data4)
                end
            else
                %if they are aware then the old data is deleted
                execute(conn, strcat('DELETE FROM', {' '}, app.actual_table, {' '}, "WHERE referencetime = ", "'", ak, "'", " AND subjectid =", {' '}, "'", a ,"'", ";"));
                %the new data is written
                sqlwrite(conn,tablename,data4)

            end
        end
        
        function checkNamesInAppMatchNamesInFile(app)

            local = app.universalCurrentNodulusFile;
            for row = 1:height(local)
                for column = 1:width(local)
                    if strcmp(string(local{row,column}), 'Animal ID')
                        namesFound = strcat(string(local{row,column+1}), {' '},string(local{row,column+2}),{' '},string(local{row,column+3}),{' '},string(local{row,column+4}));
                    end
                end
            end

            %             namesFound = split(namesFound, " ");
            %             disp(namesFound)
%                         display(namesFound(1))
%                         display(namesFound(2))
%                         display(namesFound(3))
%                         display(namesFound(4))
            namesFound = split(namesFound, " ");
            app.namesFoundUniversal = namesFound;
            matchCount = 0;
            if strcmpi(app.SubjectNameIDEditField.Value,namesFound(1))  == 0
%                 disp(strcat("1 expected: ", app.SubjectNameIDEditField.Value, "| Actual: ", namesFound(1)));
%                 errordlg("Rat entered in the Maze 1 field does not match Rat in Maze 1 according to Nodulus File. Please fix or uploads will not be allowed")
                app.RatsInAppMatchRatsInFile = 0;
            else
                matchCount = matchCount+1;
            end
            if strcmpi(app.SubjectNameIDEditField_2.Value,namesFound(2)) ==0
%                 disp(strcat("2 expected: ", app.SubjectNameIDEditField_2.Value, "| Actual: ", namesFound(2)));
%                 errordlg("Rat entered in the Maze 2 field does not match Rat in Maze 2 according to Nodulus File. Please fix or uploads will not be allowed")
                app.RatsInAppMatchRatsInFile = 0;
            else
                matchCount = matchCount+1;
            end
            if strcmpi(app.SubjectNameIDEditField_3.Value,namesFound(3)) == 0
%                 disp(strcat("3 expected: ", app.SubjectNameIDEditField_3.Value, "| Actual: ", namesFound(3)));
%                 errordlg("Rat entered in the Maze 3 field does not match Rat in Maze 3 according to Nodulus File. Please fix or uploads will not be allowed")
                app.RatsInAppMatchRatsInFile = 0;
            else
                matchCount = matchCount+1;
            end
            if strcmpi(app.SubjectNameIDEditField_4.Value,namesFound(4)) == 0
%                 disp(strcat("4 expected: ", app.SubjectNameIDEditField_4.Value, "| Actual: ", namesFound(4)));
                app.RatsInAppMatchRatsInFile = 0;
%                 errordlg("Rat entered in the Maze 4 field does not match Rat in Maze 4 according to Nodulus File. Please fix or uploads will not be allowed")
            else
                matchCount = matchCount+1;
            end
            
            if matchCount == 4
                app.RatsInAppMatchRatsInFile = 1;
            else
                app.RatsInAppMatchRatsInFile = 0;
            end
            
            %checkIfPathMatchesFile(app)
            
        end
        
        function checkIfPathMatchesFile(app)
            ratNamesFromPath = split(app.workingDirectory,"\");
            
            ratNamesFromPath = split(ratNamesFromPath(length(ratNamesFromPath)), " ");
            ratNamesFromPath = ratNamesFromPath(length(ratNamesFromPath));
            ratNamesFromPath = split(ratNamesFromPath,',');
%             display(ratNamesFromPath)
            matchCounter = 0;
            if strcmpi(regexprep(ratNamesFromPath(1), '[?,.;!@#$%""^&*()]',''), app.namesFoundUniversal(1))
                matchCounter = matchCounter +1;
            else
                errordlg("Rat 1 in the file path does not match rat 1 in the nodulus file. Please correct or uploads will not be allowed.");
            end
            if strcmpi(regexprep(ratNamesFromPath(2), '[?,.;!@#$%""^&*()]',''), app.namesFoundUniversal(2))
                matchCounter = matchCounter +1;
            else
                errordlg("Rat 2 in the file path does not match rat 2 in the nodulus file. Please correct or uploads will not be allowed.");
            end  
            if strcmpi(regexprep(ratNamesFromPath(3),'[?,.;!@#$%""^&*()]',''), app.namesFoundUniversal(3))
                matchCounter = matchCounter +1;
            else
%                 display(strcat(regexprep(ratNamesFromPath(4),'[?,.;!@#$%""^&*()]',''),"\\\\\\\\\\",app.namesFoundUniversal(3)))
                errordlg("Rat 3 in the file path does not match rat 3 in the nodulus file. Please correct or uploads will not be allowed.");
            end
            if strcmpi(regexprep(ratNamesFromPath(4),'[?,.;!@#$%""^&*()]',''), app.namesFoundUniversal(4))
                matchCounter = matchCounter +1;
            else
                errordlg("Rat 4 in the file path does not match rat 4 in the nodulus file. Please correct or uploads will not be allowed.");
            end      

%             display(matchCounter)
            if matchCounter == 4
                app.RatsInPathMatchRatsInFile = 1;
            else
                app.RatsInPathMatchRatsInFile = 0;
            end
        end

       
        function createDatabaseConnection(app)
            opts = configureJDBCDataSource('Vendor','PostgreSQL');

            opts = setConnectionOptions(opts, ...
                'DataSourceName',app.datasource, ... %datasource name is the name of the database and also the name of the connection
                'Server',app.IPAddress,... %IP address
                'PortNumber',5432, ... %port number
                'JDBCDriverLocation',app.driverPath); %file path for the .jar file
            if testConnection(opts,app.username,app.password)
                saveAsJDBCDataSource(opts)
                fileID = fopen('databaseConnection.txt', 'w');
                fprintf(fileID, strcat(app.datasource));
                fclose(fileID);

            else
                errordlg("Connection couldn't be established. Please Double check that the .jar file path and the IP address are both correct.")
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            value = lower(app.SelectRatDropDown.Value);
            conn = database(app.datasource,app.username,app.password);

            %query that searches for a rat with the current rat's name
            query = strcat('SELECT * FROM', {' '}, app.rattable, {' '},  'WHERE name =', {' '}, "'",'none', "'",';');
            %display(query)


            %executes query and returns a table with rat info
            currentRat = fetch(conn,query);


            %displays the default rat into all rat boxes
            app.GenoTypeEditField.Value = string(currentRat.genotype);
            app.CageMatesTextArea.Value = string(currentRat.cagemates);
            app.BirthDateEditField.Value = string(currentRat.birthdate);
            app.CageNumberEditField.Value = string(currentRat.cagenumber);
            app.GenderEditField.Value = string(currentRat.gender);
            app.SubjectNameIDEditField.Value = value;
            app.HealthEditField.Value = string(currentRat.health);

            app.GenoTypeEditField_2.Value = string(currentRat.genotype);
            app.CageMatesTextArea_2.Value = string(currentRat.cagemates);
            app.BirthDateEditField_2.Value = string(currentRat.birthdate);
            app.CageNumberEditField_2.Value = string(currentRat.cagenumber);
            app.GenderEditField_2.Value = string(currentRat.gender);
            app.SubjectNameIDEditField_2.Value = value;
            app.HealthEditField_2.Value = string(currentRat.health);

            app.GenoTypeEditField_3.Value = string(currentRat.genotype);
            app.CageMatesTextArea_3.Value = string(currentRat.cagemates);
            app.BirthDateEditField_3.Value = string(currentRat.birthdate);
            app.CageNumberEditField_3.Value = string(currentRat.cagenumber);
            app.GenderEditField_3.Value = string(currentRat.gender);
            app.SubjectNameIDEditField_3.Value = value;
            app.HealthEditField_3.Value = string(currentRat.health);

            app.GenoTypeEditField_4.Value = string(currentRat.genotype);
            app.CageMatesTextArea_4.Value = string(currentRat.cagemates);
            app.BirthDateEditField_4.Value = string(currentRat.birthdate);
            app.CageNumberEditField_4.Value = string(currentRat.cagenumber);
            app.GenderEditField_4.Value = string(currentRat.gender);
            app.SubjectNameIDEditField_4.Value = value;
            app.HealthEditField_4.Value = string(currentRat.health);

            %updates all rats to the default None rat
            app.rat1 = currentRat;
            app.rat2 = currentRat;
            app.rat3 = currentRat;
            app.rat4 = currentRat;


            %gets the ipAddress from a file
            app.IPAddress = string(fileread('IPAddress.txt'));

            %gets driver path from a file
            app.driverPath = strcat(fileread('jarFilePath.txt'));

            %creates database connection
            createDatabaseConnection(app)
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            AdvanceSearchWindow
        end

        % Button pushed function: SelectNodulusFileButton
        function SelectNodulusFileButtonPushed(app, event)
            %selpath is going to be the string representation of the selected directory
            selpath = uigetdir;
            app.workingDirectory = string(selpath);

            %displays the file selected in the GUI
            app.FileSelectedTextArea.Value = string(selpath);

            matchCounter = 0;


            %checks if rat 1 is in the file path
            %throws warning if not
            if xor(contains(string(selpath), string(app.rat1.name), 'IgnoreCase',true),  1)
                if xor(strcmp(string(app.rat1.name),"fake"),1)
                    warndlg("Rat 1 is not in the file name. Please Change the file, or select a different rat.")
                    app.RatsInAppMatchFilePath = 0;
                end
            else 
                matchCounter = matchCounter +1;
            end

            %checks if rat 2 is in the file path
            %throws warning if not
            if xor(contains(string(selpath), string(app.rat2.name), 'IgnoreCase',true),  1)
                if xor(strcmp(string(app.rat2.name),"fake"),1)
                    warndlg("Rat 2 is not in the file name. Please Change the file, or select a different rat.")
                    app.RatsInAppMatchFilePath = 0;
                end
            else
                matchCounter = matchCounter +1;
            end


            %checks if rat 3 is in the file path
            %throws warning if not
            if xor(contains(string(selpath), string(app.rat3.name), 'IgnoreCase',true),  1)
                if xor(strcmp(string(app.rat3.name),"fake"),1)
                    warndlg("Rat 3 is not in the file name. Please Change the file, or select a different rat.")
                    app.RatsInAppMatchFilePath = 0;                 
                end
            else
                matchCounter = matchCounter +1;
            end


            %checks if rat 4 is in the file path
            %throws warning if not
            %throws success message if it passes all tests
            if xor(contains(string(selpath), string(app.rat4.name), 'IgnoreCase',true),  1)
                if xor(strcmp(string(app.rat4.name),"fake"),1)
                    warndlg("Rat 4 is not in the file name. Please Change the file, or select a different rat.")
                end
            else
                matchCounter = matchCounter +1;
                
            end

            %listing is an array of all files within the selpath directory
            %it is always 2 greater than the number of files
            disp(matchCounter)
            if matchCounter == 4
                msgbox('Looks good')
                app.RatsInAppMatchFilePath = 1;
            else
                app.RatsInAppMatchFilePath = 0;
%                 errordlg("The rats entered into the Mazes do not match the rats in the filename. Please fix this or uploads will not be permitted.")
            end
            listing = dir (selpath);
            app.files = listing;


        end

        % Value changed function: SelectRatDropDown
        function SelectRatDropDownValueChanged(app, event)
            value = lower(app.SelectRatDropDown.Value);
            conn = database(app.datasource,app.username,app.password);

            %query that searches for a rat with the current rat's name
            query = strcat('SELECT * FROM ', {' '}, app.rattable, {' '},  'WHERE name =', {' '}, "'",string(value), "'",';');


            %executes query and returns a table with rat info
            currentRat = fetch(conn,query);

            %displays all rat info in Gui
            app.GenoTypeEditField.Value = string(currentRat.genotype);
            app.CageMatesTextArea.Value = string(currentRat.cagemates);
            app.BirthDateEditField.Value = string(currentRat.birthdate);
            app.CageNumberEditField.Value = string(currentRat.cagenumber);
            app.GenderEditField.Value = string(currentRat.gender);
            app.SubjectNameIDEditField.Value = value;
            app.HealthEditField.Value = string(currentRat.health);

            %updates the rat 1 property
            app.rat1 = currentRat;
        end

        % Value changed function: SelectRatDropDown_2
        function SelectRatDropDown_2ValueChanged(app, event)
            value = lower(app.SelectRatDropDown_2.Value);
            conn = database(app.datasource,app.username,app.password);

            %query that searches for a rat with the current rat's name
            query = strcat('SELECT * FROM', {' '}, app.rattable, {' '},  'WHERE name =', {' '}, "'",string(value), "'",';');

            %executes query and returns a table with rat info
            currentRat = fetch(conn,query);

            %displays all rat info in Gui
            app.GenoTypeEditField_2.Value = string(currentRat.genotype);
            app.CageMatesTextArea_2.Value = string(currentRat.cagemates);
            app.BirthDateEditField_2.Value = string(currentRat.birthdate);
            app.CageNumberEditField_2.Value = string(currentRat.cagenumber);
            app.GenderEditField_2.Value = string(currentRat.gender);
            app.SubjectNameIDEditField_2.Value = value;
            app.HealthEditField_2.Value = string(currentRat.health);

            %updates the rat 2 property
            app.rat2 = currentRat;
        end

        % Value changed function: SelectRatDropDown_3
        function SelectRatDropDown_3ValueChanged(app, event)
            value = lower(app.SelectRatDropDown_3.Value);
            conn = database(app.datasource,app.username,app.password);

            %query that searches for a rat with the current rat's name
            query = strcat('SELECT * FROM', {' '}, app.rattable, {' '},  'WHERE name =', {' '}, "'",string(value), "'",';');

            %executes query and returns a table with rat info
            currentRat = fetch(conn,query);

            %displays all rat info in Gui
            app.GenoTypeEditField_3.Value = string(currentRat.genotype);
            app.CageMatesTextArea_3.Value = string(currentRat.cagemates);
            app.BirthDateEditField_3.Value = string(currentRat.birthdate);
            app.CageNumberEditField_3.Value = string(currentRat.cagenumber);
            app.GenderEditField_3.Value = string(currentRat.gender);
            app.SubjectNameIDEditField_3.Value = value;
            app.HealthEditField_3.Value = string(currentRat.health);
            %updates the rat 3 property
            app.rat3 = currentRat;
        end

        % Value changed function: SelectRatDropDown_4
        function SelectRatDropDown_4ValueChanged(app, event)
            value = lower(app.SelectRatDropDown_4.Value);
            conn = database(app.datasource,app.username,app.password);

            %query that searches for a rat with the current rat's name
            query = strcat('SELECT * FROM', {' '}, app.rattable, {' '},  'WHERE name =', {' '}, "'",string(value), "'",';');

            %executes query and returns a table with rat info
            currentRat = fetch(conn,query);

            %displays all rat info in Gui
            app.GenoTypeEditField_4.Value = string(currentRat.genotype);
            app.CageMatesTextArea_4.Value = string(currentRat.cagemates);
            app.BirthDateEditField_4.Value = string(currentRat.birthdate);
            app.CageNumberEditField_4.Value = string(currentRat.cagenumber);
            app.GenderEditField_4.Value = string(currentRat.gender);
            app.SubjectNameIDEditField_4.Value = value;
            app.HealthEditField_4.Value = string(currentRat.health);

            %updates the rat 4 property
            app.rat4 = currentRat;
        end

        % Button pushed function: AddDataToDatabaseButton
        function AddDataToDatabaseButtonPushed(app, event)
            %disp("THE ADD TO DATABASE FUNCTION WAS PUSHED")


            createDatabaseConnection(app);

            %variables we want to parse from the nodulus
            variablesWeWant = readtable("InfoWeWant.xlsx");
            app.universalVariablesWeWant = variablesWeWant;

            %the i in this position and the universal counter are tied
            %together, if the i is updated, so is the universalCounter
            i = 3;
            app.universalCounter = i;

            size = length(app.files);
            %the while loop below parses through every trial file in the session file
            while i <=size
                if app.replaceAllDuplicates == -1
                    break
                end
                %creates a table from each trial(excel) file in the session(folder) file
                currentNodulusFile = readtable(strcat(app.workingDirectory,"/",app.files(i).name),'Format', 'auto', 'Sheet','Trial Control-Rat Arena 1a' );
                app.universalCurrentNodulusFile = currentNodulusFile;

                %ENTER WHAT I WANT TO DO HERE
                %checkNamesInAppMatchNamesInFile(app)
%                 if app.RatsInAppMatchFilePath == 0
%                     errordlg("UPLOAD TERMINATED. REASON: The Rats inputted into Serendipity do not appear in the file path. Please fix and try again. ")
%                     break;
%                 end
%                 if app.RatsInAppMatchRatsInFile == 0
%                     errordlg("UPLOAD TERMINATED. REASON: The rats inserted into Serendipity do not match the rats in the nodulus file.\nPlease fix and try again.")
%                     break;
%                 end
%                 if app.RatsInPathMatchRatsInFile ==0
%                     errordlg("Upload Terminated. REASON: Rats in the file path do not match the rats in the nodulus file. \nPlease fix and try again.")
%                     break;
%                 end
                %ENTER WHAT I WANT TO DO HERE
                %columnrow will be a 2d array containing the column and row of each found variable
                currentNodulusContainer =containers.Map('Keytype', 'char', 'ValueType', 'any');
                display(strcat("CURRENTLY PARSING FILE: ", {' '}, app.files(i).name))
                %the for loops below will get the location of each variable we want in the current nodulus file
                for row = 1:height(currentNodulusFile)
                    for column =1:width(currentNodulusFile)
                        currentNodulusContainer(lower(string(app.universalCurrentNodulusFile{row,column}))) = [row,column];
                    end
                end
                %                 display(app.universalColumnRow)
                
                app.universalContainer = currentNodulusContainer;
                
                results(app);
                organize(app);
                organize2(app);
                organize3(app);
                organize4(app)
                i = i +1;
                app.universalCounter = i;

            end
            app.replaceAllDuplicates = 0;


        end

        % Button pushed function: SetTodaysDateButton
        function SetTodaysDateButtonPushed(app, event)
            app.DateMMDDYYYYEditField.Value = datestr(now,'mm/dd/yyyy');
        end

        % Value changed function: PortDropDown
        function PortDropDownValueChanged(app, event)
            value = app.PortDropDown.Value;
            switch value
                case 'Maze 1'
                case 'Maze 2'
                    app.ExperimenterEditField.Value = app.ExperimenterEditField_2.Value;        %Experimenter
                    app.TaskTypeDoneEditField.Value = app.TaskTypeDoneEditField_2.Value;         %Task Type Done
                    app.NotesEditField.Value = app.NotesEditField_2.Value;                      %Notes
                    %intensity of cost 1-4
                    app.Port1EditField_CC.Value  = app.Port1EditField_CC_2.Value;
                    app.Port2EditField_CC.Value = app.Port2EditField_CC_2.Value;
                    app.Port3EditField_CC.Value = app.Port3EditField_CC_2.Value;
                    app.Port4EditField_CC.Value = app.Port4EditField_CC_2.Value;

                    %cost probability 1-4
                    app.Port1EditField_CP.Value = app.Port1EditField_CP_2.Value;
                    app.Port2EditField_CP.Value = app.Port2EditField_CP_2.Value;
                    app.Port3EditField_CP.Value = app.Port3EditField_CP_2.Value;
                    app.Port4EditField_CP.Value = app.Port4EditField_CP_2.Value;

                    %reward concentration 1-4
                    app.Port1EditField_CR_2.Value = app.Port1EditField_CR_3.Value;
                    app.Port2EditField_CR_2.Value= app.Port2EditField_CR_3.Value;
                    app.Port3EditField_CR_2.Value= app.Port3EditField_CR_3.Value;
                    app.Port4EditField_CR_2.Value= app.Port4EditField_CR_3.Value;

                    %reward volume 1-4
                    app.Port1EditField_RV_2.Value = app.Port1EditField_RV_3.Value;
                    app.Port2EditField_RV_2.Value = app.Port2EditField_RV_3.Value;
                    app.Port3EditField_RV_2.Value = app.Port3EditField_RV_3.Value;
                    app.Port4EditField_RV_2.Value = app.Port4EditField_RV_3.Value;

                    %reward probability 1-4
                    app.Port1EditField_RP_2.Value = app.Port1EditField_RP_3.Value;
                    app.Port2EditField_RP_2.Value = app.Port2EditField_RP_3.Value;
                    app.Port3EditField_RP_2.Value = app.Port3EditField_RP_3.Value;
                    app.Port4EditField_RP_2.Value = app.Port4EditField_RP_3.Value;
                case 'Maze 3'
                    app.ExperimenterEditField.Value = app.ExperimenterEditField_3.Value;        %Experimenter
                    app.TaskTypeDoneEditField.Value = app.TaskTypeDoneEditField_3.Value;         %Task Type Done
                    app.NotesEditField.Value = app.NotesEditField_3.Value;                      %Notes
                    %intensity of cost 1-4
                    app.Port1EditField_CC.Value  = app.Port1EditField_CC_3.Value;
                    app.Port2EditField_CC.Value = app.Port2EditField_CC_3.Value;
                    app.Port3EditField_CC.Value = app.Port3EditField_CC_3.Value;
                    app.Port4EditField_CC.Value = app.Port4EditField_CC_3.Value;

                    %cost probability 1-4
                    app.Port1EditField_CP.Value = app.Port1EditField_CP_3.Value;
                    app.Port2EditField_CP.Value = app.Port2EditField_CP_3.Value;
                    app.Port3EditField_CP.Value = app.Port3EditField_CP_3.Value;
                    app.Port4EditField_CP.Value = app.Port4EditField_CP_3.Value;

                    %reward concentration 1-4
                    app.Port1EditField_CR_2.Value = app.Port1EditField_CR_4.Value;
                    app.Port2EditField_CR_2.Value= app.Port2EditField_CR_4.Value;
                    app.Port3EditField_CR_2.Value= app.Port3EditField_CR_4.Value;
                    app.Port4EditField_CR_2.Value= app.Port4EditField_CR_4.Value;

                    %reward volume 1-4
                    app.Port1EditField_RV_2.Value = app.Port1EditField_RV_4.Value;
                    app.Port2EditField_RV_2.Value = app.Port2EditField_RV_4.Value;
                    app.Port3EditField_RV_2.Value = app.Port3EditField_RV_4.Value;
                    app.Port4EditField_RV_2.Value = app.Port4EditField_RV_4.Value;

                    %reward probability 1-4
                    app.Port1EditField_RP_2.Value = app.Port1EditField_RP_4.Value;
                    app.Port2EditField_RP_2.Value = app.Port2EditField_RP_4.Value;
                    app.Port3EditField_RP_2.Value = app.Port3EditField_RP_4.Value;
                    app.Port4EditField_RP_2.Value = app.Port4EditField_RP_4.Value;
                case 'Maze 4'
                    app.ExperimenterEditField.Value = app.ExperimenterEditField_4.Value;        %Experimenter
                    app.TaskTypeDoneEditField.Value = app.TaskTypeDoneEditField_4.Value;         %Task Type Done
                    app.NotesEditField.Value = app.NotesEditField_4.Value;                      %Notes
                    %intensity of cost 1-4
                    app.Port1EditField_CC.Value  = app.Port1EditField_CC_4.Value;
                    app.Port2EditField_CC.Value = app.Port2EditField_CC_4.Value;
                    app.Port3EditField_CC.Value = app.Port3EditField_CC_4.Value;
                    app.Port4EditField_CC.Value = app.Port4EditField_CC_4.Value;

                    %cost probability 1-4
                    app.Port1EditField_CP.Value = app.Port1EditField_CP_4.Value;
                    app.Port2EditField_CP.Value = app.Port2EditField_CP_4.Value;
                    app.Port3EditField_CP.Value = app.Port3EditField_CP_4.Value;
                    app.Port4EditField_CP.Value = app.Port4EditField_CP_4.Value;

                    %reward concentration 1-4
                    app.Port1EditField_CR_2.Value = app.Port1EditField_CR_5.Value;
                    app.Port2EditField_CR_2.Value= app.Port2EditField_CR_5.Value;
                    app.Port3EditField_CR_2.Value= app.Port3EditField_CR_5.Value;
                    app.Port4EditField_CR_2.Value= app.Port4EditField_CR_5.Value;

                    %reward volume 1-4
                    app.Port1EditField_RV_2.Value = app.Port1EditField_RV_5.Value;
                    app.Port2EditField_RV_2.Value = app.Port2EditField_RV_5.Value;
                    app.Port3EditField_RV_2.Value = app.Port3EditField_RV_5.Value;
                    app.Port4EditField_RV_2.Value = app.Port4EditField_RV_5.Value;

                    %reward probability 1-4
                    app.Port1EditField_RP_2.Value = app.Port1EditField_RP_5.Value;
                    app.Port2EditField_RP_2.Value = app.Port2EditField_RP_5.Value;
                    app.Port3EditField_RP_2.Value = app.Port3EditField_RP_5.Value;
                    app.Port4EditField_RP_2.Value = app.Port4EditField_RP_5.Value;
            end
        end

        % Value changed function: PortDropDown_2
        function PortDropDown_2ValueChanged(app, event)
            value = app.PortDropDown_2.Value;
            switch value
                case 'Maze 1'
                    app.ExperimenterEditField_2.Value = app.ExperimenterEditField.Value;        %Experimenter
                    app.TaskTypeDoneEditField_2.Value = app.TaskTypeDoneEditField.Value;         %Task Type Done
                    app.NotesEditField_2.Value = app.NotesEditField.Value;                      %Notes
                    %intensity of cost 1-4
                    app.Port1EditField_CC_2.Value  = app.Port1EditField_CC.Value;
                    app.Port2EditField_CC_2.Value = app.Port2EditField_CC.Value;
                    app.Port3EditField_CC_2.Value = app.Port3EditField_CC.Value;
                    app.Port4EditField_CC_2.Value = app.Port4EditField_CC.Value;

                    %cost probability 1-4
                    app.Port1EditField_CP_2.Value = app.Port1EditField_CP.Value;
                    app.Port2EditField_CP_2.Value = app.Port2EditField_CP.Value;
                    app.Port3EditField_CP_2.Value = app.Port3EditField_CP.Value;
                    app.Port4EditField_CP_2.Value = app.Port4EditField_CP.Value;

                    %reward concentration 1-4
                    app.Port1EditField_CR_3.Value = app.Port1EditField_CR_2.Value;
                    app.Port2EditField_CR_3.Value= app.Port2EditField_CR_2.Value;
                    app.Port3EditField_CR_3.Value= app.Port3EditField_CR_2.Value;
                    app.Port4EditField_CR_3.Value= app.Port4EditField_CR_2.Value;

                    %reward volume 1-4
                    app.Port1EditField_RV_3.Value = app.Port1EditField_RV_2.Value;
                    app.Port2EditField_RV_3.Value = app.Port2EditField_RV_2.Value;
                    app.Port3EditField_RV_3.Value = app.Port3EditField_RV_2.Value;
                    app.Port4EditField_RV_3.Value = app.Port4EditField_RV_2.Value;

                    %reward probability 1-4
                    app.Port1EditField_RP_3.Value = app.Port1EditField_RP_2.Value;
                    app.Port2EditField_RP_3.Value = app.Port2EditField_RP_2.Value;
                    app.Port3EditField_RP_3.Value = app.Port3EditField_RP_2.Value;
                    app.Port4EditField_RP_3.Value = app.Port4EditField_RP_2.Value;
                case 'Maze 2'
                case 'Maze 3'
                    app.ExperimenterEditField_2.Value = app.ExperimenterEditField_3.Value;        %Experimenter
                    app.TaskTypeDoneEditField_2.Value = app.TaskTypeDoneEditField_3.Value;         %Task Type Done
                    app.NotesEditField_2.Value = app.NotesEditField_3.Value;                      %Notes
                    %intensity of cost 1-4
                    app.Port1EditField_CC_2.Value  = app.Port1EditField_CC_3.Value;
                    app.Port2EditField_CC_2.Value = app.Port2EditField_CC_3.Value;
                    app.Port3EditField_CC_2.Value = app.Port3EditField_CC_3.Value;
                    app.Port4EditField_CC_2.Value = app.Port4EditField_CC_3.Value;

                    %cost probability 1-4
                    app.Port1EditField_CP_2.Value = app.Port1EditField_CP_3.Value;
                    app.Port2EditField_CP_2.Value = app.Port2EditField_CP_3.Value;
                    app.Port3EditField_CP_2.Value = app.Port3EditField_CP_3.Value;
                    app.Port4EditField_CP_2.Value = app.Port4EditField_CP_3.Value;

                    %reward concentration 1-4
                    app.Port1EditField_CR_3.Value = app.Port1EditField_CR_4.Value;
                    app.Port2EditField_CR_3.Value= app.Port2EditField_CR_4.Value;
                    app.Port3EditField_CR_3.Value= app.Port3EditField_CR_4.Value;
                    app.Port4EditField_CR_3.Value= app.Port4EditField_CR_4.Value;

                    %reward volume 1-4
                    app.Port1EditField_RV_3.Value = app.Port1EditField_RV_4.Value;
                    app.Port2EditField_RV_3.Value = app.Port2EditField_RV_4.Value;
                    app.Port3EditField_RV_3.Value = app.Port3EditField_RV_4.Value;
                    app.Port4EditField_RV_3.Value = app.Port4EditField_RV_4.Value;

                    %reward probability 1-4
                    app.Port1EditField_RP_3.Value = app.Port1EditField_RP_4.Value;
                    app.Port2EditField_RP_3.Value = app.Port2EditField_RP_4.Value;
                    app.Port3EditField_RP_3.Value = app.Port3EditField_RP_4.Value;
                    app.Port4EditField_RP_3.Value = app.Port4EditField_RP_4.Value;
                case 'Maze 4'
                    app.ExperimenterEditField_2.Value = app.ExperimenterEditField_4.Value;        %Experimenter
                    app.TaskTypeDoneEditField_2.Value = app.TaskTypeDoneEditField_4.Value;         %Task Type Done
                    app.NotesEditField_2.Value = app.NotesEditField_4.Value;                      %Notes
                    %intensity of cost 1-4
                    app.Port1EditField_CC_2.Value  = app.Port1EditField_CC_4.Value;
                    app.Port2EditField_CC_2.Value = app.Port2EditField_CC_4.Value;
                    app.Port3EditField_CC_2.Value = app.Port3EditField_CC_4.Value;
                    app.Port4EditField_CC_2.Value = app.Port4EditField_CC_4.Value;

                    %cost probability 1-4
                    app.Port1EditField_CP_2.Value = app.Port1EditField_CP_4.Value;
                    app.Port2EditField_CP_2.Value = app.Port2EditField_CP_4.Value;
                    app.Port3EditField_CP_2.Value = app.Port3EditField_CP_4.Value;
                    app.Port4EditField_CP_2.Value = app.Port4EditField_CP_4.Value;

                    %reward concentration 1-4
                    app.Port1EditField_CR_3.Value = app.Port1EditField_CR_5.Value;
                    app.Port2EditField_CR_3.Value= app.Port2EditField_CR_5.Value;
                    app.Port3EditField_CR_3.Value= app.Port3EditField_CR_5.Value;
                    app.Port4EditField_CR_3.Value= app.Port4EditField_CR_5.Value;

                    %reward volume 1-4
                    app.Port1EditField_RV_3.Value = app.Port1EditField_RV_5.Value;
                    app.Port2EditField_RV_3.Value = app.Port2EditField_RV_5.Value;
                    app.Port3EditField_RV_3.Value = app.Port3EditField_RV_5.Value;
                    app.Port4EditField_RV_3.Value = app.Port4EditField_RV_5.Value;

                    %reward probability 1-4
                    app.Port1EditField_RP_3.Value = app.Port1EditField_RP_5.Value;
                    app.Port2EditField_RP_3.Value = app.Port2EditField_RP_5.Value;
                    app.Port3EditField_RP_3.Value = app.Port3EditField_RP_5.Value;
                    app.Port4EditField_RP_3.Value = app.Port4EditField_RP_5.Value;
            end
        end

        % Value changed function: PortDropDown_3
        function PortDropDown_3ValueChanged(app, event)
            value = app.PortDropDown_3.Value;
            switch value
                case 'Maze 1'
                    app.ExperimenterEditField_3.Value = app.ExperimenterEditField.Value;        %Experimenter
                    app.TaskTypeDoneEditField_3.Value = app.TaskTypeDoneEditField.Value;         %Task Type Done
                    app.NotesEditField_3.Value = app.NotesEditField.Value;                      %Notes
                    %intensity of cost 1-4
                    app.Port1EditField_CC_3.Value  = app.Port1EditField_CC.Value;
                    app.Port2EditField_CC_3.Value = app.Port2EditField_CC.Value;
                    app.Port3EditField_CC_3.Value = app.Port3EditField_CC.Value;
                    app.Port4EditField_CC_3.Value = app.Port4EditField_CC.Value;

                    %cost probability 1-4
                    app.Port1EditField_CP_3.Value = app.Port1EditField_CP.Value;
                    app.Port2EditField_CP_3.Value = app.Port2EditField_CP.Value;
                    app.Port3EditField_CP_3.Value = app.Port3EditField_CP.Value;
                    app.Port4EditField_CP_3.Value = app.Port4EditField_CP.Value;

                    %reward concentration 1-4
                    app.Port1EditField_CR_4.Value = app.Port1EditField_CR_2.Value;
                    app.Port2EditField_CR_4.Value= app.Port2EditField_CR_2.Value;
                    app.Port3EditField_CR_4.Value= app.Port3EditField_CR_2.Value;
                    app.Port4EditField_CR_4.Value= app.Port4EditField_CR_2.Value;

                    %reward volume 1-4
                    app.Port1EditField_RV_4.Value = app.Port1EditField_RV_2.Value;
                    app.Port2EditField_RV_4.Value = app.Port2EditField_RV_2.Value;
                    app.Port3EditField_RV_4.Value = app.Port3EditField_RV_2.Value;
                    app.Port4EditField_RV_4.Value = app.Port4EditField_RV_2.Value;

                    %reward probability 1-4
                    app.Port1EditField_RP_4.Value = app.Port1EditField_RP_2.Value;
                    app.Port2EditField_RP_4.Value = app.Port2EditField_RP_2.Value;
                    app.Port3EditField_RP_4.Value = app.Port3EditField_RP_2.Value;
                    app.Port4EditField_RP_4.Value = app.Port4EditField_RP_2.Value;
                case 'Maze 2'
                    app.ExperimenterEditField_3.Value = app.ExperimenterEditField_2.Value;        %Experimenter
                    app.TaskTypeDoneEditField_3.Value = app.TaskTypeDoneEditField_2.Value;         %Task Type Done
                    app.NotesEditField_3.Value = app.NotesEditField_2.Value;                      %Notes
                    %intensity of cost 1-4
                    app.Port1EditField_CC_3.Value  = app.Port1EditField_CC_2.Value;
                    app.Port2EditField_CC_3.Value = app.Port2EditField_CC_2.Value;
                    app.Port3EditField_CC_3.Value = app.Port3EditField_CC_2.Value;
                    app.Port4EditField_CC_3.Value = app.Port4EditField_CC_2.Value;

                    %cost probability 1-4
                    app.Port1EditField_CP_3.Value = app.Port1EditField_CP_2.Value;
                    app.Port2EditField_CP_3.Value = app.Port2EditField_CP_2.Value;
                    app.Port3EditField_CP_3.Value = app.Port3EditField_CP_2.Value;
                    app.Port4EditField_CP_3.Value = app.Port4EditField_CP_2.Value;

                    %reward concentration 1-4
                    app.Port1EditField_CR_4.Value = app.Port1EditField_CR_3.Value;
                    app.Port2EditField_CR_4.Value= app.Port2EditField_CR_3.Value;
                    app.Port3EditField_CR_4.Value= app.Port3EditField_CR_3.Value;
                    app.Port4EditField_CR_4.Value= app.Port4EditField_CR_3.Value;

                    %reward volume 1-4
                    app.Port1EditField_RV_4.Value = app.Port1EditField_RV_3.Value;
                    app.Port2EditField_RV_4.Value = app.Port2EditField_RV_3.Value;
                    app.Port3EditField_RV_4.Value = app.Port3EditField_RV_3.Value;
                    app.Port4EditField_RV_4.Value = app.Port4EditField_RV_3.Value;

                    %reward probability 1-4
                    app.Port1EditField_RP_4.Value = app.Port1EditField_RP_3.Value;
                    app.Port2EditField_RP_4.Value = app.Port2EditField_RP_3.Value;
                    app.Port3EditField_RP_4.Value = app.Port3EditField_RP_3.Value;
                    app.Port4EditField_RP_4.Value = app.Port4EditField_RP_3.Value;
                case 'Maze 3'
                case 'Maze 4'
                    app.ExperimenterEditField_3.Value = app.ExperimenterEditField_4.Value;        %Experimenter
                    app.TaskTypeDoneEditField_3.Value = app.TaskTypeDoneEditField_4.Value;         %Task Type Done
                    app.NotesEditField_3.Value = app.NotesEditField_4.Value;                      %Notes
                    %intensity of cost 1-4
                    app.Port1EditField_CC_3.Value  = app.Port1EditField_CC_4.Value;
                    app.Port2EditField_CC_3.Value = app.Port2EditField_CC_4.Value;
                    app.Port3EditField_CC_3.Value = app.Port3EditField_CC_4.Value;
                    app.Port4EditField_CC_3.Value = app.Port4EditField_CC_4.Value;

                    %cost probability 1-4
                    app.Port1EditField_CP_3.Value = app.Port1EditField_CP_4.Value;
                    app.Port2EditField_CP_3.Value = app.Port2EditField_CP_4.Value;
                    app.Port3EditField_CP_3.Value = app.Port3EditField_CP_4.Value;
                    app.Port4EditField_CP_3.Value = app.Port4EditField_CP_4.Value;

                    %reward concentration 1-4
                    app.Port1EditField_CR_4.Value = app.Port1EditField_CR_5.Value;
                    app.Port2EditField_CR_4.Value= app.Port2EditField_CR_5.Value;
                    app.Port3EditField_CR_4.Value= app.Port3EditField_CR_5.Value;
                    app.Port4EditField_CR_4.Value= app.Port4EditField_CR_5.Value;

                    %reward volume 1-4
                    app.Port1EditField_RV_4.Value = app.Port1EditField_RV_5.Value;
                    app.Port2EditField_RV_4.Value = app.Port2EditField_RV_5.Value;
                    app.Port3EditField_RV_4.Value = app.Port3EditField_RV_5.Value;
                    app.Port4EditField_RV_4.Value = app.Port4EditField_RV_5.Value;

                    %reward probability 1-4
                    app.Port1EditField_RP_4.Value = app.Port1EditField_RP_5.Value;
                    app.Port2EditField_RP_4.Value = app.Port2EditField_RP_5.Value;
                    app.Port3EditField_RP_4.Value = app.Port3EditField_RP_5.Value;
                    app.Port4EditField_RP_4.Value = app.Port4EditField_RP_5.Value;
            end
        end

        % Value changed function: PortDropDown_4
        function PortDropDown_4ValueChanged(app, event)
            value = app.PortDropDown_4.Value;
            switch value
                case 'Maze 1'
                    app.ExperimenterEditField_4.Value = app.ExperimenterEditField.Value;        %Experimenter
                    app.TaskTypeDoneEditField_4.Value = app.TaskTypeDoneEditField.Value;         %Task Type Done
                    app.NotesEditField_3.Value = app.NotesEditField.Value;                      %Notes
                    %intensity of cost 1-4
                    app.Port1EditField_CC_4.Value  = app.Port1EditField_CC.Value;
                    app.Port2EditField_CC_4.Value = app.Port2EditField_CC.Value;
                    app.Port3EditField_CC_4.Value = app.Port3EditField_CC.Value;
                    app.Port4EditField_CC_4.Value = app.Port4EditField_CC.Value;

                    %cost probability 1-4
                    app.Port1EditField_CP_4.Value = app.Port1EditField_CP.Value;
                    app.Port2EditField_CP_4.Value = app.Port2EditField_CP.Value;
                    app.Port3EditField_CP_4.Value = app.Port3EditField_CP.Value;
                    app.Port4EditField_CP_4.Value = app.Port4EditField_CP.Value;

                    %reward concentration 1-4
                    app.Port1EditField_CR_5.Value = app.Port1EditField_CR_2.Value;
                    app.Port2EditField_CR_5.Value= app.Port2EditField_CR_2.Value;
                    app.Port3EditField_CR_5.Value= app.Port3EditField_CR_2.Value;
                    app.Port4EditField_CR_5.Value= app.Port4EditField_CR_2.Value;

                    %reward volume 1-4
                    app.Port1EditField_RV_5.Value = app.Port1EditField_RV_2.Value;
                    app.Port2EditField_RV_5.Value = app.Port2EditField_RV_2.Value;
                    app.Port3EditField_RV_5.Value = app.Port3EditField_RV_2.Value;
                    app.Port4EditField_RV_5.Value = app.Port4EditField_RV_2.Value;

                    %reward probability 1-4
                    app.Port1EditField_RP_5.Value = app.Port1EditField_RP_2.Value;
                    app.Port2EditField_RP_5.Value = app.Port2EditField_RP_2.Value;
                    app.Port3EditField_RP_5.Value = app.Port3EditField_RP_2.Value;
                    app.Port4EditField_RP_5.Value = app.Port4EditField_RP_2.Value;
                case 'Maze 2'
                    app.ExperimenterEditField_4.Value = app.ExperimenterEditField_2.Value;        %Experimenter
                    app.TaskTypeDoneEditField_4.Value = app.TaskTypeDoneEditField_2.Value;         %Task Type Done
                    app.NotesEditField_4.Value = app.NotesEditField_2.Value;                      %Notes
                    %intensity of cost 1-4
                    app.Port1EditField_CC_4.Value  = app.Port1EditField_CC_2.Value;
                    app.Port2EditField_CC_4.Value = app.Port2EditField_CC_2.Value;
                    app.Port3EditField_CC_4.Value = app.Port3EditField_CC_2.Value;
                    app.Port4EditField_CC_4.Value = app.Port4EditField_CC_2.Value;

                    %cost probability 1-4
                    app.Port1EditField_CP_4.Value = app.Port1EditField_CP_2.Value;
                    app.Port2EditField_CP_4.Value = app.Port2EditField_CP_2.Value;
                    app.Port3EditField_CP_4.Value = app.Port3EditField_CP_2.Value;
                    app.Port4EditField_CP_4.Value = app.Port4EditField_CP_2.Value;

                    %reward concentration 1-4
                    app.Port1EditField_CR_5.Value = app.Port1EditField_CR_3.Value;
                    app.Port2EditField_CR_5.Value= app.Port2EditField_CR_3.Value;
                    app.Port3EditField_CR_5.Value= app.Port3EditField_CR_3.Value;
                    app.Port4EditField_CR_5.Value= app.Port4EditField_CR_3.Value;

                    %reward volume 1-4
                    app.Port1EditField_RV_5.Value = app.Port1EditField_RV_3.Value;
                    app.Port2EditField_RV_5.Value = app.Port2EditField_RV_3.Value;
                    app.Port3EditField_RV_5.Value = app.Port3EditField_RV_3.Value;
                    app.Port4EditField_RV_5.Value = app.Port4EditField_RV_3.Value;

                    %reward probability 1-4
                    app.Port1EditField_RP_5.Value = app.Port1EditField_RP_3.Value;
                    app.Port2EditField_RP_5.Value = app.Port2EditField_RP_3.Value;
                    app.Port3EditField_RP_5.Value = app.Port3EditField_RP_3.Value;
                    app.Port4EditField_RP_5.Value = app.Port4EditField_RP_3.Value;
                case 'Maze 3'
                    app.ExperimenterEditField_4.Value = app.ExperimenterEditField_3.Value;        %Experimenter
                    app.TaskTypeDoneEditField_4.Value = app.TaskTypeDoneEditField_3.Value;         %Task Type Done
                    app.NotesEditField_4.Value = app.NotesEditField_3.Value;                      %Notes
                    %intensity of cost 1-4
                    app.Port1EditField_CC_4.Value  = app.Port1EditField_CC_3.Value;
                    app.Port2EditField_CC_4.Value = app.Port2EditField_CC_3.Value;
                    app.Port3EditField_CC_4.Value = app.Port3EditField_CC_3.Value;
                    app.Port4EditField_CC_4.Value = app.Port4EditField_CC_3.Value;

                    %cost probability 1-4
                    app.Port1EditField_CP_4.Value = app.Port1EditField_CP_3.Value;
                    app.Port2EditField_CP_4.Value = app.Port2EditField_CP_3.Value;
                    app.Port3EditField_CP_4.Value = app.Port3EditField_CP_3.Value;
                    app.Port4EditField_CP_4.Value = app.Port4EditField_CP_3.Value;

                    %reward concentration 1-4
                    app.Port1EditField_CR_5.Value = app.Port1EditField_CR_4.Value;
                    app.Port2EditField_CR_5.Value= app.Port2EditField_CR_4.Value;
                    app.Port3EditField_CR_5.Value= app.Port3EditField_CR_4.Value;
                    app.Port4EditField_CR_5.Value= app.Port4EditField_CR_4.Value;

                    %reward volume 1-4
                    app.Port1EditField_RV_5.Value = app.Port1EditField_RV_4.Value;
                    app.Port2EditField_RV_5.Value = app.Port2EditField_RV_4.Value;
                    app.Port3EditField_RV_5.Value = app.Port3EditField_RV_4.Value;
                    app.Port4EditField_RV_5.Value = app.Port4EditField_RV_4.Value;

                    %reward probability 1-4
                    app.Port1EditField_RP_5.Value = app.Port1EditField_RP_4.Value;
                    app.Port2EditField_RP_5.Value = app.Port2EditField_RP_4.Value;
                    app.Port3EditField_RP_5.Value = app.Port3EditField_RP_4.Value;
                    app.Port4EditField_RP_5.Value = app.Port4EditField_RP_4.Value;
                case 'Maze 4'
                    app.ExperimenterEditField_4.Value = app.ExperimenterEditField_4.Value;        %Experimenter
                    app.TaskTypeDoneEditField_4.Value = app.TaskTypeDoneEditField_4.Value;         %Task Type Done
                    app.NotesEditField_4.Value = app.NotesEditField_4.Value;                      %Notes
                    %intensity of cost 1-4
                    app.Port1EditField_CC_4.Value  = app.Port1EditField_CC_4.Value;
                    app.Port2EditField_CC_4.Value = app.Port2EditField_CC_4.Value;
                    app.Port3EditField_CC_4.Value = app.Port3EditField_CC_4.Value;
                    app.Port4EditField_CC_4.Value = app.Port4EditField_CC_4.Value;

                    %cost probability 1-4
                    app.Port1EditField_CP_4.Value = app.Port1EditField_CP_4.Value;
                    app.Port2EditField_CP_4.Value = app.Port2EditField_CP_4.Value;
                    app.Port3EditField_CP_4.Value = app.Port3EditField_CP_4.Value;
                    app.Port4EditField_CP_4.Value = app.Port4EditField_CP_4.Value;

                    %reward concentration 1-4
                    app.Port1EditField_CR_5.Value = app.Port1EditField_CR_5.Value;
                    app.Port2EditField_CR_5.Value= app.Port2EditField_CR_5.Value;
                    app.Port3EditField_CR_5.Value= app.Port3EditField_CR_5.Value;
                    app.Port4EditField_CR_5.Value= app.Port4EditField_CR_5.Value;

                    %reward volume 1-4
                    app.Port1EditField_RV_5.Value = app.Port1EditField_RV_5.Value;
                    app.Port2EditField_RV_5.Value = app.Port2EditField_RV_5.Value;
                    app.Port3EditField_RV_5.Value = app.Port3EditField_RV_5.Value;
                    app.Port4EditField_RV_5.Value = app.Port4EditField_RV_5.Value;

                    %reward probability 1-4
                    app.Port1EditField_RP_5.Value = app.Port1EditField_RP_5.Value;
                    app.Port2EditField_RP_5.Value = app.Port2EditField_RP_5.Value;
                    app.Port3EditField_RP_5.Value = app.Port3EditField_RP_5.Value;
                    app.Port4EditField_RP_5.Value = app.Port4EditField_RP_5.Value;
            end
        end

        % Button pushed function: SeeUploadHistoryButton
        function SeeUploadHistoryButtonPushed(app, event)
            conn = database(app.datasource,app.username,app.password);
            tablename = "livetable2";
            local = sqlread(conn,tablename);

            %take the number of rows in the table
            h = height(local);
            i = 1;
            T = table();
            %creates a table of only the names and dates of the rats trials
            namesAndDates = [local.referencetime,local.subjectid,local.mazenumber];
            %display(namesAndDates)

            %creates matlab containers that have the date as the key and
            %the names as the values
            M = containers.Map('Keytype', 'char', 'ValueType', 'any');

            while i <= h
                %get the original date out of the row
                ogdate = string(namesAndDates(i,1));
                %extract only the first part of the date
                newdate = split(ogdate, " ");
                newdate = string(newdate(1));

                %try to add a new value to the container at key value
                %new date
                try
                    %if current name is NOT already in the value then the
                    %new name is added to the value
                    if contains(namesAndDates(i,2), "none")
                        i = i +1;
                        continue
                    end
                    if contains(M(newdate),namesAndDates(i,2)) == 0
                        M(newdate) = strcat(M(newdate), " [",string(namesAndDates(i,2)), " | ",string(namesAndDates(i,3)),"]");
                    end
                catch
                    %if we can't add new value to the key that must mean
                    %the key doesn't appear yet, so we add the key and the
                    %new value to the container
                    M(newdate) = strcat("[",string(namesAndDates(i,2))," | ",string(namesAndDates(i,3)),"]");
                end
                i = i+1;
            end


%                        disp(keys(M))
                         %disp((values(M)).')
            %turn the values set into a vertical array for table formatting
            T.Names = (values(M)).';
            %turn the key set into a vertical array for table formatting
            T.Dates = (keys(M)).';

            sortingRow = [];


            %sort the table so dates appear in ascending order
            i = 1;
            while i <= height(T)
                %split the dates into their independent month/day/year
                dateSplit = split(T{i,2},'/');
                %convert the month/day/year into minutes and add
                dateValue = str2double(dateSplit(1))*43800 +str2double(dateSplit(2))*1440 +str2double(dateSplit(3))*525600;
                %store value into an array
                sortingRow(i) = dateValue;
                i = i +1;
            end
            %             disp(sortingRow)


            sortingTable = table();
            sortingTable.Names = (values(M)).';
            sortingTable.Dates = T.Dates;
            sortingTable.sortingColumn = (sortingRow).';
            %             display(sortingTable)
            %sort the sorting table
            [tblB,index] = sortrows(sortingTable,'sortingColumn');
            %             disp(tblB)

            %store the newly sorted values into the display table
            T.Dates = tblB.Dates;
            T.Names = tblB.Names;

            %rearrage table to put dates first
            T = movevars(T,'Names','After','Dates');

            %display table to user
            fig = uifigure;
            uit = uitable(fig,'ColumnWidth', 'fit', 'Data',T, 'Position', [1,1,510,400]);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel_4 = uipanel(app.UIFigure);
            app.Maze1Panel_2 = uipanel(app.Panel_4);
            app.Maze1Panel_2.Title = 'Maze 1';
            app.ExperimenterEditFieldLabel = uilabel(app.Maze1Panel_2);
            app.ExperimenterEditFieldLabel.Text = 'Experimenter';
            app.ExperimenterEditField = uieditfield(app.Maze1Panel_2, 'text');
            app.TaskTypeDoneEditFieldLabel = uilabel(app.Maze1Panel_2);
            app.TaskTypeDoneEditFieldLabel.Text = 'Task Type Done:';
            app.TaskTypeDoneEditField = uieditfield(app.Maze1Panel_2, 'text');
            app.Port1EditField_CC = uieditfield(app.Maze1Panel_2, 'text');
            app.Port2EditField_CC = uieditfield(app.Maze1Panel_2, 'text');
            app.Port3EditField_CC = uieditfield(app.Maze1Panel_2, 'text');
            app.Port4EditField_CC = uieditfield(app.Maze1Panel_2, 'text');
            app.Port1EditField_CP = uieditfield(app.Maze1Panel_2, 'text');
            app.Port2EditField_CP = uieditfield(app.Maze1Panel_2, 'text');
            app.Port3EditField_CP = uieditfield(app.Maze1Panel_2, 'text');
            app.Port4EditField_CP = uieditfield(app.Maze1Panel_2, 'text');
            app.Port1EditField_CR_2 = uieditfield(app.Maze1Panel_2, 'text');
            app.Port2EditField_CR_2 = uieditfield(app.Maze1Panel_2, 'text');
            app.Port3EditField_CR_2 = uieditfield(app.Maze1Panel_2, 'text');
            app.Port4EditField_CR_2 = uieditfield(app.Maze1Panel_2, 'text');
            app.Port1EditField_RV_2 = uieditfield(app.Maze1Panel_2, 'text');
            app.Port2EditField_RV_2 = uieditfield(app.Maze1Panel_2, 'text');
            app.Port3EditField_RV_2 = uieditfield(app.Maze1Panel_2, 'text');
            app.Port4EditField_RV_2 = uieditfield(app.Maze1Panel_2, 'text');
            app.Port1EditField_RP_2 = uieditfield(app.Maze1Panel_2, 'text');
            app.Port2EditField_RP_2 = uieditfield(app.Maze1Panel_2, 'text');
            app.Port3EditField_RP_2 = uieditfield(app.Maze1Panel_2, 'text');
            app.Port4EditField_RP_2 = uieditfield(app.Maze1Panel_2, 'text');
            app.IntensityofCostLabel = uilabel(app.Maze1Panel_2);
            app.IntensityofCostLabel.Text = 'Intensity of Cost';
            app.CostProbabilityLabel = uilabel(app.Maze1Panel_2);
            app.CostProbabilityLabel.Text = 'Cost Probability:';
            app.RewardConcentrationLabel = uilabel(app.Maze1Panel_2);
            app.RewardConcentrationLabel.Text = 'Reward Concentration';
            app.RewardVolumeLabel = uilabel(app.Maze1Panel_2);
            app.RewardVolumeLabel.Text = 'Reward Volume';
            app.RewardProbabilityLabel_2 = uilabel(app.Maze1Panel_2);
            app.RewardProbabilityLabel_2.Text = 'Reward Probability:';
            app.DiagonalLabel = uilabel(app.Maze1Panel_2);
            app.DiagonalLabel.Text = 'Diagonal';
            app.GridLabel = uilabel(app.Maze1Panel_2);
            app.GridLabel.Text = 'Grid';
            app.HorizontalLabel = uilabel(app.Maze1Panel_2);
            app.HorizontalLabel.Text = 'Horizontal';
            app.RadialLabel = uilabel(app.Maze1Panel_2);
            app.RadialLabel.Text = 'Radial';
            app.NotesEditFieldLabel = uilabel(app.Maze1Panel_2);
            app.NotesEditFieldLabel.Text = 'Notes';
            app.NotesEditField = uieditfield(app.Maze1Panel_2, 'text');
            app.Level1Label = uilabel(app.Maze1Panel_2);
            app.Level1Label.Text = 'Level 1';
            app.Level2Label = uilabel(app.Maze1Panel_2);
            app.Level2Label.Text = 'Level 2';
            app.Level3Label = uilabel(app.Maze1Panel_2);
            app.Level3Label.Text = 'Level 3';
            app.Level4Label = uilabel(app.Maze1Panel_2);
            app.Level4Label.Text = 'Level 4';
            app.PortDropDownLabel = uilabel(app.Maze1Panel_2);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.Maze1Panel_2);
            app.PortDropDown.Items = {'Maze 1', 'Maze 2', 'Maze 3', 'Maze 4'};
            app.PortDropDown.ValueChangedFcn = createCallbackFcn(app, @PortDropDownValueChanged, true);
            app.PortDropDown.Tooltip = {'This lets your port another Maze''s details into here. '};
            app.PortDropDown.Value = 'Maze 1';
            app.SelectNodulusFileButton = uibutton(app.Panel_4, 'push');
            app.SelectNodulusFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectNodulusFileButtonPushed, true);
            app.SelectNodulusFileButton.Tooltip = {'This button will let you select the file to upload'};
            app.SelectNodulusFileButton.Text = 'Select Nodulus File';
            app.AddDataToDatabaseButton = uibutton(app.Panel_4, 'push');
            app.AddDataToDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @AddDataToDatabaseButtonPushed, true);
            app.AddDataToDatabaseButton.Text = 'Add Data To Database';
            app.Maze2Panel_2 = uipanel(app.Panel_4);
            app.Maze2Panel_2.Title = 'Maze 2';
            app.ExperimenterEditField_2Label = uilabel(app.Maze2Panel_2);
            app.ExperimenterEditField_2Label.Text = 'Experimenter';
            app.ExperimenterEditField_2 = uieditfield(app.Maze2Panel_2, 'text');
            app.TaskTypeDoneEditField_2Label = uilabel(app.Maze2Panel_2);
            app.TaskTypeDoneEditField_2Label.Text = 'Task Type Done:';
            app.TaskTypeDoneEditField_2 = uieditfield(app.Maze2Panel_2, 'text');
            app.Port1EditField_CC_2 = uieditfield(app.Maze2Panel_2, 'text');
            app.Port2EditField_CC_2 = uieditfield(app.Maze2Panel_2, 'text');
            app.Port3EditField_CC_2 = uieditfield(app.Maze2Panel_2, 'text');
            app.Port4EditField_CC_2 = uieditfield(app.Maze2Panel_2, 'text');
            app.Port1EditField_CP_2 = uieditfield(app.Maze2Panel_2, 'text');
            app.Port2EditField_CP_2 = uieditfield(app.Maze2Panel_2, 'text');
            app.Port3EditField_CP_2 = uieditfield(app.Maze2Panel_2, 'text');
            app.Port4EditField_CP_2 = uieditfield(app.Maze2Panel_2, 'text');
            app.Port1EditField_CR_3 = uieditfield(app.Maze2Panel_2, 'text');
            app.Port2EditField_CR_3 = uieditfield(app.Maze2Panel_2, 'text');
            app.Port3EditField_CR_3 = uieditfield(app.Maze2Panel_2, 'text');
            app.Port4EditField_CR_3 = uieditfield(app.Maze2Panel_2, 'text');
            app.Port1EditField_RV_3 = uieditfield(app.Maze2Panel_2, 'text');
            app.Port2EditField_RV_3 = uieditfield(app.Maze2Panel_2, 'text');
            app.Port3EditField_RV_3 = uieditfield(app.Maze2Panel_2, 'text');
            app.Port4EditField_RV_3 = uieditfield(app.Maze2Panel_2, 'text');
            app.Port1EditField_RP_3 = uieditfield(app.Maze2Panel_2, 'text');
            app.Port2EditField_RP_3 = uieditfield(app.Maze2Panel_2, 'text');
            app.Port3EditField_RP_3 = uieditfield(app.Maze2Panel_2, 'text');
            app.Port4EditField_RP_3 = uieditfield(app.Maze2Panel_2, 'text');
            app.IntensityofCostLabel_2 = uilabel(app.Maze2Panel_2);
            app.IntensityofCostLabel_2.Text = 'Intensity of Cost';
            app.CostProbabilityLabel_2 = uilabel(app.Maze2Panel_2);
            app.CostProbabilityLabel_2.Text = 'Cost Probability:';
            app.RewardConcentrationLabel_2 = uilabel(app.Maze2Panel_2);
            app.RewardConcentrationLabel_2.Text = 'Reward Concentration';
            app.RewardVolumeLabel_2 = uilabel(app.Maze2Panel_2);
            app.RewardVolumeLabel_2.Text = 'Reward Volume';
            app.RewardProbabilityLabel_3 = uilabel(app.Maze2Panel_2);
            app.RewardProbabilityLabel_3.Text = 'Reward Probability:';
            app.NotesEditField_2Label = uilabel(app.Maze2Panel_2);
            app.NotesEditField_2Label.Text = 'Notes';
            app.NotesEditField_2 = uieditfield(app.Maze2Panel_2, 'text');
            app.Level1Label_2 = uilabel(app.Maze2Panel_2);
            app.Level1Label_2.Text = 'Level 1';
            app.Level2Label_2 = uilabel(app.Maze2Panel_2);
            app.Level2Label_2.Text = 'Level 2';
            app.Level3Label_2 = uilabel(app.Maze2Panel_2);
            app.Level3Label_2.Text = 'Level 3';
            app.Level4Label_2 = uilabel(app.Maze2Panel_2);
            app.Level4Label_2.Text = 'Level 4';
            app.PortDropDown_2Label = uilabel(app.Maze2Panel_2);
            app.PortDropDown_2Label.Text = 'Port';
            app.PortDropDown_2 = uidropdown(app.Maze2Panel_2);
            app.PortDropDown_2.Items = {'Maze 1', 'Maze 2', 'Maze 3', 'Maze 4'};
            app.PortDropDown_2.ValueChangedFcn = createCallbackFcn(app, @PortDropDown_2ValueChanged, true);
            app.PortDropDown_2.Tooltip = {'This lets your port another Maze''s details into here. '};
            app.PortDropDown_2.Value = 'Maze 2';
            app.DiagonalLabel_2 = uilabel(app.Maze2Panel_2);
            app.DiagonalLabel_2.Text = 'Diagonal';
            app.GridLabel_2 = uilabel(app.Maze2Panel_2);
            app.GridLabel_2.Text = 'Grid';
            app.HorizontalLabel_2 = uilabel(app.Maze2Panel_2);
            app.HorizontalLabel_2.Text = 'Horizontal';
            app.RadialLabel_2 = uilabel(app.Maze2Panel_2);
            app.RadialLabel_2.Text = 'Radial';
            app.Maze3Panel_2 = uipanel(app.Panel_4);
            app.Maze3Panel_2.Title = 'Maze 3';
            app.ExperimenterEditField_3Label = uilabel(app.Maze3Panel_2);
            app.ExperimenterEditField_3Label.Text = 'Experimenter';
            app.ExperimenterEditField_3 = uieditfield(app.Maze3Panel_2, 'text');
            app.TaskTypeDoneEditField_3Label = uilabel(app.Maze3Panel_2);
            app.TaskTypeDoneEditField_3Label.Text = 'Task Type Done:';
            app.TaskTypeDoneEditField_3 = uieditfield(app.Maze3Panel_2, 'text');
            app.Port1EditField_CC_3 = uieditfield(app.Maze3Panel_2, 'text');
            app.Port2EditField_CC_3 = uieditfield(app.Maze3Panel_2, 'text');
            app.Port3EditField_CC_3 = uieditfield(app.Maze3Panel_2, 'text');
            app.Port4EditField_CC_3 = uieditfield(app.Maze3Panel_2, 'text');
            app.Port1EditField_CP_3 = uieditfield(app.Maze3Panel_2, 'text');
            app.Port2EditField_CP_3 = uieditfield(app.Maze3Panel_2, 'text');
            app.Port3EditField_CP_3 = uieditfield(app.Maze3Panel_2, 'text');
            app.Port4EditField_CP_3 = uieditfield(app.Maze3Panel_2, 'text');
            app.Port1EditField_CR_4 = uieditfield(app.Maze3Panel_2, 'text');
            app.Port2EditField_CR_4 = uieditfield(app.Maze3Panel_2, 'text');
            app.Port3EditField_CR_4 = uieditfield(app.Maze3Panel_2, 'text');
            app.Port4EditField_CR_4 = uieditfield(app.Maze3Panel_2, 'text');
            app.Port1EditField_RV_4 = uieditfield(app.Maze3Panel_2, 'text');
            app.Port2EditField_RV_4 = uieditfield(app.Maze3Panel_2, 'text');
            app.Port3EditField_RV_4 = uieditfield(app.Maze3Panel_2, 'text');
            app.Port4EditField_RV_4 = uieditfield(app.Maze3Panel_2, 'text');
            app.Port1EditField_RP_4 = uieditfield(app.Maze3Panel_2, 'text');
            app.Port2EditField_RP_4 = uieditfield(app.Maze3Panel_2, 'text');
            app.Port3EditField_RP_4 = uieditfield(app.Maze3Panel_2, 'text');
            app.Port4EditField_RP_4 = uieditfield(app.Maze3Panel_2, 'text');
            app.IntensityofCostLabel_3 = uilabel(app.Maze3Panel_2);
            app.IntensityofCostLabel_3.Text = 'Intensity of Cost';
            app.CostProbabilityLabel_3 = uilabel(app.Maze3Panel_2);
            app.CostProbabilityLabel_3.Text = 'Cost Probability:';
            app.RewardConcentrationLabel_3 = uilabel(app.Maze3Panel_2);
            app.RewardConcentrationLabel_3.Text = 'Reward Concentration';
            app.RewardVolumeLabel_3 = uilabel(app.Maze3Panel_2);
            app.RewardVolumeLabel_3.Text = 'Reward Volume';
            app.RewardProbabilityLabel_4 = uilabel(app.Maze3Panel_2);
            app.RewardProbabilityLabel_4.Text = 'Reward Probability:';
            app.NotesEditField_3Label = uilabel(app.Maze3Panel_2);
            app.NotesEditField_3Label.Text = 'Notes';
            app.NotesEditField_3 = uieditfield(app.Maze3Panel_2, 'text');
            app.Level1Label_3 = uilabel(app.Maze3Panel_2);
            app.Level1Label_3.Text = 'Level 1';
            app.Level2Label_3 = uilabel(app.Maze3Panel_2);
            app.Level2Label_3.Text = 'Level 2';
            app.Level3Label_3 = uilabel(app.Maze3Panel_2);
            app.Level3Label_3.Text = 'Level 3';
            app.Level4Label_3 = uilabel(app.Maze3Panel_2);
            app.Level4Label_3.Text = 'Level 4';
            app.PortDropDown_3Label = uilabel(app.Maze3Panel_2);
            app.PortDropDown_3Label.Text = 'Port';
            app.PortDropDown_3 = uidropdown(app.Maze3Panel_2);
            app.PortDropDown_3.Items = {'Maze 1', 'Maze 2', 'Maze 3', 'Maze 4'};
            app.PortDropDown_3.ValueChangedFcn = createCallbackFcn(app, @PortDropDown_3ValueChanged, true);
            app.PortDropDown_3.Tooltip = {'This lets your port another Maze''s details into here. '};
            app.PortDropDown_3.Value = 'Maze 3';
            app.DiagonalLabel_4 = uilabel(app.Maze3Panel_2);
            app.DiagonalLabel_4.Text = 'Diagonal';
            app.GridLabel_4 = uilabel(app.Maze3Panel_2);
            app.GridLabel_4.Text = 'Grid';
            app.HorizontalLabel_4 = uilabel(app.Maze3Panel_2);
            app.HorizontalLabel_4.Text = 'Horizontal';
            app.RadialLabel_4 = uilabel(app.Maze3Panel_2);
            app.RadialLabel_4.Text = 'Radial';
            app.Maze4Panel_2 = uipanel(app.Panel_4);
            app.Maze4Panel_2.Title = 'Maze 4';
            app.ExperimenterEditField_4Label = uilabel(app.Maze4Panel_2);
            app.ExperimenterEditField_4Label.Text = 'Experimenter';
            app.ExperimenterEditField_4 = uieditfield(app.Maze4Panel_2, 'text');
            app.TaskTypeDoneEditField_4Label = uilabel(app.Maze4Panel_2);
            app.TaskTypeDoneEditField_4Label.Text = 'Task Type Done:';
            app.TaskTypeDoneEditField_4 = uieditfield(app.Maze4Panel_2, 'text');
            app.Port1EditField_CC_4 = uieditfield(app.Maze4Panel_2, 'text');
            app.Port2EditField_CC_4 = uieditfield(app.Maze4Panel_2, 'text');
            app.Port3EditField_CC_4 = uieditfield(app.Maze4Panel_2, 'text');
            app.Port4EditField_CC_4 = uieditfield(app.Maze4Panel_2, 'text');
            app.Port1EditField_CP_4 = uieditfield(app.Maze4Panel_2, 'text');
            app.Port2EditField_CP_4 = uieditfield(app.Maze4Panel_2, 'text');
            app.Port3EditField_CP_4 = uieditfield(app.Maze4Panel_2, 'text');
            app.Port4EditField_CP_4 = uieditfield(app.Maze4Panel_2, 'text');
            app.Port1EditField_CR_5 = uieditfield(app.Maze4Panel_2, 'text');
            app.Port2EditField_CR_5 = uieditfield(app.Maze4Panel_2, 'text');
            app.Port3EditField_CR_5 = uieditfield(app.Maze4Panel_2, 'text');
            app.Port4EditField_CR_5 = uieditfield(app.Maze4Panel_2, 'text');
            app.Port1EditField_RV_5 = uieditfield(app.Maze4Panel_2, 'text');
            app.Port2EditField_RV_5 = uieditfield(app.Maze4Panel_2, 'text');
            app.Port3EditField_RV_5 = uieditfield(app.Maze4Panel_2, 'text');
            app.Port4EditField_RV_5 = uieditfield(app.Maze4Panel_2, 'text');
            app.Port1EditField_RP_5 = uieditfield(app.Maze4Panel_2, 'text');
            app.Port2EditField_RP_5 = uieditfield(app.Maze4Panel_2, 'text');
            app.Port3EditField_RP_5 = uieditfield(app.Maze4Panel_2, 'text');
            app.Port4EditField_RP_5 = uieditfield(app.Maze4Panel_2, 'text');
            app.IntensityofCostLabel_4 = uilabel(app.Maze4Panel_2);
            app.IntensityofCostLabel_4.Text = 'Intensity of Cost';
            app.CostProbabilityLabel_4 = uilabel(app.Maze4Panel_2);
            app.CostProbabilityLabel_4.Text = 'Cost Probability:';
            app.RewardConcentrationLabel_4 = uilabel(app.Maze4Panel_2);
            app.RewardConcentrationLabel_4.Text = 'Reward Concentration';
            app.RewardVolumeLabel_4 = uilabel(app.Maze4Panel_2);
            app.RewardVolumeLabel_4.Text = 'Reward Volume';
            app.RewardProbabilityLabel_5 = uilabel(app.Maze4Panel_2);
            app.RewardProbabilityLabel_5.Text = 'Reward Probability:';
            app.NotesEditField_4Label = uilabel(app.Maze4Panel_2);
            app.NotesEditField_4Label.Text = 'Notes';
            app.NotesEditField_4 = uieditfield(app.Maze4Panel_2, 'text');
            app.Level1Label_4 = uilabel(app.Maze4Panel_2);
            app.Level1Label_4.Text = 'Level 1';
            app.Level2Label_4 = uilabel(app.Maze4Panel_2);
            app.Level2Label_4.Text = 'Level 2';
            app.Level3Label_4 = uilabel(app.Maze4Panel_2);
            app.Level3Label_4.Text = 'Level 3';
            app.Level4Label_4 = uilabel(app.Maze4Panel_2);
            app.Level4Label_4.Text = 'Level 4';
            app.PortDropDown_4Label = uilabel(app.Maze4Panel_2);
            app.PortDropDown_4Label.Text = 'Port';
            app.PortDropDown_4 = uidropdown(app.Maze4Panel_2);
            app.PortDropDown_4.Items = {'Maze 1', 'Maze 2', 'Maze 3', 'Maze 4'};
            app.PortDropDown_4.ValueChangedFcn = createCallbackFcn(app, @PortDropDown_4ValueChanged, true);
            app.PortDropDown_4.Tooltip = {'This lets your port another Maze''s details into here. '};
            app.PortDropDown_4.Value = 'Maze 4';
            app.DiagonalLabel_3 = uilabel(app.Maze4Panel_2);
            app.DiagonalLabel_3.Text = 'Diagonal';
            app.GridLabel_3 = uilabel(app.Maze4Panel_2);
            app.GridLabel_3.Text = 'Grid';
            app.HorizontalLabel_3 = uilabel(app.Maze4Panel_2);
            app.HorizontalLabel_3.Text = 'Horizontal';
            app.RadialLabel_3 = uilabel(app.Maze4Panel_2);
            app.RadialLabel_3.Text = 'Radial';
            app.FileSelectedTextAreaLabel = uilabel(app.Panel_4);
            app.FileSelectedTextAreaLabel.Text = 'File Selected';
            app.FileSelectedTextArea = uitextarea(app.Panel_4);
            app.SetTodaysDateButton = uibutton(app.Panel_4, 'push');
            app.SetTodaysDateButton.ButtonPushedFcn = createCallbackFcn(app, @SetTodaysDateButtonPushed, true);
            app.SetTodaysDateButton.Tooltip = {'This automatically sets today''s date. Only use if the experiments occured today.'};
            app.SetTodaysDateButton.Text = 'Set Today''s Date';
            app.DateMMDDYYYYLabel = uilabel(app.Panel_4);
            app.DateMMDDYYYYLabel.Text = 'Date (MM/DD/YYYY)';
            app.DateMMDDYYYYEditField = uieditfield(app.Panel_4, 'text');
            app.DateMMDDYYYYEditField.Tooltip = {'"Date" references the day of experimentation, not the date of logging. '};
            app.DONTFORGETTOSETDATELabel = uilabel(app.Panel_4);
            app.DONTFORGETTOSETDATELabel.Text = 'DONT FORGET TO SET DATE';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Maze1Panel = uipanel(app.Panel_2);
            app.Maze1Panel.Title = 'Maze 1';
            app.HealthEditFieldLabel = uilabel(app.Maze1Panel);
            app.HealthEditFieldLabel.Text = 'Health:';
            app.HealthEditField = uieditfield(app.Maze1Panel, 'text');
            app.SubjectNameIDEditFieldLabel = uilabel(app.Maze1Panel);
            app.SubjectNameIDEditFieldLabel.Text = 'Subject Name/ID:';
            app.SubjectNameIDEditField = uieditfield(app.Maze1Panel, 'text');
            app.GenderEditFieldLabel = uilabel(app.Maze1Panel);
            app.GenderEditFieldLabel.Text = 'Gender:';
            app.GenderEditField = uieditfield(app.Maze1Panel, 'text');
            app.CageNumberEditFieldLabel = uilabel(app.Maze1Panel);
            app.CageNumberEditFieldLabel.Text = 'Cage Number:';
            app.CageNumberEditField = uieditfield(app.Maze1Panel, 'text');
            app.BirthDateEditFieldLabel = uilabel(app.Maze1Panel);
            app.BirthDateEditFieldLabel.Text = 'Birth Date:';
            app.BirthDateEditField = uieditfield(app.Maze1Panel, 'text');
            app.CageMatesTextAreaLabel = uilabel(app.Maze1Panel);
            app.CageMatesTextAreaLabel.Text = 'Cage Mates:';
            app.CageMatesTextArea = uitextarea(app.Maze1Panel);
            app.GenoTypeEditFieldLabel = uilabel(app.Maze1Panel);
            app.GenoTypeEditFieldLabel.Text = 'GenoType';
            app.GenoTypeEditField = uieditfield(app.Maze1Panel, 'text');
            app.SelectRatDropDownLabel = uilabel(app.Maze1Panel);
            app.SelectRatDropDownLabel.Text = 'Select Rat:';
            app.SelectRatDropDown = uidropdown(app.Maze1Panel);
            app.SelectRatDropDown.Items = {'Aladdin', 'Alexis', 'Andrea', 'Carl', 'Fiona', 'Harley', 'Jafar', 'Juana', 'Jr', 'Jimi', 'Johnny', 'Kryssia', 'Kobe', 'Mike', 'Neftali', 'None', 'Raissa', 'Raven', 'Renata', 'Sully', 'Scar', 'Sarah', 'Shakira', 'Simba'};
            app.SelectRatDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectRatDropDownValueChanged, true);
            app.SelectRatDropDown.Value = 'None';
            app.Maze2Panel = uipanel(app.Panel_2);
            app.Maze2Panel.Title = 'Maze 2';
            app.HealthEditField_5Label = uilabel(app.Maze2Panel);
            app.HealthEditField_5Label.Text = 'Health:';
            app.HealthEditField_2 = uieditfield(app.Maze2Panel, 'text');
            app.SubjectNameIDEditField_5Label = uilabel(app.Maze2Panel);
            app.SubjectNameIDEditField_5Label.Text = 'Subject Name/ID:';
            app.SubjectNameIDEditField_2 = uieditfield(app.Maze2Panel, 'text');
            app.GenderEditField_5Label = uilabel(app.Maze2Panel);
            app.GenderEditField_5Label.Text = 'Gender:';
            app.GenderEditField_2 = uieditfield(app.Maze2Panel, 'text');
            app.CageNumberEditField_5Label = uilabel(app.Maze2Panel);
            app.CageNumberEditField_5Label.Text = 'Cage Number:';
            app.CageNumberEditField_2 = uieditfield(app.Maze2Panel, 'text');
            app.BirthDateEditField_5Label = uilabel(app.Maze2Panel);
            app.BirthDateEditField_5Label.Text = 'Birth Date:';
            app.BirthDateEditField_2 = uieditfield(app.Maze2Panel, 'text');
            app.CageMatesTextArea_2Label = uilabel(app.Maze2Panel);
            app.CageMatesTextArea_2Label.Text = 'Cage Mates:';
            app.CageMatesTextArea_2 = uitextarea(app.Maze2Panel);
            app.GenoTypeEditField_2Label = uilabel(app.Maze2Panel);
            app.GenoTypeEditField_2Label.Text = 'GenoType';
            app.GenoTypeEditField_2 = uieditfield(app.Maze2Panel, 'text');
            app.SelectRatDropDown_2Label = uilabel(app.Maze2Panel);
            app.SelectRatDropDown_2Label.Text = 'Select Rat:';
            app.SelectRatDropDown_2 = uidropdown(app.Maze2Panel);
            app.SelectRatDropDown_2.Items = {'Aladdin', 'Alexis', 'Andrea', 'Carl', 'Fiona', 'Harley', 'Jafar', 'Juana', 'Jr', 'Jimi', 'Johnny', 'Kryssia', 'Kobe', 'Mike', 'Neftali', 'None', 'Raissa', 'Raven', 'Renata', 'Sully', 'Scar', 'Sarah', 'Shakira', 'Simba'};
            app.SelectRatDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SelectRatDropDown_2ValueChanged, true);
            app.SelectRatDropDown_2.Value = 'None';
            app.Maze3Panel = uipanel(app.Panel_2);
            app.Maze3Panel.Title = 'Maze 3';
            app.HealthEditField_6Label = uilabel(app.Maze3Panel);
            app.HealthEditField_6Label.Text = 'Health:';
            app.HealthEditField_3 = uieditfield(app.Maze3Panel, 'text');
            app.SubjectNameIDEditField_6Label = uilabel(app.Maze3Panel);
            app.SubjectNameIDEditField_6Label.Text = 'Subject Name/ID:';
            app.SubjectNameIDEditField_3 = uieditfield(app.Maze3Panel, 'text');
            app.GenderEditField_6Label = uilabel(app.Maze3Panel);
            app.GenderEditField_6Label.Text = 'Gender:';
            app.GenderEditField_3 = uieditfield(app.Maze3Panel, 'text');
            app.CageNumberEditField_6Label = uilabel(app.Maze3Panel);
            app.CageNumberEditField_6Label.Text = 'Cage Number:';
            app.CageNumberEditField_3 = uieditfield(app.Maze3Panel, 'text');
            app.BirthDateEditField_6Label = uilabel(app.Maze3Panel);
            app.BirthDateEditField_6Label.Text = 'Birth Date:';
            app.BirthDateEditField_3 = uieditfield(app.Maze3Panel, 'text');
            app.GenoTypeEditField_3Label = uilabel(app.Maze3Panel);
            app.GenoTypeEditField_3Label.Text = 'GenoType';
            app.GenoTypeEditField_3 = uieditfield(app.Maze3Panel, 'text');
            app.CageMatesTextArea_3Label = uilabel(app.Maze3Panel);
            app.CageMatesTextArea_3Label.Text = 'Cage Mates:';
            app.CageMatesTextArea_3 = uitextarea(app.Maze3Panel);
            app.SelectRatDropDown_3Label = uilabel(app.Maze3Panel);
            app.SelectRatDropDown_3Label.Text = 'Select Rat:';
            app.SelectRatDropDown_3 = uidropdown(app.Maze3Panel);
            app.SelectRatDropDown_3.Items = {'Aladdin', 'Alexis', 'Andrea', 'Carl', 'Fiona', 'Harley', 'Jafar', 'Juana', 'Jr', 'Jimi', 'Johnny', 'Kryssia', 'Kobe', 'Mike', 'Neftali', 'None', 'Raissa', 'Raven', 'Renata', 'Sully', 'Scar', 'Sarah', 'Shakira', 'Simba'};
            app.SelectRatDropDown_3.ValueChangedFcn = createCallbackFcn(app, @SelectRatDropDown_3ValueChanged, true);
            app.SelectRatDropDown_3.Value = 'None';
            app.Maze4Panel = uipanel(app.Panel_2);
            app.Maze4Panel.Title = 'Maze 4';
            app.HealthEditField_7Label = uilabel(app.Maze4Panel);
            app.HealthEditField_7Label.Text = 'Health:';
            app.HealthEditField_4 = uieditfield(app.Maze4Panel, 'text');
            app.SubjectNameIDEditField_7Label = uilabel(app.Maze4Panel);
            app.SubjectNameIDEditField_7Label.Text = 'Subject Name/ID:';
            app.SubjectNameIDEditField_4 = uieditfield(app.Maze4Panel, 'text');
            app.GenderEditField_7Label = uilabel(app.Maze4Panel);
            app.GenderEditField_7Label.Text = 'Gender:';
            app.GenderEditField_4 = uieditfield(app.Maze4Panel, 'text');
            app.CageNumberEditField_7Label = uilabel(app.Maze4Panel);
            app.CageNumberEditField_7Label.Text = 'Cage Number:';
            app.CageNumberEditField_4 = uieditfield(app.Maze4Panel, 'text');
            app.BirthDateEditField_7Label = uilabel(app.Maze4Panel);
            app.BirthDateEditField_7Label.Text = 'Birth Date:';
            app.BirthDateEditField_4 = uieditfield(app.Maze4Panel, 'text');
            app.GenoTypeEditField_4Label = uilabel(app.Maze4Panel);
            app.GenoTypeEditField_4Label.Text = 'GenoType';
            app.GenoTypeEditField_4 = uieditfield(app.Maze4Panel, 'text');
            app.CageMatesTextArea_4Label = uilabel(app.Maze4Panel);
            app.CageMatesTextArea_4Label.Text = 'Cage Mates:';
            app.CageMatesTextArea_4 = uitextarea(app.Maze4Panel);
            app.SelectRatDropDown_4Label = uilabel(app.Maze4Panel);
            app.SelectRatDropDown_4Label.Text = 'Select Rat:';
            app.SelectRatDropDown_4 = uidropdown(app.Maze4Panel);
            app.SelectRatDropDown_4.Items = {'Aladdin', 'Alexis', 'Andrea', 'Carl', 'Fiona', 'Harley', 'Jafar', 'Juana', 'Jr', 'Jimi', 'Johnny', 'Kryssia', 'Kobe', 'Mike', 'Neftali', 'None', 'Raissa', 'Raven', 'Renata', 'Sully', 'Scar', 'Sarah', 'Shakira', 'Simba'};
            app.SelectRatDropDown_4.ValueChangedFcn = createCallbackFcn(app, @SelectRatDropDown_4ValueChanged, true);
            app.SelectRatDropDown_4.Value = 'None';
            app.Panel_3 = uipanel(app.UIFigure);
            app.DataSelectionLabel = uilabel(app.Panel_3);
            app.DataSelectionLabel.Text = 'Data Selection';
            app.SearchButton = uibutton(app.Panel_3, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.EditSwitchLabel = uilabel(app.Panel_3);
            app.EditSwitchLabel.Text = 'Edit';
            app.EditSwitch = uiswitch(app.Panel_3, 'slider');
            app.SeeUploadHistoryButton = uibutton(app.Panel_3, 'push');
            app.SeeUploadHistoryButton.ButtonPushedFcn = createCallbackFcn(app, @SeeUploadHistoryButtonPushed, true);
            app.SeeUploadHistoryButton.Text = 'See Upload History';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end