classdef RatViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Label                       matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        EditExistingRatTab          matlab.ui.container.Tab
        CageMateEditField_2         matlab.ui.control.EditField
        CageMateEditField_2Label    matlab.ui.control.Label
        GenotypeEditField_2         matlab.ui.control.EditField
        GenotypeEditField_2Label    matlab.ui.control.Label
        HealthEditField_2           matlab.ui.control.EditField
        HealthEditField_2Label      matlab.ui.control.Label
        CageNumberEditField_2       matlab.ui.control.EditField
        CageNumberEditField_2Label  matlab.ui.control.Label
        BirthdateEditField_2        matlab.ui.control.EditField
        BirthdateEditField_2Label   matlab.ui.control.Label
        GenderEditField_2           matlab.ui.control.EditField
        GenderEditField_2Label      matlab.ui.control.Label
        NameEditField_2             matlab.ui.control.EditField
        NameEditField_2Label        matlab.ui.control.Label
        SaveEditsButton             matlab.ui.control.Button
        UploadNewRatTab             matlab.ui.container.Tab
        AddNewRatButton             matlab.ui.control.Button
        CageMateEditField           matlab.ui.control.EditField
        CageMateEditFieldLabel      matlab.ui.control.Label
        GenotypeEditField           matlab.ui.control.EditField
        GenotypeEditFieldLabel      matlab.ui.control.Label
        HealthEditField             matlab.ui.control.EditField
        HealthEditFieldLabel        matlab.ui.control.Label
        CageNumberEditField         matlab.ui.control.EditField
        CageNumberEditFieldLabel    matlab.ui.control.Label
        BirthdateEditField          matlab.ui.control.EditField
        BirthdateEditFieldLabel     matlab.ui.control.Label
        GenderEditField             matlab.ui.control.EditField
        GenderEditFieldLabel        matlab.ui.control.Label
        NameEditField               matlab.ui.control.EditField
        NameEditFieldLabel          matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
    end

    
    properties (Access = private)
        rattable string = "rattable"; %name of the active rat table
        password string = "1234"; %pw for the database
        datasource string = "live_database"; %datasource
        username string = "postgres";
        columnTitles string = ["name","gender","birthdate","cagenumber","health","genotype","cagemate"];
        index double; 


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            conn = database(app.datasource,app.username,app.password);
            query = strcat("SELECT * FROM ",app.rattable,";");
            localRatTable = fetch(conn,query);
            app.UITable.Data=localRatTable;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            display(indices(1))
            app.index = indices(1);
            display(string(app.UITable.Data{1,1}))
            app.NameEditField_2.Value = string(app.UITable.Data{indices(1),1});
            app.HealthEditField_2.Value = string(app.UITable.Data{indices(1),5});
            app.GenderEditField_2.Value = string(app.UITable.Data{indices(1),2});
            app.GenotypeEditField_2.Value = string(app.UITable.Data{indices(1),6});
            app.BirthdateEditField_2.Value = string(app.UITable.Data{indices(1),3});
            app.CageMateEditField_2.Value = string(app.UITable.Data{indices(1),7});
            app.CageNumberEditField_2.Value = string(app.UITable.Data{indices(1),4});
%             app.NameEditField.Value = 
        end

        % Button pushed function: SaveEditsButton
        function SaveEditsButtonPushed(app, event)
            answer = questdlg({"You're about to edit a rat with the following information:",...
                strcat("Name:        ", app.NameEditField_2.Value), ...
                strcat("Gender:      ", app.GenderEditField_2.Value), ...
                strcat("Birthdate:   ", app.BirthdateEditField_2.Value), ...
                strcat("Cage Number: ", app.CageNumberEditField_2.Value) ...
                strcat("Health:      ", app.HealthEditField_2.Value), ...
                strcat("Genotype:    ", app.GenotypeEditField_2.Value), ...
                strcat("Cage Mate:   ", app.CageMateEditField_2.Value), ...
                "Are you sure you want to make these changes?"},"Confirm Changes", "Yes", "No", "No");

            switch answer
                case 'Yes'
                    if app.index == 0
                        errordlg("You have not selected a row to edit. Try reselecting the row. .")
                    else
                        conn = database(app.datasource,app.username,app.password);
                        query = strcat("UPDATE ",app.rattable," SET name = '",app.NameEditField_2.Value, ...
                            "', gender = '", app.GenderEditField_2.Value, ...
                            "', birthdate = '", app.BirthdateEditField_2.Value, ...
                            "', cagenumber = '", app.CageNumberEditField_2.Value, ...
                            "', health = '", app.HealthEditField_2.Value, ...
                            "', genotype = '", app.GenotypeEditField_2.Value, ...
                            "', cagemate = '", app.CageMateEditField_2.Value, ...
                            "'  WHERE id = ", string(app.UITable.Data{app.index,8}),";");
                        display(query)
                        execute(conn,query)
                        query = strcat("SELECT * FROM ",app.rattable,";");
                        results = fetch(conn,query);
                        app.UITable.Data = results;
                        app.index = 0;

                        app.NameEditField_2.Value ='';
                        app.GenderEditField_2.Value='';
                        app.BirthdateEditField_2.Value='';
                        app.CageNumberEditField_2.Value='';
                        app.HealthEditField_2.Value='';
                        app.GenotypeEditField_2.Value='';
                        app.CageMateEditField_2.Value='';
                    end

                case 'No'
            end
        end

        % Button pushed function: AddNewRatButton
        function AddNewRatButtonPushed(app, event)
            answer = questdlg({"You're about to upload a rat with the following information:",...
                strcat("Name:        ", app.NameEditField.Value), ...
                strcat("Gender:      ", app.GenderEditField.Value), ...
                strcat("Birthdate:   ", app.BirthdateEditField.Value), ...
                strcat("Cage Number: ", app.CageNumberEditField.Value) ...
                strcat("Health:      ", app.HealthEditField.Value), ...
                strcat("Genotype:    ", app.GenotypeEditField.Value), ...
                strcat("Cage Mate:   ", app.CageMateEditField.Value), ...
                "Are you sure you want to upload this rat?"},"Confirm Upload", "Yes", "No", "No");
            switch answer
                case 'Yes'
                    conn = database(app.datasource,app.username,app.password);
                    query = strcat("SELECT * FROM ", app.rattable, " WHERE name = '", app.NameEditField.Value,"'; ");
                    result = fetch(conn,query);
                    if height(result) > 0
                        errordlg("There is already a rat with that name in rat database. Please select another.")
                    else
                        data = table(string(lower(app.NameEditField.Value)), string(app.GenderEditField.Value),string(app.BirthdateEditField.Value), ...
                            string(app.CageNumberEditField.Value), string(app.HealthEditField.Value), string(app.GenotypeEditField.Value), ...
                            string(app.CageMateEditField.Value),'VariableNames',app.columnTitles);
                        sqlwrite(conn,app.rattable,data)
                        query = strcat("SELECT * FROM ", app.rattable, ";");
                        results = fetch(conn,query);
                        app.UITable.Data = results;
                    end


                case 'No'
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Name'; 'Gender'; 'Birthdate'; 'Cage Number'; 'Health'; 'Genotype'; 'Cage Mate'; 'ID'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.Multiselect = 'off';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EditExistingRatTab = uitab(app.TabGroup);
            app.EditExistingRatTab.Title = 'Edit Existing Rat';
            app.SaveEditsButton = uibutton(app.EditExistingRatTab, 'push');
            app.SaveEditsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveEditsButtonPushed, true);
            app.SaveEditsButton.Text = 'Save Edits';
            app.NameEditField_2Label = uilabel(app.EditExistingRatTab);
            app.NameEditField_2Label.Text = 'Name';
            app.NameEditField_2 = uieditfield(app.EditExistingRatTab, 'text');
            app.GenderEditField_2Label = uilabel(app.EditExistingRatTab);
            app.GenderEditField_2Label.Text = 'Gender';
            app.GenderEditField_2 = uieditfield(app.EditExistingRatTab, 'text');
            app.BirthdateEditField_2Label = uilabel(app.EditExistingRatTab);
            app.BirthdateEditField_2Label.Text = 'Birthdate';
            app.BirthdateEditField_2 = uieditfield(app.EditExistingRatTab, 'text');
            app.CageNumberEditField_2Label = uilabel(app.EditExistingRatTab);
            app.CageNumberEditField_2Label.Text = 'Cage Number';
            app.CageNumberEditField_2 = uieditfield(app.EditExistingRatTab, 'text');
            app.HealthEditField_2Label = uilabel(app.EditExistingRatTab);
            app.HealthEditField_2Label.Text = 'Health';
            app.HealthEditField_2 = uieditfield(app.EditExistingRatTab, 'text');
            app.GenotypeEditField_2Label = uilabel(app.EditExistingRatTab);
            app.GenotypeEditField_2Label.Text = 'Genotype';
            app.GenotypeEditField_2 = uieditfield(app.EditExistingRatTab, 'text');
            app.CageMateEditField_2Label = uilabel(app.EditExistingRatTab);
            app.CageMateEditField_2Label.Text = 'Cage Mate';
            app.CageMateEditField_2 = uieditfield(app.EditExistingRatTab, 'text');
            app.UploadNewRatTab = uitab(app.TabGroup);
            app.UploadNewRatTab.Title = 'Upload New Rat';
            app.NameEditFieldLabel = uilabel(app.UploadNewRatTab);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.UploadNewRatTab, 'text');
            app.GenderEditFieldLabel = uilabel(app.UploadNewRatTab);
            app.GenderEditFieldLabel.Text = 'Gender';
            app.GenderEditField = uieditfield(app.UploadNewRatTab, 'text');
            app.BirthdateEditFieldLabel = uilabel(app.UploadNewRatTab);
            app.BirthdateEditFieldLabel.Text = 'Birthdate';
            app.BirthdateEditField = uieditfield(app.UploadNewRatTab, 'text');
            app.CageNumberEditFieldLabel = uilabel(app.UploadNewRatTab);
            app.CageNumberEditFieldLabel.Text = 'Cage Number';
            app.CageNumberEditField = uieditfield(app.UploadNewRatTab, 'text');
            app.HealthEditFieldLabel = uilabel(app.UploadNewRatTab);
            app.HealthEditFieldLabel.Text = 'Health';
            app.HealthEditField = uieditfield(app.UploadNewRatTab, 'text');
            app.GenotypeEditFieldLabel = uilabel(app.UploadNewRatTab);
            app.GenotypeEditFieldLabel.Text = 'Genotype';
            app.GenotypeEditField = uieditfield(app.UploadNewRatTab, 'text');
            app.CageMateEditFieldLabel = uilabel(app.UploadNewRatTab);
            app.CageMateEditFieldLabel.Text = 'Cage Mate';
            app.CageMateEditField = uieditfield(app.UploadNewRatTab, 'text');
            app.AddNewRatButton = uibutton(app.UploadNewRatTab, 'push');
            app.AddNewRatButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewRatButtonPushed, true);
            app.AddNewRatButton.Text = 'Add New Rat';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '_______________________________________________________________________________________________';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RatViewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end