classdef Start < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LoginButton                    matlab.ui.control.Button
        JDBCDriverPathEditField        matlab.ui.control.EditField
        JDBCDriverPathEditFieldLabel   matlab.ui.control.Label
        ServerIPAddressEditField       matlab.ui.control.EditField
        ServerIPAddressEditFieldLabel  matlab.ui.control.Label
        SetDriverPathButton            matlab.ui.control.Button
        SetIPAddressButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        IPAddressSet double = 0; % keeps track of whether or not the IP is set
        driverPathSet double = 0 ; %keeps track of whether the driver path is set
        driverPath string = '';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SetDriverPathButton
        function SetDriverPathButtonPushed(app, event)
            [file,path] = uigetfile('*.jar');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
                app.JDBCDriverPathEditField.Value = fullfile(path,file);
                app.driverPath = app.JDBCDriverPathEditField.Value;
                fileID = fopen('jarFilePath.txt',"w");
                
                fprintf(fileID,'%s',app.driverPath); 
                fclose(fileID);
                app.driverPathSet = 1;
            end
        end

        % Button pushed function: SetIPAddressButton
        function SetIPAddressButtonPushed(app, event)
            fileID = fopen('IPAddress.txt','w');
            fprintf(fileID,string(app.ServerIPAddressEditField.Value));
            fclose(fileID);
            app.IPAddressSet = 1;
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            if app.IPAddressSet
                if app.driverPathSet
                    Main
                else
                    errordlg("The Driver path is not set. Please set before logging in.")
                end
            else
                errordlg("The IP address is not set. Please set before logging in.")
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SetIPAddressButton = uibutton(app.UIFigure, 'push');
            app.SetIPAddressButton.ButtonPushedFcn = createCallbackFcn(app, @SetIPAddressButtonPushed, true);
            app.SetIPAddressButton.Text = 'Set IP Address';
            app.SetDriverPathButton = uibutton(app.UIFigure, 'push');
            app.SetDriverPathButton.ButtonPushedFcn = createCallbackFcn(app, @SetDriverPathButtonPushed, true);
            app.SetDriverPathButton.Text = 'Set Driver Path';
            app.ServerIPAddressEditFieldLabel = uilabel(app.UIFigure);
            app.ServerIPAddressEditFieldLabel.Text = 'Server IP Address';
            app.ServerIPAddressEditField = uieditfield(app.UIFigure, 'text');
            app.JDBCDriverPathEditFieldLabel = uilabel(app.UIFigure);
            app.JDBCDriverPathEditFieldLabel.Text = 'JDBC Driver Path';
            app.JDBCDriverPathEditField = uieditfield(app.UIFigure, 'text');
            app.LoginButton = uibutton(app.UIFigure, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Start

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end